classdef CVAktakomapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CapacitanceVoltageTab         matlab.ui.container.Tab
        StopExperimentButton          matlab.ui.control.Button
        IndicatorLamp                 matlab.ui.control.Lamp
        IndicatorLampLabel            matlab.ui.control.Label
        StartExperimentButton         matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        SettingsTab                   matlab.ui.container.Tab
        FunctionsListBox              matlab.ui.control.ListBox
        FunctionsListBoxLabel         matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        CommentsEditField             matlab.ui.control.EditField
        CommentsEditFieldLabel        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        SampleInfoEditField           matlab.ui.control.EditField
        SampleInfoEditFieldLabel      matlab.ui.control.Label
        MeasurementParametersPanel    matlab.ui.container.Panel
        SpeedListBox                  matlab.ui.control.ListBox
        SpeedListBoxLabel             matlab.ui.control.Label
        StepBiasEditField             matlab.ui.control.NumericEditField
        StepBiasEditFieldLabel        matlab.ui.control.Label
        StopBiasEditField             matlab.ui.control.NumericEditField
        StopBiasEditFieldLabel        matlab.ui.control.Label
        VoltsLabel_3                  matlab.ui.control.Label
        KHzLabel                      matlab.ui.control.Label
        StartBiasEditField            matlab.ui.control.NumericEditField
        StartBiasEditFieldLabel       matlab.ui.control.Label
        LevelVoltageEditField         matlab.ui.control.NumericEditField
        LevelVoltageLabel             matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        ConnectionStatusPanel         matlab.ui.container.Panel
        AktakomRLCMeterAMM3048Lamp    matlab.ui.control.Lamp
        AktakomRLCMeterAMM3048LampLabel  matlab.ui.control.Label
        Tables                        matlab.ui.container.Tab
        CVLabel                       matlab.ui.control.Label
        UITable1                      matlab.ui.control.Table
        GraphsTab                     matlab.ui.container.Tab
        MeasurementsPanel             matlab.ui.container.Panel
        FaradLabel                    matlab.ui.control.Label
        CapacitanceTextArea           matlab.ui.control.TextArea
        CapacitanceTextAreaLabel      matlab.ui.control.Label
        VoltsLabel_2                  matlab.ui.control.Label
        VoltageTextArea_2             matlab.ui.control.TextArea
        VoltageLabel                  matlab.ui.control.Label
        StateoftheExperimentPanel     matlab.ui.container.Panel
        StateoftheExperimentTextArea  matlab.ui.control.TextArea
        UIAxes1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Aktakom3048;
        connectionStatusAktakom;

        freq;
        lev;
        ran;
        startbias;
        stepbias;
        stopbias;
        spd;
        func;
        capacitance;
        voltage;
        gmeas;

        i;
        j;
        k;
          
    end   
    
    methods (Access = public)
        
        function connectDeviceAktakom3048(app)
            if strcmp(app.connectionStatusAktakom,'Connected')
                delete(app.Aktakom3048)
                app.connectionStatusAktakom='Not Connected';
            end
            try
                app.Aktakom3048=visadev("USB0::0x0471::0x2827::QF40900001::0::INSTR");
                app.AktakomRLCMeterAMM3048Lamp.Color='g';
                app.StateoftheExperimentTextArea.Value=('The connection to the device is successful!');
                app.connectionStatusAktakom='Connected';
            catch
                app.AktakomRLCMeterAMM3048Lamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('The connection to the device has failed!');
                app.connectionStatusAktakom='Not Connected';
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %%
            %Experiment has begun:
            format longE
            
            app.connectDeviceAktakom3048();
            if ~strcmp(app.connectionStatusAktakom,'Connected') 
                uialert(app.UIFigure,'Cannot start the experiment because the devices is not connected','Connection Error')
                clc
                clear
                return;
            end

            cla(app.UIAxes1);
            app.UITable1.Data=[];

            app.i=1;
            app.j=1;
            app.k=1;

            app.func=char(app.FunctionsListBox.Value);
            app.freq=app.FrequencyEditField.Value;
            app.lev=app.LevelVoltageEditField.Value;
            app.startbias=app.StartBiasEditField.Value;
            app.stepbias=app.StepBiasEditField.Value;
            app.stopbias=app.StopBiasEditField.Value;
            app.spd=char(app.SpeedListBox.Value);

            app.voltage=zeros(app.i,1);
            app.capacitance=zeros(app.j,1);
            app.gmeas=zeros(app.k,1);


            app.IndicatorLamp.Color='y'; % Yellow means experiment has started
            app.StateoftheExperimentTextArea.Value=('Experiment has started, resetting and initializing parameters');
            app.StartExperimentButton.Enable="off";

            writeline(app.Aktakom3048,'*RST')
           
            
            % Aktakom Settings
            %writeline(app.Aktakom3048,'AMPL:ALC 1')
            writeline(app.Aktakom3048, sprintf('FUNCtion:IMPedance %s', app.func))
            writeline(app.Aktakom3048, sprintf('FREQ %dKHz', app.freq))
            writeline(app.Aktakom3048, sprintf('VOLT %dV', app.lev))
            writeline(app.Aktakom3048, 'FUNC:IMP:RANG:AUTO ON')
            writeline(app.Aktakom3048, 'BIAS:STATe 1') 
            writeline(app.Aktakom3048, sprintf('APER %s', app.spd))
            writeline(app.Aktakom3048,'TRIG:SOUR BUS')
                
            for  bias=app.startbias:app.stepbias:app.stopbias
            writeline(app.Aktakom3048, sprintf('BIAS:VOLT %f',bias))
            writeline(app.Aktakom3048,'TRIG')
            pause(1)
            writeline(app.Aktakom3048,'FUNC:SMON:VAC ON')  
           
            cpgreadings=strsplit((writeread(app.Aktakom3048,'FETC?')),',');
            app.voltage(app.i,1)=str2double(writeread(app.Aktakom3048,'BIAS:VOLTage?'));  
            app.capacitance(app.j,1)=str2double(cpgreadings(1));
            app.gmeas(app.k,1)=str2double(cpgreadings(2));


            MeasurementtableData=[app.voltage, app.capacitance, app.gmeas];
            app.UITable1.ColumnFormat={'numeric'};
            app.UITable1.Data=MeasurementtableData;
 
            plot(app.UIAxes1,app.voltage,app.capacitance)
            xlabel(app.UIAxes1,'Voltage, V')
            ylabel(app.UIAxes1,'Capacitance, F')
            title(app.UIAxes1,'C-V Measurements')

            app.i=app.i+1;
            app.j=app.j+1;
            app.k=app.k+1;
           
            end

            app.StartExperimentButton.Enable="on";
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CapacitanceVoltageTab = uitab(app.TabGroup);
            app.CapacitanceVoltageTab.Title = 'Capacitance-Voltage';
            app.TabGroup2 = uitabgroup(app.CapacitanceVoltageTab);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.AktakomRLCMeterAMM3048LampLabel = uilabel(app.ConnectionStatusPanel);
            app.AktakomRLCMeterAMM3048LampLabel.Text = 'Aktakom RLC Meter AMM-3048';
            app.AktakomRLCMeterAMM3048Lamp = uilamp(app.ConnectionStatusPanel);
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.FrequencyEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.FrequencyEditField.Value = 1;
            app.LevelVoltageLabel = uilabel(app.MeasurementParametersPanel);
            app.LevelVoltageLabel.Text = 'Level (Voltage)';
            app.LevelVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.LevelVoltageEditField.Value = 0.03;
            app.StartBiasEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StartBiasEditFieldLabel.Text = 'Start Bias';
            app.StartBiasEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.KHzLabel = uilabel(app.MeasurementParametersPanel);
            app.KHzLabel.Text = '[KHz]';
            app.VoltsLabel_3 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_3.Text = '[Volts]';
            app.StopBiasEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StopBiasEditFieldLabel.Text = 'Stop Bias';
            app.StopBiasEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StopBiasEditField.Value = 1;
            app.StepBiasEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StepBiasEditFieldLabel.Text = 'Step Bias';
            app.StepBiasEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StepBiasEditField.Value = 0.1;
            app.SpeedListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.SpeedListBoxLabel.Text = 'Speed';
            app.SpeedListBox = uilistbox(app.MeasurementParametersPanel);
            app.SpeedListBox.Items = {'FAST', 'MED', 'SLOW'};
            app.SpeedListBox.Value = 'MED';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel, 'text');
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel_2);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel_2, 'text');
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.FunctionsListBoxLabel = uilabel(app.SettingsTab);
            app.FunctionsListBoxLabel.Text = 'Functions';
            app.FunctionsListBox = uilistbox(app.SettingsTab);
            app.FunctionsListBox.Items = {'CPG', 'CPQ', 'CPD    ', 'RX', 'LPRP    ', 'LSD'};
            app.FunctionsListBox.Value = 'CPG';
            app.Tables = uitab(app.TabGroup2);
            app.Tables.Title = 'Tables';
            app.UITable1 = uitable(app.Tables);
            app.UITable1.ColumnName = {'Voltage'; 'Capacitance, F'; 'G'};
            app.UITable1.RowName = {};
            app.CVLabel = uilabel(app.Tables);
            app.CVLabel.Text = 'C-V';
            app.GraphsTab = uitab(app.TabGroup2);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes1 = uiaxes(app.GraphsTab);
            title(app.UIAxes1, 'C-V Measurement')
            xlabel(app.UIAxes1, 'Voltage, V')
            ylabel(app.UIAxes1, 'Capacitance, F')
            zlabel(app.UIAxes1, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements:';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Voltage:';
            app.VoltageTextArea_2 = uitextarea(app.MeasurementsPanel);
            app.VoltsLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltsLabel_2.Text = '[Volts]';
            app.CapacitanceTextAreaLabel = uilabel(app.MeasurementsPanel);
            app.CapacitanceTextAreaLabel.Text = 'Capacitance:';
            app.CapacitanceTextArea = uitextarea(app.MeasurementsPanel);
            app.FaradLabel = uilabel(app.MeasurementsPanel);
            app.FaradLabel.Text = '[Farad]';
            app.StartExperimentButton = uibutton(app.CapacitanceVoltageTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CapacitanceVoltageTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CapacitanceVoltageTab);
            app.StopExperimentButton = uibutton(app.CapacitanceVoltageTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVAktakomapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end