classdef CVAktakomapp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CapacitanceVoltageTab           matlab.ui.container.Tab
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        MeasurementParametersPanel      matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        MajorLabel                      matlab.ui.control.Label
        BiasAfterMeasureSwitch          matlab.ui.control.Switch
        BiasAfterMeasureSwitchLabel     matlab.ui.control.Label
        SweepBiasSwitch                 matlab.ui.control.Switch
        SweepBiasSwitchLabel            matlab.ui.control.Label
        VoltsLabel_9                    matlab.ui.control.Label
        FunctionsListBox                matlab.ui.control.ListBox
        FunctionsListBoxLabel           matlab.ui.control.Label
        VoltsLabel_5                    matlab.ui.control.Label
        VoltsLabel_4                    matlab.ui.control.Label
        SpeedListBox                    matlab.ui.control.ListBox
        SpeedListBoxLabel               matlab.ui.control.Label
        StepBiasEditField               matlab.ui.control.NumericEditField
        StepBiasEditFieldLabel          matlab.ui.control.Label
        StopBiasEditField               matlab.ui.control.NumericEditField
        StopBiasEditFieldLabel          matlab.ui.control.Label
        VoltsLabel_3                    matlab.ui.control.Label
        KHzLabel                        matlab.ui.control.Label
        StartBiasEditField              matlab.ui.control.NumericEditField
        StartBiasEditFieldLabel         matlab.ui.control.Label
        LevelVoltageEditField           matlab.ui.control.NumericEditField
        LevelVoltageLabel               matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        ConnectionStatusPanel           matlab.ui.container.Panel
        AktakomRLCMeterAMM3048Lamp      matlab.ui.control.Lamp
        AktakomRLCMeterAMM3048LampLabel  matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        CVLabel                         matlab.ui.control.Label
        UITable1                        matlab.ui.control.Table
        GraphsTab                       matlab.ui.container.Tab
        MeasurementsPanel               matlab.ui.container.Panel
        SmLabel                         matlab.ui.control.Label
        ConductanceTextArea             matlab.ui.control.TextArea
        ConductanceTextAreaLabel        matlab.ui.control.Label
        FaradLabel                      matlab.ui.control.Label
        CapacitanceTextArea             matlab.ui.control.TextArea
        CapacitanceTextAreaLabel        matlab.ui.control.Label
        VoltsLabel_2                    matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        UIAxes1_3                       matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        CapacitanceFrequencyTab         matlab.ui.container.Tab
        StopExperimentButton_2          matlab.ui.control.Button
        IndicatorLamp_2                 matlab.ui.control.Lamp
        IndicatorLamp_2Label            matlab.ui.control.Label
        StartExperimentButton_2         matlab.ui.control.Button
        TabGroup2_2                     matlab.ui.container.TabGroup
        SettingsTab_2                   matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        CommentsEditField_2             matlab.ui.control.EditField
        CommentsEditField_2Label        matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        SampleInfoEditField_2           matlab.ui.control.EditField
        SampleInfoEditFieldLabel_2      matlab.ui.control.Label
        MeasurementParametersPanel_2    matlab.ui.container.Panel
        KHzLabel_6                      matlab.ui.control.Label
        FunctionsListBox_2              matlab.ui.control.ListBox
        FunctionsListBox_2Label         matlab.ui.control.Label
        SpeedListBox_2                  matlab.ui.control.ListBox
        SpeedListBox_2Label             matlab.ui.control.Label
        KHzLabel_5                      matlab.ui.control.Label
        KHzLabel_4                      matlab.ui.control.Label
        VoltsLabel_6                    matlab.ui.control.Label
        VoltsLabel_10                   matlab.ui.control.Label
        StepBiasEditField_2             matlab.ui.control.NumericEditField
        StepBiasEditField_2Label        matlab.ui.control.Label
        StopBiasEditField_2             matlab.ui.control.NumericEditField
        StopBiasEditField_2Label        matlab.ui.control.Label
        StartBiasEditField_2            matlab.ui.control.NumericEditField
        StartBiasEditField_2Label       matlab.ui.control.Label
        LevelVoltageEditField_2         matlab.ui.control.NumericEditField
        LevelVoltageEditField_2Label    matlab.ui.control.Label
        BiasEditField                   matlab.ui.control.NumericEditField
        BiasEditFieldLabel              matlab.ui.control.Label
        ConnectionStatusPanel_2         matlab.ui.container.Panel
        AktakomRLCMeterAMM3048Lamp_2    matlab.ui.control.Lamp
        AktakomRLCMeterAMM3048Lamp_2Label  matlab.ui.control.Label
        Tables_2                        matlab.ui.container.Tab
        CVLabel_2                       matlab.ui.control.Label
        UITable1_2                      matlab.ui.control.Table
        GraphsTab_2                     matlab.ui.container.Tab
        MeasurementsPanel_2             matlab.ui.container.Panel
        FaradLabel_2                    matlab.ui.control.Label
        KHzLabel_3                      matlab.ui.control.Label
        CapacitanceTextArea_2           matlab.ui.control.TextArea
        CapacitanceTextArea_2Label      matlab.ui.control.Label
        FrequencyTextArea               matlab.ui.control.TextArea
        FrequencyLabel                  matlab.ui.control.Label
        StateoftheExperimentPanel_2     matlab.ui.container.Panel
        StateoftheExperimentTextArea_2  matlab.ui.control.TextArea
        UIAxes1_2                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Aktakom3048;
        connectionStatusAktakom;

        freq;
        lev;
        ran;
        startbias;
        stepbias;
        stopbias;
        spd;
        func;
        capacitance;
        voltage;
        conductance;
        sweepswitch;
        biasaftermeasure;

        i;
        j;
        k;
        n;

        settings;
        measurement_var_names;
        measurement_table;
        defaultFolder;

        SampleInfo;
        Comments;
        additionalCommentsCell;
        additionalComments;


        data;
        sheet1;
        sheet2;

        time;
        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;

    end

    methods (Access = public)

        function connectDeviceAktakom3048(app)
            if strcmp(app.connectionStatusAktakom,'Connected')
                delete(app.Aktakom3048)
                app.connectionStatusAktakom='Not Connected';
            end
            try
                app.Aktakom3048=visadev("USB0::0x0471::0x2827::QF40900001::0::INSTR");
                app.AktakomRLCMeterAMM3048Lamp.Color='g';
                app.StateoftheExperimentTextArea.Value=('The connection to the device is successful!');
                app.connectionStatusAktakom='Connected';
            catch
                app.AktakomRLCMeterAMM3048Lamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('The connection to the device has failed!');
                app.connectionStatusAktakom='Not Connected';
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %% Initialization
            %Experiment has begun:
            format longE
            app.connectDeviceAktakom3048();
            if ~strcmp(app.connectionStatusAktakom,'Connected')
                app.AktakomRLCMeterAMM3048Lamp.Color='r';
                uialert(app.UIFigure,'Cannot start the experiment because the devices is not connected','Connection Error')
                clc
                clear
                return;
            end
            cla(app.UIAxes1);
            app.UITable1.Data=[];

            app.i=1;
            app.j=1;
            app.k=1;

            app.func=char(app.FunctionsListBox.Value);
            app.freq=app.FrequencyEditField.Value;
            app.lev=app.LevelVoltageEditField.Value;
            app.startbias=app.StartBiasEditField.Value;
            app.stepbias=app.StepBiasEditField.Value;
            app.stopbias=app.StopBiasEditField.Value;
            app.sweepswitch=app.SweepBiasSwitch.Value;
            app.biasaftermeasure=app.BiasAfterMeasureSwitch.Value;
            app.spd=char(app.SpeedListBox.Value);
            app.voltage=zeros(app.i,1);
            app.capacitance=zeros(app.j,1);
            app.conductance=zeros(app.k,1);
            %% Saving Parameters
            if exist('datacv.xlsx','file')==2
                delete('datacv.xlsx')
            end

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.data='datacv.xlsx';
            app.sheet1 ='Settings';
            app.sheet2 ='Measurements';
            app.defaultFolder='D:\RRAM\Measurements';
            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ;'Function', app.func ;'Speed', app.spd ;'Measurement Parameters' , ' '; 'Frequency [KHz]' , app.freq ; 'Level (Voltage)' , app.lev ; 'Start Bias' , app.startbias ;'Step Bias' , app.stepbias ; 'Stop Bias' , app.stopbias ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};
            app.measurement_var_names = {'Voltage', 'Capacitance','Conductance'};
            app.measurement_table = table(app.voltage, app.capacitance,app.conductance ,'VariableNames', app.measurement_var_names);
            writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
            writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet2);

            %% Device Settings
            app.IndicatorLamp.Color='y'; % Yellow means experiment has started
            app.StateoftheExperimentTextArea.Value=('Experiment has started, resetting and initializing parameters');
            app.StartExperimentButton.Enable="off";


            writeline(app.Aktakom3048,'*RST')
            % Aktakom Settings
            %writeline(app.Aktakom3048,'AMPL:ALC 1')
            writeline(app.Aktakom3048, sprintf('FUNCtion:IMPedance %s', app.func))
            writeline(app.Aktakom3048, sprintf('FREQ %dKHz', app.freq))
            writeline(app.Aktakom3048, sprintf('VOLT %dV', app.lev))
            writeline(app.Aktakom3048, 'FUNC:IMP:RANG:AUTO ON')
            writeline(app.Aktakom3048, 'BIAS:STATe 1')
            writeline(app.Aktakom3048, sprintf('APER %s', app.spd))
            writeline(app.Aktakom3048,'TRIG:SOUR BUS')

            %% Measurements
            app.IndicatorLamp.Color='r';
            if app.startbias<app.stepbias
                step=abs(app.stepbias);
            else
                step=-abs(app.stepbias);
            end
            if strcmp(app.sweepswitch,'Off')
                for bias=app.startbias:step:app.stopbias
                    writeline(app.Aktakom3048, sprintf('BIAS:VOLT %f',bias))
                    writeline(app.Aktakom3048,'TRIG')
                    pause(0.5)
                    writeline(app.Aktakom3048,'FUNC:SMON:VAC ON')
                    cpgreadings=strsplit((writeread(app.Aktakom3048,'FETC?')),',');
                    app.voltage(app.i,1)=str2double(writeread(app.Aktakom3048,'BIAS:VOLTage?'));
                    app.capacitance(app.j,1)=str2double(cpgreadings(1));
                    app.conductance(app.k,1)=str2double(cpgreadings(2));

                    MeasurementtableData=[app.voltage, app.capacitance, app.conductance];
                    app.UITable1.ColumnFormat={'numeric'};
                    app.UITable1.Data=MeasurementtableData;

                    app.VoltageTextArea.Value=num2str(app.voltage(app.i,1));
                    app.CapacitanceTextArea.Value=num2str(app.capacitance(app.j,1));
                    app.ConductanceTextArea.Value=num2str(app.conductance(app.k,1));

                    plot(app.UIAxes1,app.voltage,app.capacitance)
                    xlabel(app.UIAxes1,'Voltage, V')
                    ylabel(app.UIAxes1,'Capacitance, F')
                    title(app.UIAxes1,'C-V')

                    plot(app.UIAxes1_3,app.voltage,app.conductance)
                    xlabel(app.UIAxes1_3,'Voltage, V')
                    ylabel(app.UIAxes1_3,'Conductance, S/m')
                    title(app.UIAxes1_3,'G-V')

                    app.i=app.i+1;
                    app.j=app.j+1;
                    app.k=app.k+1;

                    app.measurement_var_names = {'Voltage', 'Capacitance','Conductance'};
                    app.measurement_table = table(app.voltage, app.capacitance,app.conductance ,'VariableNames', app.measurement_var_names);
                    writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
                    writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet2);

                end
            elseif strcmp(app.sweepswitch,'On')
                biasRange=[app.startbias:step:app.stopbias, app.stopbias-step:-step:app.startbias];
                for bias=biasRange
                    writeline(app.Aktakom3048, sprintf('BIAS:VOLT %f',bias))
                    writeline(app.Aktakom3048,'TRIG')
                    pause(0.5)
                    writeline(app.Aktakom3048,'FUNC:SMON:VAC ON')
                    cpgreadings=strsplit((writeread(app.Aktakom3048,'FETC?')),',');
                    app.voltage(app.i,1)=str2double(writeread(app.Aktakom3048,'BIAS:VOLTage?'));
                    app.capacitance(app.j,1)=str2double(cpgreadings(1));
                    app.conductance(app.k,1)=str2double(cpgreadings(2));

                    MeasurementtableData=[app.voltage, app.capacitance, app.conductance];
                    app.UITable1.ColumnFormat={'numeric'};
                    app.UITable1.Data=MeasurementtableData;

                    app.VoltageTextArea.Value=num2str(app.voltage(app.i,1));
                    app.CapacitanceTextArea.Value=num2str(app.capacitance(app.j,1));
                    app.ConductanceTextArea.Value=num2str(app.conductance(app.k,1));

                    plot(app.UIAxes1,app.voltage,app.capacitance)
                    xlabel(app.UIAxes1,'Voltage, V')
                    ylabel(app.UIAxes1,'Capacitance, F')
                    title(app.UIAxes1,'C-V')

                    plot(app.UIAxes1_3,app.voltage,app.conductance)
                    xlabel(app.UIAxes1_3,'Voltage, V')
                    ylabel(app.UIAxes1_3,'Conductance, S/m')
                    title(app.UIAxes1_3,'G-V')

                    app.i=app.i+1;
                    app.j=app.j+1;
                    app.k=app.k+1;

                    app.measurement_var_names = {'Voltage', 'Capacitance','Conductance'};
                    app.measurement_table = table(app.voltage, app.capacitance,app.conductance ,'VariableNames', app.measurement_var_names);
                    writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
                    writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet2);
                end
            end

            if strcmp(app.biasaftermeasure,'Off')
                writeline(app.Aktakom3048, sprintf('BIAS:VOLT %f',0))
            end

            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ;'Function', app.func ;'Speed', app.spd ;'Measurement Parameters' , ' '; 'Frequency [KHz]' , app.freq ; 'Level (Voltage)' , app.lev ; 'Start Bias' , app.startbias ;'Step Bias' , app.stepbias ; 'Stop Bias' , app.stopbias ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};

            [filename, pathname] = uiputfile('.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                app.measurement_table = table(app.voltage, app.capacitance,app.conductance ,'VariableNames', app.measurement_var_names);
                %write temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                figure(app.UIFigure)
            end

            app.StartExperimentButton.Enable="on";
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            if strcmp(app.biasaftermeasure,'Off')
                writeline(app.Aktakom3048, sprintf('BIAS:VOLT %f',0))
            end
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ;'Function', app.func ;'Speed', app.spd ;'Measurement Parameters' , ' '; 'Frequency [KHz]' , app.freq ; 'Level (Voltage)' , app.lev ; 'Start Bias' , app.startbias ;'Step Bias' , app.stepbias ; 'Stop Bias' , app.stopbias ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};
            [filename, pathname] = uiputfile('*.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                app.measurement_table = table(app.voltage, app.capacitance,app.conductance ,'VariableNames', app.measurement_var_names);
                %write temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                figure(app.UIFigure)
            end
            clc
            waitfor(app.StartExperimentButton)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CapacitanceVoltageTab = uitab(app.TabGroup);
            app.CapacitanceVoltageTab.Title = 'Capacitance-Voltage';
            app.TabGroup2 = uitabgroup(app.CapacitanceVoltageTab);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.AktakomRLCMeterAMM3048LampLabel = uilabel(app.ConnectionStatusPanel);
            app.AktakomRLCMeterAMM3048LampLabel.Text = 'Aktakom RLC Meter AMM-3048';
            app.AktakomRLCMeterAMM3048Lamp = uilamp(app.ConnectionStatusPanel);
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.FrequencyEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.FrequencyEditField.Value = 100;
            app.LevelVoltageLabel = uilabel(app.MeasurementParametersPanel);
            app.LevelVoltageLabel.Text = 'Level (Voltage)';
            app.LevelVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.LevelVoltageEditField.Value = 0.03;
            app.StartBiasEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StartBiasEditFieldLabel.Text = 'Start Bias';
            app.StartBiasEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.KHzLabel = uilabel(app.MeasurementParametersPanel);
            app.KHzLabel.Text = '[KHz]';
            app.VoltsLabel_3 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_3.Text = '[Volts]';
            app.StopBiasEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StopBiasEditFieldLabel.Text = 'Stop Bias';
            app.StopBiasEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StopBiasEditField.Value = 1;
            app.StepBiasEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StepBiasEditFieldLabel.Text = 'Step Bias';
            app.StepBiasEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StepBiasEditField.Value = 0.1;
            app.SpeedListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.SpeedListBoxLabel.Text = 'Speed';
            app.SpeedListBox = uilistbox(app.MeasurementParametersPanel);
            app.SpeedListBox.Items = {'FAST', 'MED', 'SLOW'};
            app.SpeedListBox.Value = 'MED';
            app.VoltsLabel_4 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_4.Text = '[Volts]';
            app.VoltsLabel_5 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_5.Text = '[Volts]';
            app.FunctionsListBoxLabel = uilabel(app.MeasurementParametersPanel);
            app.FunctionsListBoxLabel.Text = 'Functions';
            app.FunctionsListBox = uilistbox(app.MeasurementParametersPanel);
            app.FunctionsListBox.Items = {'CPG', 'CPRP', 'CSRS', 'CPQ', 'CPD    ', 'RX', 'LPRP    ', 'LSD'};
            app.FunctionsListBox.Value = 'CPG';
            app.VoltsLabel_9 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_9.Text = '[Volts]';
            app.SweepBiasSwitchLabel = uilabel(app.MeasurementParametersPanel);
            app.SweepBiasSwitchLabel.Text = 'Sweep Bias';
            app.SweepBiasSwitch = uiswitch(app.MeasurementParametersPanel, 'slider');
            app.BiasAfterMeasureSwitchLabel = uilabel(app.MeasurementParametersPanel);
            app.BiasAfterMeasureSwitchLabel.Text = 'Bias After Measure';
            app.BiasAfterMeasureSwitch = uiswitch(app.MeasurementParametersPanel, 'slider');
            app.MajorLabel = uilabel(app.MeasurementParametersPanel);
            app.MajorLabel.Text = 'Major';
            app.Panel = uipanel(app.MeasurementParametersPanel);
            app.Panel.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel, 'text');
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel_2 = uipanel(app.MeasurementParametersPanel);
            app.Panel_2.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel_2);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel_2, 'text');
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.Tables = uitab(app.TabGroup2);
            app.Tables.Title = 'Tables';
            app.UITable1 = uitable(app.Tables);
            app.UITable1.ColumnName = {'Voltage'; 'Capacitance, F'; 'G'};
            app.UITable1.RowName = {};
            app.CVLabel = uilabel(app.Tables);
            app.CVLabel.Text = 'C-V';
            app.GraphsTab = uitab(app.TabGroup2);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes1 = uiaxes(app.GraphsTab);
            title(app.UIAxes1, 'C-V ')
            xlabel(app.UIAxes1, 'Voltage, V')
            ylabel(app.UIAxes1, 'Capacitance, F')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1_3 = uiaxes(app.GraphsTab);
            title(app.UIAxes1_3, 'G-V')
            xlabel(app.UIAxes1_3, 'Voltage, V')
            ylabel(app.UIAxes1_3, 'Conductance, S/m')
            zlabel(app.UIAxes1_3, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.MeasurementsPanel = uipanel(app.GraphsTab);
            app.MeasurementsPanel.Title = 'Measurements:';
            app.VoltageLabel = uilabel(app.MeasurementsPanel);
            app.VoltageLabel.Text = 'Voltage:';
            app.VoltageTextArea = uitextarea(app.MeasurementsPanel);
            app.VoltsLabel_2 = uilabel(app.MeasurementsPanel);
            app.VoltsLabel_2.Text = '[Volts]';
            app.CapacitanceTextAreaLabel = uilabel(app.MeasurementsPanel);
            app.CapacitanceTextAreaLabel.Text = 'Capacitance:';
            app.CapacitanceTextArea = uitextarea(app.MeasurementsPanel);
            app.FaradLabel = uilabel(app.MeasurementsPanel);
            app.FaradLabel.Text = '[Farad]';
            app.ConductanceTextAreaLabel = uilabel(app.MeasurementsPanel);
            app.ConductanceTextAreaLabel.Text = 'Conductance:';
            app.ConductanceTextArea = uitextarea(app.MeasurementsPanel);
            app.SmLabel = uilabel(app.MeasurementsPanel);
            app.SmLabel.Text = '[S/m]';
            app.StartExperimentButton = uibutton(app.CapacitanceVoltageTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CapacitanceVoltageTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CapacitanceVoltageTab);
            app.StopExperimentButton = uibutton(app.CapacitanceVoltageTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.CapacitanceFrequencyTab = uitab(app.TabGroup);
            app.CapacitanceFrequencyTab.Title = 'Capacitance-Frequency';
            app.TabGroup2_2 = uitabgroup(app.CapacitanceFrequencyTab);
            app.SettingsTab_2 = uitab(app.TabGroup2_2);
            app.SettingsTab_2.Title = 'Settings';
            app.ConnectionStatusPanel_2 = uipanel(app.SettingsTab_2);
            app.ConnectionStatusPanel_2.Title = 'Connection Status';
            app.AktakomRLCMeterAMM3048Lamp_2Label = uilabel(app.ConnectionStatusPanel_2);
            app.AktakomRLCMeterAMM3048Lamp_2Label.Text = 'Aktakom RLC Meter AMM-3048';
            app.AktakomRLCMeterAMM3048Lamp_2 = uilamp(app.ConnectionStatusPanel_2);
            app.MeasurementParametersPanel_2 = uipanel(app.SettingsTab_2);
            app.MeasurementParametersPanel_2.Title = 'Measurement Parameters';
            app.BiasEditFieldLabel = uilabel(app.MeasurementParametersPanel_2);
            app.BiasEditFieldLabel.Text = 'Bias';
            app.BiasEditField = uieditfield(app.MeasurementParametersPanel_2, 'numeric');
            app.BiasEditField.Value = 100;
            app.LevelVoltageEditField_2Label = uilabel(app.MeasurementParametersPanel_2);
            app.LevelVoltageEditField_2Label.Text = 'Level (Voltage)';
            app.LevelVoltageEditField_2 = uieditfield(app.MeasurementParametersPanel_2, 'numeric');
            app.LevelVoltageEditField_2.Value = 0.03;
            app.StartBiasEditField_2Label = uilabel(app.MeasurementParametersPanel_2);
            app.StartBiasEditField_2Label.Text = 'Start Bias';
            app.StartBiasEditField_2 = uieditfield(app.MeasurementParametersPanel_2, 'numeric');
            app.StopBiasEditField_2Label = uilabel(app.MeasurementParametersPanel_2);
            app.StopBiasEditField_2Label.Text = 'Stop Bias';
            app.StopBiasEditField_2 = uieditfield(app.MeasurementParametersPanel_2, 'numeric');
            app.StopBiasEditField_2.Value = 1;
            app.StepBiasEditField_2Label = uilabel(app.MeasurementParametersPanel_2);
            app.StepBiasEditField_2Label.Text = 'Step Bias';
            app.StepBiasEditField_2 = uieditfield(app.MeasurementParametersPanel_2, 'numeric');
            app.StepBiasEditField_2.Value = 0.1;
            app.VoltsLabel_10 = uilabel(app.MeasurementParametersPanel_2);
            app.VoltsLabel_10.Text = '[Volts]';
            app.VoltsLabel_6 = uilabel(app.MeasurementParametersPanel_2);
            app.VoltsLabel_6.Text = '[Volts]';
            app.KHzLabel_4 = uilabel(app.MeasurementParametersPanel_2);
            app.KHzLabel_4.Text = '[KHz]';
            app.KHzLabel_5 = uilabel(app.MeasurementParametersPanel_2);
            app.KHzLabel_5.Text = '[KHz]';
            app.SpeedListBox_2Label = uilabel(app.MeasurementParametersPanel_2);
            app.SpeedListBox_2Label.Text = 'Speed';
            app.SpeedListBox_2 = uilistbox(app.MeasurementParametersPanel_2);
            app.SpeedListBox_2.Items = {'FAST', 'MED', 'SLOW'};
            app.SpeedListBox_2.Value = 'MED';
            app.FunctionsListBox_2Label = uilabel(app.MeasurementParametersPanel_2);
            app.FunctionsListBox_2Label.Text = 'Functions';
            app.FunctionsListBox_2 = uilistbox(app.MeasurementParametersPanel_2);
            app.FunctionsListBox_2.Items = {'CPG', 'CPRP', 'CSRS', 'CPQ', 'CPD    ', 'RX', 'LPRP    ', 'LSD'};
            app.FunctionsListBox_2.Value = 'CPG';
            app.KHzLabel_6 = uilabel(app.MeasurementParametersPanel_2);
            app.KHzLabel_6.Text = '[KHz]';
            app.Panel_3 = uipanel(app.SettingsTab_2);
            app.Panel_3.Title = ' ';
            app.SampleInfoEditFieldLabel_2 = uilabel(app.Panel_3);
            app.SampleInfoEditFieldLabel_2.Text = 'Sample Info';
            app.SampleInfoEditField_2 = uieditfield(app.Panel_3, 'text');
            app.SampleInfoEditField_2.Value = 'Please Enter the Sample Info';
            app.Panel_4 = uipanel(app.SettingsTab_2);
            app.Panel_4.Title = ' ';
            app.CommentsEditField_2Label = uilabel(app.Panel_4);
            app.CommentsEditField_2Label.Text = 'Comments';
            app.CommentsEditField_2 = uieditfield(app.Panel_4, 'text');
            app.CommentsEditField_2.Value = 'Please Enter the Comments';
            app.Tables_2 = uitab(app.TabGroup2_2);
            app.Tables_2.Title = 'Tables';
            app.UITable1_2 = uitable(app.Tables_2);
            app.UITable1_2.ColumnName = {'Voltage'; 'Capacitance, F'; 'G'};
            app.UITable1_2.RowName = {};
            app.CVLabel_2 = uilabel(app.Tables_2);
            app.CVLabel_2.Text = 'C-V';
            app.GraphsTab_2 = uitab(app.TabGroup2_2);
            app.GraphsTab_2.Title = 'Graphs';
            app.UIAxes1_2 = uiaxes(app.GraphsTab_2);
            title(app.UIAxes1_2, 'C-F Measurement')
            xlabel(app.UIAxes1_2, 'Voltage, V')
            ylabel(app.UIAxes1_2, 'Capacitance, F')
            zlabel(app.UIAxes1_2, 'Z')
            app.StateoftheExperimentPanel_2 = uipanel(app.GraphsTab_2);
            app.StateoftheExperimentPanel_2.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea_2 = uitextarea(app.StateoftheExperimentPanel_2);
            app.MeasurementsPanel_2 = uipanel(app.GraphsTab_2);
            app.MeasurementsPanel_2.Title = 'Measurements:';
            app.FrequencyLabel = uilabel(app.MeasurementsPanel_2);
            app.FrequencyLabel.Text = 'Frequency:';
            app.FrequencyTextArea = uitextarea(app.MeasurementsPanel_2);
            app.CapacitanceTextArea_2Label = uilabel(app.MeasurementsPanel_2);
            app.CapacitanceTextArea_2Label.Text = 'Capacitance:';
            app.CapacitanceTextArea_2 = uitextarea(app.MeasurementsPanel_2);
            app.KHzLabel_3 = uilabel(app.MeasurementsPanel_2);
            app.KHzLabel_3.Text = '[KHz]';
            app.FaradLabel_2 = uilabel(app.MeasurementsPanel_2);
            app.FaradLabel_2.Text = '[Farad]';
            app.StartExperimentButton_2 = uibutton(app.CapacitanceFrequencyTab, 'push');
            app.StartExperimentButton_2.Text = 'Start Experiment';
            app.IndicatorLamp_2Label = uilabel(app.CapacitanceFrequencyTab);
            app.IndicatorLamp_2Label.Text = 'Indicator';
            app.IndicatorLamp_2 = uilamp(app.CapacitanceFrequencyTab);
            app.StopExperimentButton_2 = uibutton(app.CapacitanceFrequencyTab, 'push');
            app.StopExperimentButton_2.Text = 'Stop Experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVAktakomapp3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end