classdef IV_Keithley_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CurrentVoltageIVKeithleyTab     matlab.ui.container.Tab
        SaveDataButton                  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        ConnectionStatusPanel           matlab.ui.container.Panel
        KEITHLEYSourceMeter2450Lamp     matlab.ui.control.Lamp
        KEITHLEYSourceMeter2450LampLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        CommonSettingsPanel             matlab.ui.container.Panel
        RepeatEditField                 matlab.ui.control.NumericEditField
        RepeatEditFieldLabel            matlab.ui.control.Label
        CalculatedPointsEditField       matlab.ui.control.NumericEditField
        CalculatedPointsEditFieldLabel  matlab.ui.control.Label
        SourceSweepPointsEditField      matlab.ui.control.NumericEditField
        SourceSweepPointsEditFieldLabel  matlab.ui.control.Label
        VoltageaftermeasureSwitch       matlab.ui.control.Switch
        VoltageaftermeasureSwitchLabel  matlab.ui.control.Label
        MeasurecountEditField           matlab.ui.control.NumericEditField
        NPLCEditField_2Label_2          matlab.ui.control.Label
        NPLCEditField                   matlab.ui.control.NumericEditField
        NPLCEditField_2Label            matlab.ui.control.Label
        MeasurementParametersPanel      matlab.ui.container.Panel
        VoltsLabel_5                    matlab.ui.control.Label
        OutputOffEditField              matlab.ui.control.NumericEditField
        OutputOffEditFieldLabel         matlab.ui.control.Label
        VoltsLabel_4                    matlab.ui.control.Label
        AutoZeroEditField               matlab.ui.control.NumericEditField
        AutoZeroEditFieldLabel          matlab.ui.control.Label
        SecLabel                        matlab.ui.control.Label
        MinimumRangeEditField           matlab.ui.control.NumericEditField
        MinimumRangeEditFieldLabel      matlab.ui.control.Label
        SecLabel_2                      matlab.ui.control.Label
        CurrentRangeEditField           matlab.ui.control.NumericEditField
        PulseTimeLabel                  matlab.ui.control.Label
        VoltsLabel_2                    matlab.ui.control.Label
        VoltageRangeEditField           matlab.ui.control.NumericEditField
        PulseVoltageLabel               matlab.ui.control.Label
        AmpsLabel_2                     matlab.ui.control.Label
        CurrentLimitEditField           matlab.ui.control.NumericEditField
        CurrentLimitEditFieldLabel      matlab.ui.control.Label
        VoltsLabel_7                    matlab.ui.control.Label
        StopVoltageEditField            matlab.ui.control.NumericEditField
        StopVoltageEditFieldLabel       matlab.ui.control.Label
        VoltsLabel_6                    matlab.ui.control.Label
        StartVoltageEditField           matlab.ui.control.NumericEditField
        StartVoltageEditFieldLabel      matlab.ui.control.Label
        IVKeithleyLabel                 matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        UITable1                        matlab.ui.control.Table
        MeasurementTableLabel           matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        InstantMeasurementsPanel        matlab.ui.container.Panel
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel_2                  matlab.ui.control.Label
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        UIAxes1                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SampleInfo;
        Comments;
        additionalComments;
        additionalCommentsCell;
        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;

        data;
        sheet1;
        settings;
        measurement_var_names;
        defaultFolder;

        KLYSM2450;
        connectionStatusKeithley;

        freq;
        measurement_window;
        consttableData;
        measurement_table;
        aftervoltage;
        

    end

    methods (Access = public)
        function connectDeviceKeithley(app)
            if strcmp(app.connectionStatusKeithley,'Connected')
                delete(app.KLYSM2450)
                app.connectionStatusKeithley='Not Connected';
            end
            try
                app.KLYSM2450=visadev("USB0::0x05E6::0x2450::04429200::0::INSTR");
                app.KEITHLEYSourceMeter2450Lamp.Color='g';
                app.connectionStatusKeithley='Connected';
            catch
                app.KEITHLEYSourceMeter2450Lamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('The Phase: The Connection to at least one of the devices has failed!');
                app.connectionStatusKeithley='Not Connected';

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %% Initialization:

            format longE
            app.connectDeviceKeithley();
            if ~strcmp(app.connectionStatusKeithley,'Connected')
                uialert(app.UIFigure,'Cannot start the experiment because the device is not connected','Connection Error')
                clear IV_Keithley_1;
                return;
            end

            %Clearing Data, Tables, Graphs:
            app.IndicatorLamp.Color='y'; % Yellow means experiment has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Experiment has started, resetting and initializing parameters');
            app.StartExperimentButton.Enable="off";

            cla(app.UIAxes1)
            app.UITable1.Data=[];
            app.UITable2.Data=[];
            
            app.T0=app.StartVoltageEditField.Value;
            app.Tf=app.StopVoltageEditField.Value;
            app.tp=app.CurrentRangeEditField.Value;
            app.Vp=app.VoltageRangeEditField.Value;
            app.V0=app.CurrentLimitEditField.Value;
            app.Vf=app.AutoZeroEditField.Value;
            app.MinimumRangeEditField=app.MinimumRangeEditField.Value;

            app.NPLC=app.NPLCEditField.Value;
            app.Measurecount=app.MeasurecountEditField.Value;

            app.Range=app.RangeEditField.Value; % Heating
            app.Ramp=app.RampEditField.Value;
            app.P=app.PEditField.Value;
            app.I=app.IEditField.Value;
            app.D=app.DEditField.Value;

            app.Step=app.StepEditField.Value; %Step poitns of temperature at which measurements are taken
            app.AccuracyStable=app.AccuracyStableEditField.Value;
            app.AccuracyStep=app.AccuracyStepEditField.Value;
            app.AccuracyEnd=app.AccuracyEndEditField.Value;

            app.SampleInfo=app.SampleInfoEditField.Value;
            app.Comments=app.CommentsEditField.Value;


            %% Saving parameters
            if exist('data.xlsx','file')==2
                delete('data.xlsx')
            end
            app.data='data.xlsx';
            app.sheet1 ='Settings';
            app.sheet2 ='Stabilization';
            app.sheet3='Pulse';
            app.sheet4 ='Measurements';

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ;'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};
            app.defaultFolder='D:\RRAM\Measurements';

            app.stablization_var_names = {'Temperature', 'Current','Temp Rate'};
            app.pulse_var_names = {'Temperature', 'Current','Time'};
            app.measurement_var_names = {'Temperature', 'Current','Temp Rate'};
            app.stablization_table = table(app.initialtemp, app.initialcurrent, app.initialrealramping,'VariableNames', app.stablization_var_names);
            app.pulse_table = table(app.realtemppulse, app.currentpulse,app.pulseelapsedtime,'VariableNames', app.pulse_var_names);
            app.measurement_table = table(app.realtemp, app.current, app.realramping ,'VariableNames', app.measurement_var_names);

            writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
            writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
            writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
            writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);


            app.freq=str2double(writeread(app.KLYSM2450,'print(frequency)'));
            app.measurement_window=app.NPLC/app.freq;
            app.consttableData=[app.freq, app.NPLC,app.measurement_window];
            app.UITable2.ColumnFormat={'numeric'};
            app.UITable2.Data=app.consttableData;

            app.aftervoltage=app.VoltageaftermeasureSwitch.Value;

            %% Device Settings:
            writeline(app.KLYSM2450,'*RST')
            pause(0.1)

            %Measurement Settings for KEITHLEY:
            writeline(app.KLYSM2450,'smu.measure.terminals = smu.TERMINALS_REAR')
            writeline(app.KLYSM2450,'smu.measure.sense = smu.SENSE_2WIRE')
            writeline(app.KLYSM2450,'smu.measure.autorange = smu.ON')
            writeline(app.KLYSM2450, strcat('smu.measure.count = ',string(app.Measurecount)))
            writeline(app.KLYSM2450, strcat('smu.measure.nplc=', string(app.NPLC)))
            writeline(app.KLYSM2450,'frequency=localnode.linefreq')

            %Voltage is the SOURCE function, Current is measurement:
            writeline(app.KLYSM2450,'smu.measure.func = smu.FUNC_DC_CURRENT')
            writeline(app.KLYSM2450,'smu.source.func = smu.FUNC_DC_VOLTAGE')

            % Source Settings for KEITHLEY:
            writeline(app.KLYSM2450,'smu.source.highc = smu.OFF')
            writeline(app.KLYSM2450,'smu.source.autorange = smu.ON')
            writeline(app.KLYSM2450,'smu.source.readback = smu.ON')
            writeline(app.KLYSM2450,'smu.source.output = smu.ON')
            writeline(app.KLYSM2450,'smu.source.ilimit.level = 0.1')

            %% Finishing the Experiment
            writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            if strcmp(app.aftervoltage,'On')
                writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp)))
            end
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KLYSM2450, 'smu.source.level = 0')
            end

            pause(1);
            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done!');
            app.IndicatorLamp.Color='w';
            app.KEITHLEYSourceMeter2450Lamp.Color='w';
            app.StartExperimentButton.Enable="on";

            clc
            clearvars -global
            clear IV_Keithley_1
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            app.LakeShoreTemperatureController336Lamp.Color='w';
            app.KEITHLEYSourceMeter2450Lamp.Color='w';

            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment has been stopped, Save the data!');
            writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            writeline(app.LSTC336,'RAMP 1,0,0') % Set Ramping Off
            if strcmp(app.afterrange,'On')
                writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            end
            if strcmp(app.afterrange,'Off')
                writeline(app.LSTC336,('RANGE 1, 0'))
            end
            if strcmp(app.aftervoltage,'On')
                writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp)))
            end
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KLYSM2450, 'smu.source.level = 0')
            end

            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ; 'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};

            [filename, pathname] = uiputfile('*.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                StabilizationRamp=app.initialrealramping;
                PulseTemperature=app.realtemppulse;
                PulseCurrent=app.currentpulse;
                PulseTime=app.pulseelapsedtime;
                Measurementsramping=app.realramping;
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                writetable(app.pulse_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet4);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                figure(app.UIFigure)
            end

            if exist('initialstart','var') == 1 && ~isempty(initialstart)
                app.initialtime=toc(initialstart);
            end
            if exist('start','var') == 1 && ~isempty(start)
                app.time=toc(start);
            end

            clc
            waitfor(app.StartExperimentButton)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end

        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ; 'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};

            [filename, pathname] = uiputfile('*.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                StabilizationRamp=app.initialrealramping;
                PulseTemperature=app.realtemppulse;
                PulseCurrent=app.currentpulse;
                PulseTime=app.pulseelapsedtime;
                Measurementsramping=app.realramping;
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                writetable(app.pulse_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet4);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                figure(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CurrentVoltageIVKeithleyTab = uitab(app.TabGroup2);
            app.CurrentVoltageIVKeithleyTab.Title = 'Current-Voltage (I-V, Keithley)';
            app.TabGroup = uitabgroup(app.CurrentVoltageIVKeithleyTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.IVKeithleyLabel = uilabel(app.SettingsTab);
            app.IVKeithleyLabel.Text = 'I-V / Keithley';
            app.MeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MeasurementParametersPanel.Title = 'Measurement Parameters';
            app.StartVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StartVoltageEditFieldLabel.Text = 'Start Voltage';
            app.StartVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StartVoltageEditField.Value = 80.5;
            app.VoltsLabel_6 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_6.Text = '[Volts]';
            app.StopVoltageEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.StopVoltageEditFieldLabel.Text = 'Stop Voltage';
            app.StopVoltageEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.StopVoltageEditField.Value = 450;
            app.VoltsLabel_7 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_7.Text = '[Volts]';
            app.CurrentLimitEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.CurrentLimitEditFieldLabel.Text = 'Current Limit';
            app.CurrentLimitEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.AmpsLabel_2 = uilabel(app.MeasurementParametersPanel);
            app.AmpsLabel_2.Text = '[Amps]';
            app.PulseVoltageLabel = uilabel(app.MeasurementParametersPanel);
            app.PulseVoltageLabel.Text = 'Voltage Range';
            app.VoltageRangeEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.VoltsLabel_2 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_2.Text = '[Volts]';
            app.PulseTimeLabel = uilabel(app.MeasurementParametersPanel);
            app.PulseTimeLabel.Text = 'Current Range';
            app.CurrentRangeEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.CurrentRangeEditField.Value = 5;
            app.SecLabel_2 = uilabel(app.MeasurementParametersPanel);
            app.SecLabel_2.Text = '[Sec]';
            app.MinimumRangeEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.MinimumRangeEditFieldLabel.Text = 'Minimum Range';
            app.MinimumRangeEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.SecLabel = uilabel(app.MeasurementParametersPanel);
            app.SecLabel.Text = '[Sec]';
            app.AutoZeroEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.AutoZeroEditFieldLabel.Text = 'Auto Zero';
            app.AutoZeroEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.VoltsLabel_4 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_4.Text = '[Volts]';
            app.OutputOffEditFieldLabel = uilabel(app.MeasurementParametersPanel);
            app.OutputOffEditFieldLabel.Text = 'Output Off';
            app.OutputOffEditField = uieditfield(app.MeasurementParametersPanel, 'numeric');
            app.VoltsLabel_5 = uilabel(app.MeasurementParametersPanel);
            app.VoltsLabel_5.Text = '[Volts]';
            app.CommonSettingsPanel = uipanel(app.SettingsTab);
            app.CommonSettingsPanel.Title = 'Common Settings';
            app.NPLCEditField_2Label = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label.Text = 'NPLC';
            app.NPLCEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.NPLCEditField.Value = 5;
            app.NPLCEditField_2Label_2 = uilabel(app.CommonSettingsPanel);
            app.NPLCEditField_2Label_2.Text = 'Measurecount';
            app.MeasurecountEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.MeasurecountEditField.Value = 1;
            app.VoltageaftermeasureSwitchLabel = uilabel(app.CommonSettingsPanel);
            app.VoltageaftermeasureSwitchLabel.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch = uiswitch(app.CommonSettingsPanel, 'slider');
            app.SourceSweepPointsEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.SourceSweepPointsEditFieldLabel.Text = {'Source/ Sweep '; 'Points'};
            app.SourceSweepPointsEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.SourceSweepPointsEditField.Value = 5;
            app.CalculatedPointsEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.CalculatedPointsEditFieldLabel.Text = 'Calculated Points';
            app.CalculatedPointsEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.CalculatedPointsEditField.Value = 5;
            app.RepeatEditFieldLabel = uilabel(app.CommonSettingsPanel);
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.CommonSettingsPanel, 'numeric');
            app.RepeatEditField.Value = 5;
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_2);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_2, 'text');
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.KEITHLEYSourceMeter2450LampLabel = uilabel(app.ConnectionStatusPanel);
            app.KEITHLEYSourceMeter2450LampLabel.Text = 'KEITHLEY SourceMeter 2450';
            app.KEITHLEYSourceMeter2450Lamp = uilamp(app.ConnectionStatusPanel);
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.UITable2 = uitable(app.Tables);
            app.UITable2.ColumnName = {'Frequency'; 'NPLC'; 'Measurement Window'};
            app.UITable2.RowName = {};
            app.MeasurementTableLabel = uilabel(app.Tables);
            app.MeasurementTableLabel.Text = 'Measurement Table';
            app.UITable1 = uitable(app.Tables);
            app.UITable1.ColumnName = {'Current, [A]'; 'Voltage, [V]'; 'Time, [S]'};
            app.UITable1.RowName = {};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes1 = uiaxes(app.GraphsTab);
            title(app.UIAxes1, 'I-V Characteristics')
            xlabel(app.UIAxes1, 'Voltage [V]')
            ylabel(app.UIAxes1, 'Current [A]')
            zlabel(app.UIAxes1, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.InstantMeasurementsPanel = uipanel(app.GraphsTab);
            app.InstantMeasurementsPanel.Title = 'Instant Measurements:';
            app.VoltageLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel.Text = 'Current:';
            app.CurrentTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.AmpsLabel = uilabel(app.InstantMeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.VoltageLabel_2 = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage:';
            app.VoltageTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.VoltsLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.StartExperimentButton = uibutton(app.CurrentVoltageIVKeithleyTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CurrentVoltageIVKeithleyTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CurrentVoltageIVKeithleyTab);
            app.StopExperimentButton = uibutton(app.CurrentVoltageIVKeithleyTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataButton = uibutton(app.CurrentVoltageIVKeithleyTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IV_Keithley_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end