classdef RRAMappKeysight6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        TSCMethodKeysightTab            matlab.ui.container.Tab
        SaveDataButton                  matlab.ui.control.Button
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        ConnectionStatusPanel           matlab.ui.container.Panel
        KeysightElectrometerB2987ALamp  matlab.ui.control.Lamp
        KeysightElectrometerB2987ALampLabel  matlab.ui.control.Label
        LakeShoreTemperatureController336Lamp  matlab.ui.control.Lamp
        LakeShoreTemperatureController336LampLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SampleInfoEditField             matlab.ui.control.EditField
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        KeysightSMParametersPanel       matlab.ui.container.Panel
        VoltageaftermeasureSwitch       matlab.ui.control.Switch
        VoltageaftermeasureSwitchLabel  matlab.ui.control.Label
        MeasurecountEditField           matlab.ui.control.NumericEditField
        NPLCEditField_2Label_2          matlab.ui.control.Label
        NPLCEditField                   matlab.ui.control.NumericEditField
        NPLCEditField_2Label            matlab.ui.control.Label
        LakeshoreTCParametersPanel      matlab.ui.container.Panel
        HeateraftermeasureSwitch        matlab.ui.control.Switch
        HeateraftermeasureSwitchLabel   matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        IEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        HMLOLabel                       matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        KMinLabel                       matlab.ui.control.Label
        RampEditField                   matlab.ui.control.NumericEditField
        RampEditFieldLabel              matlab.ui.control.Label
        SubsidiaryMeasurementParametersPanel  matlab.ui.container.Panel
        KelvinLabel_8                   matlab.ui.control.Label
        AccuracyEndEditField            matlab.ui.control.NumericEditField
        AccuracyEndLabel                matlab.ui.control.Label
        KelvinLabel_6                   matlab.ui.control.Label
        AccuracyStableEditField         matlab.ui.control.NumericEditField
        AccuracyStableLabel             matlab.ui.control.Label
        KelvinLabel_7                   matlab.ui.control.Label
        AccuracyStepEditField           matlab.ui.control.NumericEditField
        AccuracyStepLabel               matlab.ui.control.Label
        KelvinLabel_5                   matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        StepEditField_4Label            matlab.ui.control.Label
        MajorMeasurementParametersPanel  matlab.ui.container.Panel
        VoltsLabel_4                    matlab.ui.control.Label
        MeasurementVoltageEditField     matlab.ui.control.NumericEditField
        MeasurementVoltageEditFieldLabel  matlab.ui.control.Label
        SecondLabel_2                   matlab.ui.control.Label
        DelayAfterPulseEditField        matlab.ui.control.NumericEditField
        DelayAfterPulseEditFieldLabel   matlab.ui.control.Label
        SecondLabel                     matlab.ui.control.Label
        PulseTimeEditField              matlab.ui.control.NumericEditField
        PulseTimeLabel                  matlab.ui.control.Label
        VoltsLabel_2                    matlab.ui.control.Label
        PulseVoltageEditField           matlab.ui.control.NumericEditField
        PulseVoltageLabel               matlab.ui.control.Label
        VoltsLabel_3                    matlab.ui.control.Label
        PreliminaryVoltageEditField     matlab.ui.control.NumericEditField
        PreliminaryVoltageEditFieldLabel  matlab.ui.control.Label
        KelvinLabel_4                   matlab.ui.control.Label
        FinalTemperatureEditField       matlab.ui.control.NumericEditField
        FinalTemperatureEditFieldLabel  matlab.ui.control.Label
        KelvinLabel_3                   matlab.ui.control.Label
        InitialTemperatureEditField     matlab.ui.control.NumericEditField
        InitialTemperatureEditFieldLabel  matlab.ui.control.Label
        TSCKeysightLabel                matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        PulsePhaseLabel                 matlab.ui.control.Label
        UITable2_2                      matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        ConstantValuesLabel             matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        UITable1                        matlab.ui.control.Table
        MeasurementPhaseLabel           matlab.ui.control.Label
        StabilizationPhaseLabel         matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        InstantMeasurementsPanel        matlab.ui.container.Panel
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel_2                  matlab.ui.control.Label
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        HeaterActivatedTextArea         matlab.ui.control.TextArea
        HeaterActivatedLabel            matlab.ui.control.Label
        KelvinLabel                     matlab.ui.control.Label
        TemperatureTextArea             matlab.ui.control.TextArea
        TemperatureLabel                matlab.ui.control.Label
        KMinLabel_2                     matlab.ui.control.Label
        TemperatureRateTextArea         matlab.ui.control.TextArea
        TemperatureRateTextAreaLabel    matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %Establishing Connection Parameters
        LSTC336;
        KYST2987;
        connectionStatusLakeshore;
        connectionStatusKeysight;

        %Parameters:
        T0;
        Tf;
        tp;
        Vp;
        V0;
        Vf;
        NPLC;
        Measurecount;

        Range;
        Ramp;

        Heater;
        P;
        I;
        D;

        Step;
        AccuracyStable;
        AccuracyStep;
        AccuracyEnd;
        waitafterpulse;

        SampleInfo;
        Comments;
        additionalCommentsCell;
        additionalComments;


        data;
        sheet1;
        sheet2;
        sheet3;
        sheet4;

        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;

        settings;
        stablization_var_names;
        pulse_var_names;
        measurement_var_names;
        defaultFolder;

        freq;
        mw;
        consttableData;

        % Set Initial Variables for the Stabilization Phase
        k;
        l1;
        l2;
        k1;
        l3;
        l4;
        l5;
        l6;
        k2;

        initialtemp;
        initialvolt;
        initialcurrent;
        initialtempall;
        initialtempallrate;
        initialvoltall;
        initialcurrentall;
        initialtime;
        initialtimeall;
        initialrealramping;

        % Set Initial Variables for the Pulse Phase
        p;
        q;
        p1;
        realtemppulse;
        currentpulse;
        pulsetimebegin;
        pulseelapsedtime;

        % Set Initial Variables for the Measurement Phase
        n;
        i;
        m;
        j;
        i1;
        n1;
        m1;

        Startingtemperature;
        outputtemp;
        realtemp;
        realtempall;
        voltage;
        current;
        time;
        timeall;
        realramping;

        stablization_table;
        pulse_table;
        measurement_table;

        afterrange;
        aftervoltage;

    end

    methods (Access = public)

        function connectDeviceTempcontroller(app)
            if strcmp(app.connectionStatusLakeshore,'Connected')
                delete(app.LSTC336)
                app.connectionStatusLakeshore='Not Connected';
            end
            try
                app.LSTC336=serialport("COM3",57600);
                app.LakeShoreTemperatureController336Lamp.Color='g';
                app.connectionStatusLakeshore='Connected';
            catch
                app.LakeShoreTemperatureController336Lamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('The Phase: The Connection to at least one of the devices has failed!');
                app.connectionStatusLakeshore='Not Connected';
            end

        end

        function connectDeviceKeysight(app)
            if strcmp(app.connectionStatusKeysight,'Connected')
                delete(app.KYST2987)
                app.connectionStatusKeysight='Not Connected';
            end
            try
                app.KYST2987=visadev("USB0::0x0957::0x9418::MY54321288::0::INSTR");
                app.KeysightElectrometerB2987ALamp.Color='g';
                app.connectionStatusKeysight='Connected';
            catch
                app.KeysightElectrometerB2987ALamp.Color='r';
                app.StateoftheExperimentTextArea.Value=('The Phase: The Connection to at least one of the devices has failed!');
                app.connectionStatusKeysight='Not Connected';

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure(app.UIFigure)
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %%
            %Experiment has begun:
            format longE
            app.connectDeviceTempcontroller();
            app.connectDeviceKeysight();
            if ~strcmp(app.connectionStatusKeysight,'Connected') || ~strcmp(app.connectionStatusLakeshore,'Connected')
                uialert(app.UIFigure,'Cannot start the experiment because at least one of the devices is not connected','Connection Error')
                clc
                clear
                return;
            end
            %Clearing Data, Tables, Graphs:
            app.IndicatorLamp.Color='y'; % Yellow means experiment has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Experiment has started, resetting and initializing parameters');
            app.StartExperimentButton.Enable="off";

            cla(app.UIAxes1)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)

            app.HeaterActivatedTextArea.FontColor='blue';

            app.UITable1.Data=[];
            app.UITable2.Data=[];
            app.UITable2_2.Data=[];

            app.T0=app.InitialTemperatureEditField.Value;
            app.Tf=app.FinalTemperatureEditField.Value;
            app.tp=app.PulseTimeEditField.Value;
            app.Vp=app.PulseVoltageEditField.Value;
            app.V0=app.PreliminaryVoltageEditField.Value;
            app.Vf=app.MeasurementVoltageEditField.Value;
            app.waitafterpulse=app.DelayAfterPulseEditField.Value;

            app.NPLC=app.NPLCEditField.Value;
            app.Measurecount=app.MeasurecountEditField.Value;

            app.Range=app.RangeEditField.Value; % Heating
            app.Ramp=app.RampEditField.Value;
            app.P=app.PEditField.Value;
            app.I=app.IEditField.Value;
            app.D=app.DEditField.Value;

            app.Step=app.StepEditField.Value; %Step poitns of temperature at which measurements are taken
            app.AccuracyStable=app.AccuracyStableEditField.Value;
            app.AccuracyStep=app.AccuracyStepEditField.Value;
            app.AccuracyEnd=app.AccuracyEndEditField.Value;

            app.SampleInfo=app.SampleInfoEditField.Value;
            app.Comments=app.CommentsEditField.Value;
            %app.StateofTheExperimentTextArea1.WordWrap="on";

            % The parameters for configuration of Temperature Controller Lake Shore Model 336
            app.LSTC336.FlowControl="none";
            app.LSTC336.Parity="odd";
            app.LSTC336.StopBits=1;
            app.LSTC336.DataBits=7;
            writeline(app.KYST2987,':SYST:COMM:ENAB 1,USB')

            %Reset all devices:
            writeline(app.LSTC336,'*RST')
            writeline(app.KYST2987,'*RST')
            pause(0.1)

            %Measuring Starting Temperature
            app.Startingtemperature=str2double(writeread(app.LSTC336,'KRDG?1'));

            % Set Initial Variables for the Stabilization Phase
            app.k=1;
            app.l1=1;
            app.l2=1;
            app.k1=1;
            app.l3=1;
            app.l4=1;
            app.l5=1;
            app.l6=1;
            app.k2=1;

            app.initialtemp =zeros(app.k,1);
            app.initialvolt=zeros(app.l1,1);
            app.initialcurrent=zeros(app.l2,1);
            app.initialtempall=zeros(app.k1,1);
            app.initialtempallrate=zeros(app.k2,1);
            app.initialvoltall=zeros(app.l3,1);
            app.initialcurrentall=zeros(app.l4,1);
            app.initialtimeall=zeros(app.l5,1);
            app.initialrealramping=zeros(app.l6,1);

            % Set Initial Variables for the Measurement Phase
            app.p=1;
            app.q=1;
            app.p1=1;
            app.realtemppulse=zeros(app.p,1);
            app.currentpulse=zeros(app.q,1);
            app.pulseelapsedtime=zeros(app.p1,1);

            % Set Initial Variables for the Measurement Phase
            app.n=1;
            app.i=1;
            app.m=1;
            app.j=1;
            app.i1=1;
            app.n1=1;
            app.m1=1;

            app.outputtemp=zeros(app.n,1);
            app.realtemp=zeros(app.i,1);
            app.realtempall=zeros(app.i1,1);
            app.voltage=zeros(app.m,1);
            app.current=zeros(app.j,1);
            app.timeall=zeros(app.n1,1);
            app.realramping=zeros(app.m1,1);

            % define measurement parameters and data for saving data
            if exist('data.xlsx','file')==2
                delete('data.xlsx')
            end
            app.data='data.xlsx';
            app.sheet1 ='Settings';
            app.sheet2 ='Stabilization';
            app.sheet3='Pulse';
            app.sheet4 ='Measurements';

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ;'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};
            app.defaultFolder='D:\RRAM\Measurements';

            app.stablization_var_names = {'Temperature', 'Current','Temp Rate'};
            app.pulse_var_names = {'Temperature', 'Current','Time'};
            app.measurement_var_names = {'Temperature', 'Current','Temp Rate'};
            app.stablization_table = table(app.initialtemp, app.initialcurrent, app.initialrealramping,'VariableNames', app.stablization_var_names);
            app.pulse_table = table(app.realtemppulse, app.currentpulse,app.pulseelapsedtime,'VariableNames', app.pulse_var_names);
            app.measurement_table = table(app.realtemp, app.current, app.realramping ,'VariableNames', app.measurement_var_names);

            writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
            writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
            writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
            writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);

            %Parameters for Keysight: Voltage is the SOURCE function, Current is SENS:
            writeline(app.KYST2987,':SENS:CURR:RANG:AUTO ON')
            writeline(app.KYST2987,':SENS:CURR:LIM:AUTO ON')

            writeline(app.KYST2987,':SOUR:FUNC:MODE VOLT')
            writeline(app.KYST2987,':SOUR:VOLT:RANG:AUTO ON')

            writeline(app.KYST2987,':SENS:CURR:NPLC:AUTO OFF')
            writeline(app.KYST2987, sprintf(':SENS:CURR:NPLC %f', (app.NPLC)))
            writeline(app.KYST2987, sprintf('trig:coun %f',(app.Measurecount)))

            writeline(app.KYST2987, 'trig:sour aint')
            writeline(app.KYST2987, 'trig:acq:del 2e-4')
            writeline(app.KYST2987,':OUTP ON')
            writeline(app.KYST2987,':INP ON')
            writeline(app.KYST2987,':SYST:LFR:DET:AUTO')

            app.freq=str2double(writeread(app.KYST2987,':SYST:LFR?'));
            app.mw=app.NPLC/app.freq;
            app.consttableData=[app.freq, app.NPLC,app.mw];
            app.UITable3.ColumnFormat={'numeric'};
            app.UITable3.Data=app.consttableData;

            % Set Initial Parameters for the Temperature Controller: RANGE,PID
            writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            pause(0.1)
            str_P=num2str(app.P);
            str_I=num2str(app.I);
            str_D=num2str(app.D);
            PID=strcat(str_P,',',str_I,',',str_D);
            writeline(app.LSTC336, strcat('PID 1,',PID))
            pause(0.1)
            app.afterrange=app.HeateraftermeasureSwitch.Value;
            app.aftervoltage=app.VoltageaftermeasureSwitch.Value;


            %%
            %Stabilization Phase:
            app.IndicatorLamp.Color='b'; % Blue means stabilization part has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Stabilizing initial temperature');

            %Set INITIAL Temperature
            writeline(app.LSTC336,'RAMP 1,0,0')
            pause(0.1)
            writeline(app.LSTC336, strcat('SETP 1,', string(app.T0)))  %initial temp
            writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.V0)))

            % Check the initial condition for more than onepoint, compare all values
            % to T0 and make sure it falls within the range, waits until temp is stable

            lowerBound=app.T0-app.AccuracyStable;
            upperBound=app.T0+app.AccuracyStable;

            %process of stabilizing temperature:

            initialstart=tic;
            while true

                writeline(app.KYST2987, 'INIT:acq')
                newTemp=str2double(writeread(app.LSTC336,'KRDG?1'));
                app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));

                %initialvoltagetest=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                initialcurrenttest=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                if isempty(newTemp)
                    break;
                end

                app.TemperatureTextArea.Value=num2str(newTemp);
                app.VoltageTextArea.Value=num2str('N/A');
                app.CurrentTextArea.Value=num2str(initialcurrenttest);
                app.HeaterActivatedTextArea.Value=num2str(app.Heater);

                app.initialtemp(app.k,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                app.initialtempall(app.k1,1)=app.initialtemp(app.k,1);
                app.initialtempallrate(app.k2,1)=app.initialtemp(app.k,1);
                app.initialcurrent(app.l2,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));
                app.initialcurrentall(app.l4,1)=app.initialcurrent(app.l2,1);

                plot(app.UIAxes1,app.initialtemp,app.initialcurrent)
                xlabel(app.UIAxes1,'Initial Temperature ; [T]=[Kelvin]')
                ylabel(app.UIAxes1,'Initial Current Linear ; [I]=[Amps]')
                title(app.UIAxes1,'Linear TSC Spectra During Cooling')

                semilogy(app.UIAxes2,app.initialtemp,abs(app.initialcurrent))
                xlabel(app.UIAxes2,'Initial Temperature ; [T]=[Kelvin]')
                ylabel(app.UIAxes2,'Initial Current Logarithmic ; [I]=[Amps]')
                title(app.UIAxes2,'Logarithmic TSC Spectra During Cooling')

                app.k1=app.k1+1;
                app.l3=app.l3+1;
                app.l4=app.l4+1;
                app.k2=app.k2+1;

                app.initialtime=toc(initialstart);
                app.initialtimeall(app.l5,1)=app.initialtime;

                if length(app.initialtimeall)>10
                    app.initialtimeall=app.initialtimeall(end-9:end);
                end
                if length(app.initialtempallrate)>10
                    app.initialtempallrate=app.initialtempallrate(end-9:end);
                end

                if app.l5>1
                    initialtimedifference=(app.initialtimeall(end)-app.initialtimeall(1))/60;
                    initialtempdifference=app.initialtempall(end)-app.initialtemp(1);
                    app.initialrealramping(app.l6,1)=(initialtempdifference./initialtimedifference);
                    initialrealramp=app.initialrealramping(app.l6,1);
                    app.TemperatureRateTextArea.Value=num2str(round(initialrealramp,2));
                    app.l6=app.l6+1;
                elseif app.l5==1
                    app.initialrealramping(app.l6,1)=0;
                    app.l6=app.l6+1;
                end
                app.k=app.k+1;
                app.l1=app.l1+1;
                app.l2=app.l2+1;

                initialtableData=[app.initialtempall, app.initialcurrentall,app.initialrealramping];
                app.UITable1.ColumnFormat={'numeric'};
                app.UITable1.Data=initialtableData;

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                StabilizationRamp=app.initialrealramping;
                PulseTemperature=app.realtemppulse;
                PulseCurrent=app.currentpulse;
                PulseTime=app.pulseelapsedtime;
                Measurementsramping=app.realramping;
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
                writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                pause(1)

                app.l5=app.l5+1;
                if length(app.initialtemp)>=10
                    last10Elements1=app.initialtemp(end-9:end);

                    rangeCheck=all(last10Elements1>=lowerBound & last10Elements1<=upperBound);
                    if rangeCheck
                        break;
                    end
                end

            end
            %%
            %Pulse Phase:
            writeline(app.KYST2987,':SYSTem:BEEPer 500,0.5')
            app.IndicatorLamp.Color='c'; % Cyan means pulse phase has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Pulse  has started');
            app.VoltageTextArea.Value=num2str('N/A');

            if app.tp>0
                app.pulsetimebegin=tic;
                while toc(app.pulsetimebegin)<app.tp
                    app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                    app.HeaterActivatedTextArea.Value=num2str(app.Heater);

                    writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vp))) %Sets the Pulse voltage
                    writeline(app.KYST2987, 'INIT:acq')
                    app.realtemppulse(app.p,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                    app.currentpulse(app.q,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));
                    app.pulseelapsedtime(app.p1,1)=toc(app.pulsetimebegin);

                    app.TemperatureTextArea.Value=num2str(app.realtemppulse(app.p,1));
                    app.CurrentTextArea.Value=num2str(app.currentpulse(app.q,1));

                    PulsetableData=[app.realtemppulse, app.currentpulse,app.pulseelapsedtime];
                    app.UITable2_2.ColumnFormat={'numeric'};
                    app.UITable2_2.Data=PulsetableData;

                    StabilizationTemperature = app.initialtempall;
                    StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                    StabilizationRamp=app.initialrealramping;
                    PulseTemperature=app.realtemppulse;
                    PulseCurrent=app.currentpulse;
                    PulseTime=app.pulseelapsedtime;
                    Measurementsramping=app.realramping;
                    MeasurementsTemperature = app.realtemp;
                    MeasurementsCurrent = app.current ; % replace this with your Measurements data

                    % Create tables for measurements
                    app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                    app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                    app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                    %write settings, temperature data and current data to the Excel file
                    writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                    writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
                    writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);
                    % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                    app.p=app.p+1;
                    app.q=app.q+1;
                    app.p1=app.p1+1;
                    pause(1)
                    writeline(app.KYST2987, ':TRAC:CLE')
                end
            elseif app.tp==0
                app.pulsetimebegin=tic;
                app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                app.HeaterActivatedTextArea.Value=num2str(app.Heater);

                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vp)))  %Sets the Pulse voltage
                writeline(app.KYST2987, 'INIT:acq')

                app.realtemppulse(app.p,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                app.currentpulse(app.q,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Pulse_Buffer.n,Pulse_Buffer.readings)'),', ')));
                app.pulseelapsedtime(app.p1,1)=toc(app.pulsetimebegin);
                app.TemperatureTextArea.Value=num2str(app.realtemppulse(app.p,1));
                app.CurrentTextArea.Value=num2str(app.currentpulse(app.q,1));

                PulsetableData=[app.realtemppulse, app.currentpulse,app.pulseelapsedtime];
                app.UITable2_2.ColumnFormat={'numeric'};
                app.UITable2_2.Data=PulsetableData;

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                StabilizationRamp=app.initialrealramping;
                PulseTemperature=app.realtemppulse;
                PulseCurrent=app.currentpulse;
                PulseTime=app.pulseelapsedtime;
                Measurementsramping=app.realramping;
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
                writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                pause(app.tp)
                writeline(app.KYST2987, ':TRAC:CLE')
            end
            %%
            % Measurment Phase:
            writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vf))) %Sets the final voltage
            app.StateoftheExperimentTextArea.Value=('The Wait Time after the Pulse  has started');
            app.VoltageTextArea.Value=num2str('N/A');
            pause(app.waitafterpulse)

            writeline(app.KYST2987,':SYSTem:BEEPer 1000,0.35')
            pause(0.35)
            writeline(app.KYST2987,':SYSTem:BEEPer 1000,0.35')

            app.IndicatorLamp.Color='r'; % red means measurement part has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Measurements have started');

            %PROCESS for T0 to Tf, Setting the final Voltage, Buffers and readin values
            writeline(app.LSTC336, strcat('RAMP 1,1,',string(app.Ramp)))
            pause(0.1)
            writeline(app.LSTC336, strcat('SETP 1,', string(app.Tf)))

            outputtemptest=str2double(writeread(app.LSTC336,'SETP?1'));
            realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
            app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
            app.HeaterActivatedTextArea.Value=num2str(app.Heater);

            lasttempforheating=realtemptest+app.AccuracyStep;
            lasttempforcooling=realtemptest-app.AccuracyStep;

            % The while loops: 1. The Heating, 2. The Cooling

            if outputtemptest<app.Tf % Means it's heating

                start=tic;
                while (realtemptest<(app.Tf+app.Step)) % Heating

                    realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
                    app.TemperatureTextArea.Value=num2str(realtemptest);
                    app.realtempall(app.i1,1)=realtemptest;
                    app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                    app.HeaterActivatedTextArea.Value=num2str(app.Heater);

                    if app.Heater>99
                        writeline(app.KYST2987,':SYSTem:BEEPer 2100,0.2')
                        app.HeaterActivatedTextArea.FontColor='red';
                    else
                        app.HeaterActivatedTextArea.FontColor='blue';
                    end

                    if abs((realtemptest-lasttempforheating))<=(app.AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(app.AccuracyStep)

                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Linear TSC Spectra During Warming')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Logarithmic TSC Spectra During Warming')

                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating+app.Step;
                        lasttempforcooling=lasttempforcooling+app.Step;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        app.time=toc(start);
                        app.timeall(app.n1,1)=app.time;

                        if length(app.timeall)>10
                            app.timeall=app.timeall(end-9:end);
                        end
                        if length(app.realtempall)>10
                            app.realtempall=app.realtempall(end-9:end);
                        end

                        if app.n1>1
                            timedifference=(app.timeall(end)-app.timeall(1))/60;
                            tempdifference=app.realtempall(end)-app.realtemp(1);
                            app.realramping(app.m1,1)=(tempdifference./timedifference);
                            realramp=app.realramping(app.m1,1);
                            app.TemperatureRateTextArea.Value=num2str(round(realramp,2));
                            app.m1=app.m1+1;
                        elseif app.n1==1
                            app.realramping(app.m1,1)=0;
                            app.m1=app.m1+1;
                        end
                        app.n1=app.n1+1;

                        MeasurementtableData=[app.realtemp, app.current,app.realramping];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        StabilizationRamp=app.initialrealramping;
                        PulseTemperature=app.realtemppulse;
                        PulseCurrent=app.currentpulse;
                        PulseTime=app.pulseelapsedtime;
                        Measurementsramping=app.realramping;
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                        app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                    end

                    if (realtemptest-lasttempforheating)>1
                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Linear TSC Spectra During Warming')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Logarithmic TSC Spectra During Warming')

                        %app.VoltageTextArea.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating+app.Step+2;
                        lasttempforcooling=lasttempforcooling+app.Step+2;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        app.time=toc(start);
                        app.timeall(app.n1,1)=app.time;

                        if length(app.timeall)>10
                            app.timeall=app.timeall(end-9:end);
                        end
                        if length(app.realtempall)>10
                            app.realtempall=app.realtempall(end-9:end);
                        end

                        if app.n1>1
                            timedifference=(app.timeall(end)-app.timeall(1))/60;
                            tempdifference=app.realtempall(end)-app.realtemp(1);
                            app.realramping(app.m1,1)=(tempdifference./timedifference);
                            realramp=app.realramping(app.m1,1);
                            app.TemperatureRateTextArea.Value=num2str(round(realramp,2));
                            app.m1=app.m1+1;
                        elseif app.n1==1
                            app.realramping(app.m1,1)=0;
                            app.m1=app.m1+1;
                        end
                        app.n1=app.n1+1;

                        MeasurementtableData=[app.realtemp, app.current,app.realramping];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        StabilizationRamp=app.initialrealramping;
                        PulseTemperature=app.realtemppulse;
                        PulseCurrent=app.currentpulse;
                        PulseTime=app.pulseelapsedtime;
                        Measurementsramping=app.realramping;
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                        app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                    end

                    writeline(app.KYST2987, ':TRAC:CLE')
                    app.i1=app.i1+1;

                    pause(0.25)
                    if (realtemptest>(app.Tf+app.AccuracyEnd))
                        app.IndicatorLamp.Color='g'; % green means Measurements have stopped
                        app.StateoftheExperimentTextArea.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');

                        prompt={'Enter additional comments:'};
                        dlgtitle='Additional Comments';
                        dims=[1 50];
                        definput={'No additional comments'};
                        app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
                        if isempty (app.additionalCommentsCell)
                            app.additionalComments='No Comments Provided';
                        else
                            app.additionalComments=app.additionalCommentsCell{1};
                        end
                        app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ; 'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};

                        [filename, pathname] = uiputfile('*.xlsx', 'Save as',app.defaultFolder);
                        if isequal(filename,0) || isequal(pathname,0)
                            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                            figure(app.UIFigure)
                        else
                            StabilizationTemperature = app.initialtempall;
                            StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                            StabilizationRamp=app.initialrealramping;
                            PulseTemperature=app.realtemppulse;
                            PulseCurrent=app.currentpulse;
                            PulseTime=app.pulseelapsedtime;
                            Measurementsramping=app.realramping;
                            MeasurementsTemperature = app.realtemp;
                            MeasurementsCurrent = app.current ; % replace this with your Measurements data

                            % Create tables for measurements
                            app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                            app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                            app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                            %write settings, temperature data and current data to the Excel file
                            writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                            writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                            writetable(app.pulse_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                            writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet4);
                            figure(app.UIFigure)

                        end
                        break
                    end
                end

            elseif outputtemptest>app.Tf %means it's cooling
                start=tic;
                while (realtemptest>(app.Tf-app.Step)) %cooling

                    realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
                    app.TemperatureTextArea.Value=num2str(realtemptest);
                    app.realtempall(app.i1,1)=realtemptest;
                    app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                    app.HeaterActivatedTextArea.Value=num2str(app.Heater);

                    if app.Heater>99
                        writeline(app.KYST2987,':SYSTem:BEEPer 2100,0.2')
                        app.HeaterActivatedTextArea.FontColor='red';
                    else
                        app.HeaterActivatedTextArea.FontColor='blue';
                    end

                    if abs((realtemptest-lasttempforheating))<=(app.AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(app.AccuracyStep)
                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Linear TSC Spectra During Warming')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Logarithmic TSC Spectra During Warming')

                        %app.VoltageTextArea.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating-app.Step;
                        lasttempforcooling=lasttempforcooling-app.Step;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        app.time=toc(start);
                        app.timeall(app.n1,1)=app.time;

                        if length(app.timeall)>10
                            app.timeall=app.timeall(end-9:end);
                        end
                        if length(app.realtempall)>10
                            app.realtempall=app.realtempall(end-9:end);
                        end

                        if app.n1>1
                            timedifference=(app.timeall(end)-app.timeall(1))/60;
                            tempdifference=app.realtempall(end)-app.realtemp(1);
                            app.realramping(app.m1,1)=(tempdifference./timedifference);
                            realramp=app.realramping(app.m1,1);
                            app.TemperatureRateTextArea.Value=num2str(round(realramp,2));
                            app.m1=app.m1+1;
                        elseif app.n1==1
                            app.realramping(app.m1,1)=0;
                            app.m1=app.m1+1;
                        end
                        app.n1=app.n1+1;

                        MeasurementtableData=[app.realtemp, app.current,app.realramping];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        StabilizationRamp=app.initialrealramping;
                        PulseTemperature=app.realtemppulse;
                        PulseCurrent=app.currentpulse;
                        PulseTime=app.pulseelapsedtime;
                        Measurementsramping=app.realramping;
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                        app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                    end

                    if abs(realtemptest-lasttempforheating)>1
                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Linear TSC Spectra During Warming')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Logarithmic TSC Spectra During Warming')

                        %app.VoltageTextArea.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating-app.Step-2;
                        lasttempforcooling=lasttempforcooling-app.Step-2;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        app.time=toc(start);
                        app.timeall(app.n1,1)=app.time;

                        if length(app.timeall)>10
                            app.timeall=app.timeall(end-9:end);
                        end
                        if length(app.realtempall)>10
                            app.realtempall=app.realtempall(end-9:end);
                        end

                        if app.n1>1
                            timedifference=(app.timeall(end)-app.timeall(1))/60;
                            tempdifference=app.realtempall(end)-app.realtemp(1);
                            app.realramping(app.m1,1)=(tempdifference./timedifference);
                            realramp=app.realramping(app.m1,1);
                            app.TemperatureRateTextArea.Value=num2str(round(realramp,2));
                            app.m1=app.m1+1;
                        elseif app.n1==1
                            app.realramping(app.m1,1)=0;
                            app.m1=app.m1+1;
                        end
                        app.n1=app.n1+1;

                        MeasurementtableData=[app.realtemp, app.current,app.realramping];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        StabilizationRamp=app.initialrealramping;
                        PulseTemperature=app.realtemppulse;
                        PulseCurrent=app.currentpulse;
                        PulseTime=app.pulseelapsedtime;
                        Measurementsramping=app.realramping;
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                        app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.pulse_table, fullfile(app.data), 'Sheet', app.sheet3);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet4);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                    end

                    writeline(app.KYST2987, ':TRAC:CLE')
                    app.i1=app.i1+1;

                    pause(0.25)
                    if (realtemptest<(app.Tf-app.AccuracyEnd))
                        app.IndicatorLamp.Color='g'; % green means Measurements have stopped
                        app.StateoftheExperimentTextArea.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');
                        prompt={'Enter additional comments:'};
                        dlgtitle='Additional Comments';
                        dims=[1 50];
                        definput={'No additional comments'};
                        app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
                        if isempty (app.additionalCommentsCell)
                            app.additionalComments='No Comments Provided';
                        else
                            app.additionalComments=app.additionalCommentsCell{1};
                        end
                        app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ; 'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};
                        [filename, pathname] = uiputfile('*.xlsx', 'Save as',app.defaultFolder);
                        if isequal(filename,0) || isequal(pathname,0)
                            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                            figure(app.UIFigure)
                        else
                            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                            StabilizationTemperature = app.initialtempall;
                            StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                            StabilizationRamp=app.initialrealramping;
                            PulseTemperature=app.realtemppulse;
                            PulseCurrent=app.currentpulse;
                            PulseTime=app.pulseelapsedtime;
                            Measurementsramping=app.realramping;
                            MeasurementsTemperature = app.realtemp;
                            MeasurementsCurrent = app.current ; % replace this with your Measurements data

                            % Create tables for measurements
                            app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                            app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                            app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                            %write settings, temperature data and current data to the Excel file
                            writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                            writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                            writetable(app.pulse_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                            writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet4);
                            figure(app.UIFigure)
                        end

                        break
                    end
                end

            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Final temperature and Set point temperature are equal!');
                app.IndicatorLamp.Color='0.50,0.50,0.50';
            end

            %%
            writeline(app.LSTC336,'RAMP 1,0,0') % Set Ramping Off
            if strcmp(app.afterrange,'On')
                writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            end
            if strcmp(app.afterrange,'Off')
                writeline(app.LSTC336,('RANGE 1, 0'))
            end
            if strcmp(app.aftervoltage,'On')
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vf)))
            end
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KYST2987,':OUTP OFF')
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(0)))
            end

            pause(1);
            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done!');
            app.IndicatorLamp.Color='w';
            app.StartExperimentButton.Enable="on";

            clc
            clear
            close all
            clearvars
            clearvars -global
            clear RRAM
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment has been stopped, Save the data!');

            writeline(app.LSTC336,'RAMP 1,0,0') % Set Ramping Off
            if strcmp(app.afterrange,'On')
                writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            end
            if strcmp(app.afterrange,'Off')
                writeline(app.LSTC336,('RANGE 1, 0'))
            end
            if strcmp(app.aftervoltage,'On')
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vf)))
            end
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KYST2987,':OUTP OFF')
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(0)))
            end

            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ; 'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};

            [filename, pathname] = uiputfile('*.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                StabilizationRamp=app.initialrealramping;
                PulseTemperature=app.realtemppulse;
                PulseCurrent=app.currentpulse;
                PulseTime=app.pulseelapsedtime;
                Measurementsramping=app.realramping;
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                writetable(app.pulse_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet4);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                figure(app.UIFigure)
            end

            if exist('initialstart','var') ==1 && ~isempty(initialstart)
                app.initialtime=toc(initialstart);
            end
            if exist('start','var') ==1 && ~isempty(start)
                app.time=toc(start);
            end

            clc
            waitfor(app.StartExperimentButton)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            selection=uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Close Request', 'Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            switch selection
                case 'Yes'
                    delete(app.UIFigure)
                case 'No'
                    return;
            end

        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            prompt={'Enter additional comments:'};
            dlgtitle='Additional Comments';
            dims=[1 50];
            definput={'No additional comments'};
            app.additionalCommentsCell=inputdlg(prompt,dlgtitle,dims,definput);
            if isempty (app.additionalCommentsCell)
                app.additionalComments='No Comments Provided';
            else
                app.additionalComments=app.additionalCommentsCell{1};
            end
            app.settings = {'Sample Info' , app.SampleInfo ; 'Comments' , app.Comments ; 'Additional Comments', app.additionalComments ; 'Major Measurement Parameters' , ' ';'Starting Temperature' , app.Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ;'Wait Time' , app.waitafterpulse ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'Clock', app.timeString};

            [filename, pathname] = uiputfile('*.xlsx', 'Save as',app.defaultFolder);
            if isequal(filename,0) || isequal(pathname,0)
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                figure(app.UIFigure)
            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                StabilizationRamp=app.initialrealramping;
                PulseTemperature=app.realtemppulse;
                PulseCurrent=app.currentpulse;
                PulseTime=app.pulseelapsedtime;
                Measurementsramping=app.realramping;
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, StabilizationRamp,'VariableNames', app.stablization_var_names);
                app.pulse_table = table(PulseTemperature, PulseCurrent,PulseTime,'VariableNames', app.pulse_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, Measurementsramping, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                writetable(app.pulse_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet4);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                figure(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TSCMethodKeysightTab = uitab(app.TabGroup2);
            app.TSCMethodKeysightTab.Title = 'TSC Method (Keysight)';
            app.TabGroup = uitabgroup(app.TSCMethodKeysightTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TSCKeysightLabel = uilabel(app.SettingsTab);
            app.TSCKeysightLabel.Text = 'TSC / Keysight';
            app.MajorMeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MajorMeasurementParametersPanel.Title = 'Major Measurement Parameters';
            app.InitialTemperatureEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.InitialTemperatureEditFieldLabel.Text = 'Initial Temperature';
            app.InitialTemperatureEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.InitialTemperatureEditField.Value = 80.5;
            app.KelvinLabel_3 = uilabel(app.MajorMeasurementParametersPanel);
            app.KelvinLabel_3.Text = '[Kelvin]';
            app.FinalTemperatureEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.FinalTemperatureEditFieldLabel.Text = 'Final Temperature';
            app.FinalTemperatureEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.FinalTemperatureEditField.Value = 450;
            app.KelvinLabel_4 = uilabel(app.MajorMeasurementParametersPanel);
            app.KelvinLabel_4.Text = '[Kelvin]';
            app.PreliminaryVoltageEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PreliminaryVoltageEditFieldLabel.Text = 'Preliminary Voltage';
            app.PreliminaryVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.VoltsLabel_3 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_3.Text = '[Volts]';
            app.PulseVoltageLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PulseVoltageLabel.Text = 'Pulse Voltage';
            app.PulseVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.VoltsLabel_2 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_2.Text = '[Volts]';
            app.PulseTimeLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PulseTimeLabel.Text = 'Pulse Time';
            app.PulseTimeEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.PulseTimeEditField.Value = 5;
            app.SecondLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.SecondLabel.Text = '[Second]';
            app.DelayAfterPulseEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.DelayAfterPulseEditFieldLabel.Text = 'Delay After Pulse';
            app.DelayAfterPulseEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.SecondLabel_2 = uilabel(app.MajorMeasurementParametersPanel);
            app.SecondLabel_2.Text = '[Second]';
            app.MeasurementVoltageEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.MeasurementVoltageEditFieldLabel.Text = 'Measurement Voltage';
            app.MeasurementVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.VoltsLabel_4 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_4.Text = '[Volts]';
            app.SubsidiaryMeasurementParametersPanel = uipanel(app.SettingsTab);
            app.SubsidiaryMeasurementParametersPanel.Title = 'Subsidiary Measurement Parameters';
            app.StepEditField_4Label = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.StepEditField_4Label.Text = 'Step';
            app.StepEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.StepEditField.Value = 0.3;
            app.KelvinLabel_5 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_5.Text = '[Kelvin]';
            app.AccuracyStepLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyStepLabel.Text = 'Accuracy Step';
            app.AccuracyStepEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyStepEditField.Value = 0.1;
            app.KelvinLabel_7 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_7.Text = '[Kelvin]';
            app.AccuracyStableLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyStableLabel.Text = 'Accuracy Stable';
            app.AccuracyStableEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyStableEditField.Value = 1;
            app.KelvinLabel_6 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_6.Text = '[Kelvin]';
            app.AccuracyEndLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyEndLabel.Text = 'Accuracy End';
            app.AccuracyEndEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyEndEditField.Value = 0.2;
            app.KelvinLabel_8 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_8.Text = '[Kelvin]';
            app.LakeshoreTCParametersPanel = uipanel(app.SettingsTab);
            app.LakeshoreTCParametersPanel.Title = 'Lakeshore TC Parameters';
            app.RampEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.RampEditFieldLabel.Text = 'Ramp';
            app.RampEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.RampEditField.Value = 10;
            app.KMinLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.KMinLabel.Text = '[K/Min]';
            app.RangeEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.RangeEditField.Value = 3;
            app.HMLOLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.HMLOLabel.Text = '[H/M/L/O]';
            app.PEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.PEditField.Value = 100;
            app.IEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.IEditField.Value = 50;
            app.DEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.DEditField.Value = 20;
            app.HeateraftermeasureSwitchLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.HeateraftermeasureSwitchLabel.Text = 'Heater after measure';
            app.HeateraftermeasureSwitch = uiswitch(app.LakeshoreTCParametersPanel, 'slider');
            app.KeysightSMParametersPanel = uipanel(app.SettingsTab);
            app.KeysightSMParametersPanel.Title = 'Keysight SM Parameters';
            app.NPLCEditField_2Label = uilabel(app.KeysightSMParametersPanel);
            app.NPLCEditField_2Label.Text = 'NPLC';
            app.NPLCEditField = uieditfield(app.KeysightSMParametersPanel, 'numeric');
            app.NPLCEditField.Value = 5;
            app.NPLCEditField_2Label_2 = uilabel(app.KeysightSMParametersPanel);
            app.NPLCEditField_2Label_2.Text = 'Measurecount';
            app.MeasurecountEditField = uieditfield(app.KeysightSMParametersPanel, 'numeric');
            app.MeasurecountEditField.Value = 1;
            app.VoltageaftermeasureSwitchLabel = uilabel(app.KeysightSMParametersPanel);
            app.VoltageaftermeasureSwitchLabel.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch = uiswitch(app.KeysightSMParametersPanel, 'slider');
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = ' ';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_2);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.SampleInfoEditField = uieditfield(app.Panel_2, 'text');
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.ConnectionStatusPanel = uipanel(app.SettingsTab);
            app.ConnectionStatusPanel.Title = 'Connection Status';
            app.LakeShoreTemperatureController336LampLabel = uilabel(app.ConnectionStatusPanel);
            app.LakeShoreTemperatureController336LampLabel.Text = 'Lake Shore Temperature Controller 336';
            app.LakeShoreTemperatureController336Lamp = uilamp(app.ConnectionStatusPanel);
            app.KeysightElectrometerB2987ALampLabel = uilabel(app.ConnectionStatusPanel);
            app.KeysightElectrometerB2987ALampLabel.Text = 'Keysight Electrometer B2987A';
            app.KeysightElectrometerB2987ALamp = uilamp(app.ConnectionStatusPanel);
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.StabilizationPhaseLabel = uilabel(app.Tables);
            app.StabilizationPhaseLabel.Text = 'Stabilization Phase';
            app.MeasurementPhaseLabel = uilabel(app.Tables);
            app.MeasurementPhaseLabel.Text = 'Measurement Phase';
            app.UITable1 = uitable(app.Tables);
            app.UITable1.ColumnName = {'Temperature, [K]'; 'Current, [A]'; 'Temp Rate, [K/min]'};
            app.UITable1.RowName = {};
            app.UITable2 = uitable(app.Tables);
            app.UITable2.ColumnName = {'Temperature, [K]'; 'Current, [A]'; 'Temp Rate, [K/min]'};
            app.UITable2.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.UITable3 = uitable(app.Tables);
            app.UITable3.ColumnName = {'Frequency'; 'NPLC'; 'Measurement Window'};
            app.UITable3.RowName = {};
            app.UITable2_2 = uitable(app.Tables);
            app.UITable2_2.ColumnName = {'Temperature, [K]'; 'Current, [A]'; 'Time, [S]'};
            app.UITable2_2.RowName = {};
            app.PulsePhaseLabel = uilabel(app.Tables);
            app.PulsePhaseLabel.Text = 'Pulse Phase';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes1 = uiaxes(app.GraphsTab);
            title(app.UIAxes1, 'Linear TSC Spectra During Cooling')
            xlabel(app.UIAxes1, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes1, 'Initial Current Linear ; [I]=[Amps]')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GraphsTab);
            title(app.UIAxes2, 'Logarithmic TSC Spectra During Cooling')
            xlabel(app.UIAxes2, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes2, 'Initial Current Logarithmic ; [I]=[Amps]''')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraphsTab);
            title(app.UIAxes3, 'Linear TSC Spectra During Warming')
            xlabel(app.UIAxes3, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes3, 'Current Linear ; [I]=[Amps]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GraphsTab);
            title(app.UIAxes4, 'Logarithmic TSC Spectra During Warming')
            xlabel(app.UIAxes4, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes4, 'Current Logarithmic ; [I]=[Amps]')
            zlabel(app.UIAxes4, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.InstantMeasurementsPanel = uipanel(app.GraphsTab);
            app.InstantMeasurementsPanel.Title = 'Instant Measurements:';
            app.TemperatureRateTextAreaLabel = uilabel(app.InstantMeasurementsPanel);
            app.TemperatureRateTextAreaLabel.Text = 'Temperature Rate:';
            app.TemperatureRateTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.KMinLabel_2 = uilabel(app.InstantMeasurementsPanel);
            app.KMinLabel_2.Text = '[K/Min]';
            app.TemperatureLabel = uilabel(app.InstantMeasurementsPanel);
            app.TemperatureLabel.Text = 'Temperature:';
            app.TemperatureTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.KelvinLabel = uilabel(app.InstantMeasurementsPanel);
            app.KelvinLabel.Text = '[Kelvin]';
            app.HeaterActivatedLabel = uilabel(app.InstantMeasurementsPanel);
            app.HeaterActivatedLabel.Text = 'Heater Activated:';
            app.HeaterActivatedTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.Label = uilabel(app.InstantMeasurementsPanel);
            app.Label.Text = '[%]';
            app.VoltageLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel.Text = 'Current:';
            app.CurrentTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.AmpsLabel = uilabel(app.InstantMeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.VoltageLabel_2 = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage:';
            app.VoltageTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.VoltsLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.StartExperimentButton = uibutton(app.TSCMethodKeysightTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.TSCMethodKeysightTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.TSCMethodKeysightTab);
            app.StopExperimentButton = uibutton(app.TSCMethodKeysightTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.SaveDataButton = uibutton(app.TSCMethodKeysightTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RRAMappKeysight6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end