classdef RRAMapp4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CurrentTemperatureCharacteristicsTab  matlab.ui.container.Tab
        StopExperimentButton            matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        StartExperimentButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        SampleInfoEditField             matlab.ui.control.EditField
        KeithleySMParametersPanel       matlab.ui.container.Panel
        MeasurecountEditField           matlab.ui.control.NumericEditField
        NPLCEditField_2Label_2          matlab.ui.control.Label
        NPLCEditField                   matlab.ui.control.NumericEditField
        NPLCEditField_2Label            matlab.ui.control.Label
        LakeshoreTCParametersPanel      matlab.ui.container.Panel
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        IEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        HMLOLabel                       matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        KMinLabel                       matlab.ui.control.Label
        RampEditField                   matlab.ui.control.NumericEditField
        RampEditFieldLabel              matlab.ui.control.Label
        SubsidiaryMeasurementParametersPanel  matlab.ui.container.Panel
        KelvinLabel_8                   matlab.ui.control.Label
        AccuracyEndEditField            matlab.ui.control.NumericEditField
        AccuracyEndLabel                matlab.ui.control.Label
        KelvinLabel_6                   matlab.ui.control.Label
        AccuracyStableEditField         matlab.ui.control.NumericEditField
        AccuracyStableLabel             matlab.ui.control.Label
        KelvinLabel_7                   matlab.ui.control.Label
        AccuracyStepEditField           matlab.ui.control.NumericEditField
        AccuracyStepLabel               matlab.ui.control.Label
        KelvinLabel_5                   matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        StepEditField_4Label            matlab.ui.control.Label
        MajorMeasurementParametersPanel  matlab.ui.container.Panel
        VoltsLabel_4                    matlab.ui.control.Label
        MeasurementVoltageEditField     matlab.ui.control.NumericEditField
        MeasurementVoltageEditFieldLabel  matlab.ui.control.Label
        SecondLabel                     matlab.ui.control.Label
        PulseTimeEditField              matlab.ui.control.NumericEditField
        PulseTimeLabel                  matlab.ui.control.Label
        VoltsLabel_2                    matlab.ui.control.Label
        PulseVoltageEditField           matlab.ui.control.NumericEditField
        PulseVoltageLabel               matlab.ui.control.Label
        VoltsLabel_3                    matlab.ui.control.Label
        PreliminaryVoltageEditField     matlab.ui.control.NumericEditField
        PreliminaryVoltageEditFieldLabel  matlab.ui.control.Label
        KelvinLabel_4                   matlab.ui.control.Label
        FinalTemperatureEditField       matlab.ui.control.NumericEditField
        FinalTemperatureEditFieldLabel  matlab.ui.control.Label
        KelvinLabel_3                   matlab.ui.control.Label
        InitialTemperatureEditField     matlab.ui.control.NumericEditField
        InitialTemperatureEditFieldLabel  matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        MeasurementPhasePanel           matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        StabilizationPhasePanel         matlab.ui.container.Panel
        UITable1                        matlab.ui.control.Table
        GraphsTab                       matlab.ui.container.Tab
        InstantMeasurementsPanel        matlab.ui.container.Panel
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel_2                  matlab.ui.control.Label
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        KelvinLabel                     matlab.ui.control.Label
        TemperatureTextArea             matlab.ui.control.TextArea
        TemperatureLabel                matlab.ui.control.Label
        KelvinLabel_2                   matlab.ui.control.Label
        TemperatureRateTextArea         matlab.ui.control.TextArea
        TemperatureRateTextAreaLabel    matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartExperimentButton
        function StartMeasurementsButtonPushed(app, event)
%%
%Experiment has begun:
format longE
%Clearing Data, Tables, Graphs:
app.IndicatorLamp.Color='y'; % Yellow means experiment has started
app.StateoftheExperimentTextArea.Value=('The Phase: Experiment has started, resetting and initializing parameters');
cla(app.UIAxes1)
cla(app.UIAxes2)
cla(app.UIAxes3)
cla(app.UIAxes4)
app.UITable1.Data=[];
app.UITable2.Data=[];

T0=app.InitialTemperatureEditField.Value;
Tf=app.FinalTemperatureEditField.Value;
tp=app.PulseTimeEditField.Value;
Vp=app.PulseVoltageEditField.Value;
V0=app.PreliminaryVoltageEditField.Value;
Vf=app.MeasurementVoltageEditField.Value;

NPLC=app.NPLCEditField.Value;
Measurecount=app.MeasurecountEditField.Value;

Range=app.RangeEditField.Value; % Heating
Ramp=app.RampEditField.Value;
P=app.PEditField.Value;
I=app.IEditField.Value;
D=app.DEditField.Value;

Step=app.StepEditField.Value; %Step poitns of temperature at which measurements are taken
AccuracyStable=app.AccuracyStableEditField.Value;
AccuracyStep=app.AccuracyStepEditField.Value;
AccuracyEnd=app.AccuracyEndEditField.Value;

SampleInfo=app.SampleInfoEditField.Value;
Comments=app.CommentsEditField.Value;
%app.StateofTheExperimentTextArea1.WordWrap="on";

% The parameters for configuration of Temperature Controller Lake Shore Model 336.
LSTC336=serialport("COM3",57600);
LSTC336.FlowControl="none";
LSTC336.Parity="odd";
LSTC336.StopBits=1;
LSTC336.DataBits=7;
KLYSM2450=visadev("USB0::0x05E6::0x2450::04429200::0::INSTR");
Startingtemperature=str2double(writeread(LSTC336,'KRDG?1'));
% Name of the Devices, check if conncetion is stablished.

disp('The model of the temperature controller device is:')
disp(writeread(LSTC336,'*IDN?'))
disp ('The model of the SourceMeter device is:')
disp(writeread(KLYSM2450,'*IDN?'))

%Reset devices:
%writeline(KLYSM2450,'reset()');
writeline(KLYSM2450,'smu.reset()');

%Voltage is the SOURCE function, Current is measurement: 
writeline(KLYSM2450,'smu.measure.func = smu.FUNC_DC_CURRENT')
writeline(KLYSM2450,'smu.source.func = smu.FUNC_DC_VOLTAGE')
%Settings for KEITHLEY that need to be set before start of measurements
%Measurement Settings for KEITHLEY:
writeline(KLYSM2450,'smu.measure.terminals = smu.TERMINALS_REAR')
writeline(KLYSM2450,'smu.measure.sense = smu.SENSE_2WIRE')
writeline(KLYSM2450,'smu.measure.autorange = smu.ON')
%writeline(KLYSM2450,'smu.measure.count = 1')
writeline(KLYSM2450, strcat('smu.measure.count = ',string(Measurecount)))
writeline(KLYSM2450, strcat('smu.measure.nplc=', string(NPLC))) 
writeline(KLYSM2450,'frequency=localnode.linefreq');
freq=str2double(writeread(KLYSM2450,'print(frequency)'));
disp('the value of frequency, nplc and measurement window respectively are:')
mw=NPLC/freq;
disp([freq NPLC mw])

%{
app.StateofTheExperimentTextArea1.Value={('The experiment has started and the value of frequency, nplc and measurement window respectively are:')};
%app.UITable1.ColumnRearrangeable="on";
app.UITable1.ColumnName={'frequency', 'nplc','Measurement Window'};
app.UITable1.Data=[freq, NPLC, mw];
pause(3)
%}

% Source Settings for KEITHLEY:
writeline(KLYSM2450,'smu.source.highc = smu.OFF')
writeline(KLYSM2450,'smu.source.autorange = smu.ON')
writeline(KLYSM2450,'smu.source.readback = smu.ON')
writeline(KLYSM2450,'smu.source.output = smu.ON')
%writeline(KLYSM2450,'smu.source.offmode = smu.OFFMODE_NORMAL')
writeline(KLYSM2450,'smu.source.ilimit.level = 0.1')


% Set Initial Parameters for the Temperature Controller: RANGE,PID
writeline(LSTC336, strcat('RANGE 1,', string(Range))) 
%writeline(LSTC336,'RANGE 1,0')
pause(0.1)
str_P=num2str(P);
str_I=num2str(I);
str_D=num2str(D);
PID=strcat(str_P,',',str_I,',',str_D);
writeline(LSTC336, strcat('PID 1,',PID))
pause(0.1)


%%

%Stabilization Phase:
app.IndicatorLamp.Color='b'; % Blue means stabilization part has started
app.StateoftheExperimentTextArea.Value=('The Phase: Stabilizing initial temperature');

%Set INITIAL Temperature
disp('                            ')
disp('The process of stabilizing initial temperature phase has begun:')
writeline(LSTC336,'RAMP 1,0,0')
pause(0.1)
writeline(LSTC336, strcat('SETP 1,', string(T0)))  %initial temp
writeline(KLYSM2450, strcat('smu.source.level = ',string(V0)))% Initial Voltage

% Check the initial condition for more than onepoint, compare all values
% to T0 and make sure it falls within the range, waits until temp is stable

lowerBound=T0-AccuracyStable;
upperBound=T0+AccuracyStable;
k=1;
l1=1;
l2=1;
k1=1;
l3=1;
l4=1;


%Initilaizing the initial vectors
initialtemp =zeros(k,1);
initialvolt=zeros(l1,1);
initialcurrent=zeros(l2,1);
initialtempall=zeros(k1,1);
initialvoltall=zeros(l3,1);
initialcurrentall=zeros(l4,1);

%process of stabilizing temperature:
%Setup buffer readings to take in values of current and voltage during the
writeline(KLYSM2450,'Voltage_Current_Initial_Buffer = buffer.make(10),buffer.STYLE_WRITABLE_FULL')
writeline(KLYSM2450,'Voltage_Current_Initial_Buffer.clear()')

while true
    
    writeline(KLYSM2450,'smu.measure.read(Voltage_Current_Initial_Buffer)')
    %writeline(KLYSM2450,'display.activebuffer = Voltage_Current_Initial_Buffer')
    
    newTemp=str2double(writeread(LSTC336,'KRDG?1'));
    initialvoltagetest=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Initial_Buffer.sourcevalues)'));
    initialcurrenttest=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Initial_Buffer.readings)'));
        
        
    if isempty(newTemp)
        break;
    end

    disp('Temperature has not yet stabilized, The temperature is: ')
    disp(newTemp);
    disp('Temperature has not yet stabilized, The voltage  is: ')
    disp(initialvoltagetest);
    disp('Temperature has not yet stabilized, The current  is: ')
    disp(initialcurrenttest);
   
    %{
    app.StateofTheExperimentTextArea1.Value={('Temperature has not yet stabilized, The values of Temperature, Current and Voltage respectively are:')};
    app.UITable1.ColumnName={'Temperature','Current','Voltage'};
    app.UITable1.Data=[newTemp, initialcurrenttest, initialvoltagetest];
  
    app.StateofTheExperimentTextArea1.Value={('Temperature has not yet stabilized, The values of Current and Voltage respectively are:')};
    app.UITable1.ColumnName={'Current','Voltage'};
    app.UITable1.Data=[initialcurrenttest, initialvoltagetest];
    %}
    app.TemperatureTextArea.Value=num2str(newTemp);
    app.VoltageTextArea.Value=num2str(initialvoltagetest);
    app.CurrentTextArea.Value=num2str(initialcurrenttest);
  
    
    %initialtemp=[initialtemp, newElement];
    initialtemp(k,1)=str2double(writeread(LSTC336,'KRDG?1'));
    initialtempall(k1,1)=initialtemp(k,1);
    initialvolt(l1,1)=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Initial_Buffer.sourcevalues)'));
    initialvoltall(l3,1)=initialvolt(l1,1);
    initialcurrent(l2,1)=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Initial_Buffer.readings)'));
    initialcurrentall(l4,1)=initialcurrent(l2,1);

    initialtableData=[initialtempall, initialcurrentall];
    app.UITable1.ColumnFormat={'numeric'};
    app.UITable1.Data=initialtableData;
    
    plot(app.UIAxes1,initialtemp,initialcurrent)
    xlabel(app.UIAxes1,'Initial Temperature ; [T]=[Kelvin]')
    ylabel(app.UIAxes1,'Initial Current Linear ; [I]=[Amps]')
    title(app.UIAxes1,'Current Vs Temperature, In Linear and During Stabilization')
   
    semilogy(app.UIAxes2,initialtemp,abs(initialcurrent))
    xlabel(app.UIAxes2,'Initial Temperature ; [T]=[Kelvin]')
    ylabel(app.UIAxes2,'Initial Current Logarithmic ; [I]=[Amps]')
    title(app.UIAxes2,'Current Vs Temperature, In Logarithmic and During Stabilization')

    k1=k1+1;
    l3=l3+1;
    l4=l4+1;

    if length(initialtemp)>=10
        last10Elements1=initialtemp(end-9:end);
        last10Elements2=initialvolt(end-9:end);
        last10Elements3=initialcurrent(end-9:end);

        rangeCheck=all(last10Elements1>=lowerBound & last10Elements1<=upperBound);
        if rangeCheck
            break;
        end
    end

    writeline(KLYSM2450,'Voltage_Current_Initial_Buffer.clear()')

    k=k+1;
    l1=l1+1;
    l2=l2+1;

    pause(1)
end

disp ('The last ten elements of the initial temperature within the range are:')
disp (last10Elements1)
disp ('The last ten elements of the initial voltage within the range are:')
disp (last10Elements2)
disp ('The last ten elements of the initial current within the range are:')
disp (last10Elements3)

%stabilizationdata='stabilizationdata.xlsx';
%writematrix(initialtempall,stabilizationdata,'Sheet',1,'Range','A1');
%writematrix(initialcurrentall,stabilizationdata,'Sheet',1,'Range','B1');

%%

%Pulse Phase:
app.IndicatorLamp.Color='c'; % Cyan means pulse phase has started
app.StateoftheExperimentTextArea.Value=('The Phase: Pulse  has started');

disp('                            ')
disp ('The Temperature has stabilized and the Pulse phase will now start:')

writeline(KLYSM2450,'Pulse_Buffer = buffer.make(10),buffer.STYLE_WRITABLE_FULL')
writeline(KLYSM2450, strcat('smu.source.level = ',string(Vp))) %Sets the Pulse voltage
writeline(KLYSM2450,'smu.measure.read(Pulse_Buffer)')

outputtemppulse=str2double(writeread(LSTC336,'SETP?1'));
realtemppulse=str2double(writeread(LSTC336,'KRDG?1'));
voltagepulse=str2double(writeread(KLYSM2450,'printbuffer(1,1,Pulse_Buffer.sourcevalues)'));
currentpulse=str2double(writeread(KLYSM2450,'printbuffer(1,1,Pulse_Buffer.readings)'));
disp('      device temperature at the moment of pulse:   real temperature at the moment of pulse:');
Y0=['              ',num2str(outputtemppulse),'                                ',num2str(realtemppulse)];
disp(Y0)

disp('             VOLTAGE at the moment of pulse:      CURRENT at the moment of pulse:');
Y1=['              ',num2str(voltagepulse),'                            ',num2str(currentpulse)];
writeline(KLYSM2450,'Pulse_Buffer.clear()')
disp(Y1);

app.TemperatureTextArea.Value=num2str(realtemppulse);
app.VoltageTextArea.Value=num2str(voltagepulse);
app.CurrentTextArea.Value=num2str(currentpulse);

pause(tp)


%%


% Measurment Phase:
app.IndicatorLamp.Color='r'; % red means measurement part has started
app.StateoftheExperimentTextArea.Value=('The Phase: Measurements have started');

disp('                            ')
disp ('The Temperature has stabilized and the measurements phase will now start:')


%PROCESS for T0 to Tf, Setting the final Voltage, Buffers and readin values
%writeline(LSTC336,'RAMP 1,1,10')
writeline(LSTC336, strcat('RAMP 1,1,',string(Ramp)))
pause(0.1)
writeline(LSTC336, strcat('SETP 1,', string(Tf)))  %writeline(LSTC336,'SETP 1,260)')
pause(0.1)
writeline(KLYSM2450, strcat('smu.source.level = ',string(Vf))) %Sets the final voltage  


% Set Initial Variables for the while loops to receive and save data for LSTC336
n=1;
i=1;
outputtemp=zeros(n,1);
realtemp=zeros(i,1);
% Set Initial Variables for the while loops and initial plotting
m=1;
j=1;
%i1=1;
%currentvector=zeros(i1,1);
voltage=zeros(m,1);
current=zeros(j,1);

outputtemptest=str2double(writeread(LSTC336,'SETP?1'));
realtemptest=str2double(writeread(LSTC336,'KRDG?1'));

lasttempforheating=realtemptest+AccuracyStep;
lasttempforcooling=realtemptest-AccuracyStep;

% The while loops: 1. The Heating, 2. The Cooling
if outputtemptest<Tf % Means it's heating
    disp ('The regime is heating')
    disp('                            ')

%make buffer and print readings to receive and save data for KETHLEY2450:
writeline(KLYSM2450,'Voltage_Current_Buffer = buffer.make(10),buffer.STYLE_WRITABLE_FULL')
writeline(KLYSM2450,'Voltage_Current_Buffer.clear()')

 while (realtemptest<(Tf+Step)) % Heating
       
        %writeline(KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
        %writeline(KLYSM2450,'display.activebuffer = Voltage_Current_Buffer')

        outputtemptest=str2double(writeread(LSTC336,'SETP?1'));
        realtemptest=str2double(writeread(LSTC336,'KRDG?1'));
        %voltagetest=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.sourcevalues)'));
        %currenttest=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.readings)'));
  
        if abs((realtemptest-lasttempforheating))<=(AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(AccuracyStep) 

        writeline(KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')

        outputtemp(n,1)=str2double(writeread(LSTC336,'SETP?1'));
        realtemp(i,1)=str2double(writeread(LSTC336,'KRDG?1'));
        voltage(m,1)=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.sourcevalues)'));
        current(j,1)=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.readings)'));

        MeasurementtableData=[realtemp, current];
        app.UITable2.ColumnFormat={'numeric'};
        app.UITable2.Data=MeasurementtableData;
        
        plot(app.UIAxes3,realtemp,current)
        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
        title(app.UIAxes3,'Current Vs Temperature, In Linear, During Measurements')

        semilogy(app.UIAxes4,realtemp,abs(current))
        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
        title(app.UIAxes4,'Current Vs Temperature, In Logarithmic, During Measurements')

        app.VoltageTextArea.Value=num2str(voltage(m,1));
        app.CurrentTextArea.Value=num2str(current(j,1));

        writeline(KLYSM2450,'Voltage_Current_Buffer.clear()')
        lasttempforheating=lasttempforheating+Step;
        lasttempforcooling=lasttempforcooling+Step;

        n=n+1;
        i=i+1;
        m=m+1;
        j=j+1;
        
        end

        writeline(KLYSM2450,'Voltage_Current_Buffer.clear()')

        disp('      Device Temperature at the moment:          Real Temperature at the moment:');
        Y4=['              ',num2str(outputtemptest),'                           ',num2str(realtemptest)];
        disp(Y4)
        %disp('             Voltage at the moment:              Current at the moment:');
        %Y5=['              ',num2str(voltagetest),'                         ',num2str(currenttest)];
        %disp(Y5);

        app.TemperatureTextArea.Value=num2str(realtemptest);


        pause(0.1);
       
   if (realtemptest>(Tf+AccuracyEnd))
     app.IndicatorLamp.Color='g'; % green means Measurements have stopped
     app.StateoftheExperimentTextArea.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');

     [filename, pathname] = uiputfile('*.xlsx', 'Save as');
     if isequal(filename,0) || isequal(pathname,0)
         %disp('User pressed cancel')
         app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
     else
         %disp(['User selected ', fullfile(pathname, filename)])
         app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
         %define your sheets names
         sheet1 = 'Settings';
         sheet2 = 'Stabilization';
         sheet3 = 'Measurements';

         % define measurement parameters and data
         settings = {'Sample Info' , SampleInfo ; 'Sample Description' , Comments ;'Major Measurement Parameters' , ' ';'Starting Temperature' , Startingtemperature ; 'Initial Temperature', T0 ; 'Final Temperature' , Tf ; 'Prelimenary Voltage' , V0 ; 'Pulse Voltage' , Vp ; 'Pulse Time' , tp ; 'Measurement Voltage' , Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , Step ; 'Accuracy Step' , AccuracyStep ; 'Accuracy Stable' , AccuracyStable ; 'Accuracy End' , AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , Ramp ; 'Range' , Range ; 'P' , P ; 'I' , I ; 'D' , D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , NPLC ; 'Measurecount' , Measurecount}; % replace with your settings

         StabilizationTemperature = initialtempall;
         StabilizationCurrent = initialcurrentall; % replace this with your Stabilization data
         MeasurementsTemperature = realtemp;
         MeasurementsCurrent = current ; % replace this with your Measurements data

         % Define variable names for measurements tables
         stablization_var_names = {'Temperature', 'Current'};
         measurement_var_names = {'Temperature', 'Current'};

         % Create tables for measurements
         stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', stablization_var_names);
         measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', measurement_var_names);

         %write settings, temperature data and current data to the Excel file
         writecell(settings, fullfile(pathname, filename), 'Sheet', sheet1);
         writetable(stablization_table, fullfile(pathname, filename), 'Sheet', sheet2);
         writetable(measurement_table, fullfile(pathname, filename), 'Sheet', sheet3);
         % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

     end

     break
     
   end 
    

 end
 
elseif outputtemptest>Tf %means it's cooling
    disp ('The regime is cooling')
    disp('                            ')

    while (realtemptest>(Tf-Step)) %cooling

        %writeline(KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
        %writeline(KLYSM2450,'display.activebuffer = Voltage_Current_Buffer')

        outputtemptest=str2double(writeread(LSTC336,'SETP?1'));
        realtemptest=str2double(writeread(LSTC336,'KRDG?1'));
        %voltagetest=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.sourcevalues)'));
        %currenttest=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.readings)'));
  
        if abs((realtemptest-lasttempforheating))<=(AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(AccuracyStep) 
            
        writeline(KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
        
        outputtemp(n,1)=str2double(writeread(LSTC336,'SETP?1'));
        realtemp(i,1)=str2double(writeread(LSTC336,'KRDG?1'));
        voltage(m,1)=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.sourcevalues)'));
        current(j,1)=str2double(writeread(KLYSM2450,'printbuffer(1,1,Voltage_Current_Buffer.readings)'));

        MeasurementtableData=[realtemp, current];
        app.UITable2.ColumnFormat={'numeric'};
        app.UITable2.Data=MeasurementtableData;
  
        plot(app.UIAxes3,realtemp,current)
        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
        title(app.UIAxes3,'Current Vs Temperature, In Linear, During Measurements')

        semilogy(app.UIAxes4,realtemp,abs(current))
        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
        title(app.UIAxes4,'Current Vs Temperature, In Logarithmic, During Measurements')

        app.VoltageTextArea.Value=num2str(voltage(m,1));
        app.CurrentTextArea.Value=num2str(current(j,1));

        writeline(KLYSM2450,'Voltage_Current_Buffer.clear()')
        lasttempforheating=lasttempforheating-Step;
        lasttempforcooling=lasttempforcooling-Step;

        n=n+1;
        i=i+1;
        m=m+1;
        j=j+1;
        
        end

        writeline(KLYSM2450,'Voltage_Current_Buffer.clear()')

        disp('      Device Temperature at the moment:          Real Temperature at the moment:');
        Y4=['              ',num2str(outputtemptest),'                           ',num2str(realtemptest)];
        disp(Y4)
        %disp('             Voltage at the moment:              Current at the moment:');
        %Y5=['              ',num2str(voltagetest),'                         ',num2str(currenttest)];
        %disp(Y5);

        app.TemperatureTextArea.Value=num2str(realtemptest);
        %app.CurrentTextArea.Value=num2str(voltagetest);
        %app.CurrentTextArea.Value=num2str(currenttest);

        pause(0.1);
       
   if (realtemptest<(Tf-AccuracyEnd))
     app.IndicatorLamp.Color='g'; % green means Measurements have stopped
     app.StateoftheExperimentTextArea.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');

     [filename, pathname] = uiputfile('*.xlsx', 'Save as');
     if isequal(filename,0) || isequal(pathname,0)
         %disp('User pressed cancel')
         app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
     else
         %disp(['User selected ', fullfile(pathname, filename)])
         app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');
         %define your sheets names
         sheet1 = 'Settings';
         sheet2 = 'Stabilization';
         sheet3 = 'Measurements';

         % define measurement parameters and data
         settings = {'Sample Info' , SampleInfo ; 'Sample Description' , Comments ;'Major Measurement Parameters' , ' ';'Starting Temperature' , Startingtemperature ; 'Initial Temperature', T0 ; 'Final Temperature' , Tf ; 'Preliminary Voltage' , V0 ; 'Pulse Voltage' , Vp ; 'Pulse Time' , tp ; 'Measurement Voltage' , Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , Step ; 'Accuracy Step' , AccuracyStep ; 'Accuracy Stable' , AccuracyStable ; 'Accuracy End' , AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , Ramp ; 'Range' , Range ; 'P' , P ; 'I' , I ; 'D' , D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , NPLC ; 'Measurecount' , Measurecount}; % replace with your settings

         StabilizationTemperature = initialtempall;
         StabilizationCurrent = initialcurrentall; % replace this with your Stabilization data
         MeasurementsTemperature = realtemp;
         MeasurementsCurrent = current ; % replace this with your Measurements data

         % Define variable names for measurements tables
         stablization_var_names = {'Temperature', 'Current'};
         measurement_var_names = {'Temperature', 'Current'};

         % Create tables for measurements
         stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', stablization_var_names);
         measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', measurement_var_names);

         %write settings, temperature data and current data to the Excel file
         writecell(settings, fullfile(pathname, filename), 'Sheet', sheet1);
         writetable(stablization_table, fullfile(pathname, filename), 'Sheet', sheet2);
         writetable(measurement_table, fullfile(pathname, filename), 'Sheet', sheet3);
         % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

     end
     break
   end
   
   end 

     
else 
    app.StateoftheExperimentTextArea.Value=('The Phase: The Final temperature and Set point temperature are equal!');
    app.IndicatorLamp.Color='k';
    disp ('The final temperature and Set point temperature are equal!')
end

%Measurementdata='Measurementdata.xlsx';
%writematrix(realtemp,Measurementdata,'Sheet',1,'Range','A1');
%writematrix(current,Measurementdata,'Sheet',1,'Range','B1');


 %%

% Display the obtained values for both while loops
disp('                            ')
disp('            Device Temp:              Real Temp:');
disp([outputtemp realtemp]);
disp('                            ')
disp('              Voltage:            Current:');
disp([voltage current]);


writeline(KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
%writeline(KLYSM2450,'Voltage_Current_Buffer.clear()')

pause(5);

app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done!');
app.IndicatorLamp.Color='w';


clear
clear ('Variables')
clearvars
clear ('app')


        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='m';
            stop;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CurrentTemperatureCharacteristicsTab = uitab(app.TabGroup2);
            app.CurrentTemperatureCharacteristicsTab.Title = 'Current Temperature Characteristics';
            app.TabGroup = uitabgroup(app.CurrentTemperatureCharacteristicsTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MajorMeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MajorMeasurementParametersPanel.Title = 'Major Measurement Parameters';
            app.InitialTemperatureEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.InitialTemperatureEditFieldLabel.Text = 'Initial Temperature';
            app.InitialTemperatureEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.InitialTemperatureEditField.Value = 81;
            app.KelvinLabel_3 = uilabel(app.MajorMeasurementParametersPanel);
            app.KelvinLabel_3.Text = '[Kelvin]';
            app.FinalTemperatureEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.FinalTemperatureEditFieldLabel.Text = 'Final Temperature';
            app.FinalTemperatureEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.FinalTemperatureEditField.Value = 450;
            app.KelvinLabel_4 = uilabel(app.MajorMeasurementParametersPanel);
            app.KelvinLabel_4.Text = '[Kelvin]';
            app.PreliminaryVoltageEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PreliminaryVoltageEditFieldLabel.Text = 'Preliminary Voltage';
            app.PreliminaryVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.PreliminaryVoltageEditField.Value = 1;
            app.VoltsLabel_3 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_3.Text = '[Volts]';
            app.PulseVoltageLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PulseVoltageLabel.Text = 'Pulse Voltage';
            app.PulseVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.VoltsLabel_2 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_2.Text = '[Volts]';
            app.PulseTimeLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PulseTimeLabel.Text = 'Pulse Time';
            app.PulseTimeEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.PulseTimeEditField.Value = 5;
            app.SecondLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.SecondLabel.Text = '[Second]';
            app.MeasurementVoltageEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.MeasurementVoltageEditFieldLabel.Text = 'Measurement Voltage';
            app.MeasurementVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.MeasurementVoltageEditField.Value = -1;
            app.VoltsLabel_4 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_4.Text = '[Volts]';
            app.SubsidiaryMeasurementParametersPanel = uipanel(app.SettingsTab);
            app.SubsidiaryMeasurementParametersPanel.Title = 'Subsidiary Measurement Parameters';
            app.StepEditField_4Label = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.StepEditField_4Label.Text = 'Step';
            app.StepEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.StepEditField.Value = 0.5;
            app.KelvinLabel_5 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_5.Text = '[Kelvin]';
            app.AccuracyStepLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyStepLabel.Text = 'Accuracy Step';
            app.AccuracyStepEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyStepEditField.Value = 0.1;
            app.KelvinLabel_7 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_7.Text = '[Kelvin]';
            app.AccuracyStableLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyStableLabel.Text = 'Accuracy Stable';
            app.AccuracyStableEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyStableEditField.Value = 1;
            app.KelvinLabel_6 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_6.Text = '[Kelvin]';
            app.AccuracyEndLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyEndLabel.Text = 'Accuracy End';
            app.AccuracyEndEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyEndEditField.Value = 0.2;
            app.KelvinLabel_8 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_8.Text = '[Kelvin]';
            app.LakeshoreTCParametersPanel = uipanel(app.SettingsTab);
            app.LakeshoreTCParametersPanel.Title = 'Lakeshore TC Parameters';
            app.RampEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.RampEditFieldLabel.Text = 'Ramp';
            app.RampEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.RampEditField.Value = 10;
            app.KMinLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.KMinLabel.Text = '[K/Min]';
            app.RangeEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.RangeEditField.Value = 3;
            app.HMLOLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.HMLOLabel.Text = '[H/M/L/O]';
            app.PEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.PEditField.Value = 100;
            app.IEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.IEditField.Value = 50;
            app.Label = uilabel(app.LakeshoreTCParametersPanel);
            app.Label.Text = '';
            app.DEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.DEditField.Value = 20;
            app.KeithleySMParametersPanel = uipanel(app.SettingsTab);
            app.KeithleySMParametersPanel.Title = 'Keithley SM Parameters';
            app.NPLCEditField_2Label = uilabel(app.KeithleySMParametersPanel);
            app.NPLCEditField_2Label.Text = 'NPLC';
            app.NPLCEditField = uieditfield(app.KeithleySMParametersPanel, 'numeric');
            app.NPLCEditField.Value = 10;
            app.NPLCEditField_2Label_2 = uilabel(app.KeithleySMParametersPanel);
            app.NPLCEditField_2Label_2.Text = 'Measurecount';
            app.MeasurecountEditField = uieditfield(app.KeithleySMParametersPanel, 'numeric');
            app.MeasurecountEditField.Value = 1;
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = ' ';
            app.SampleInfoEditField = uieditfield(app.Panel_2, 'text');
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_2);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.StabilizationPhasePanel = uipanel(app.Tables);
            app.StabilizationPhasePanel.Title = 'Stabilization Phase';
            app.UITable1 = uitable(app.StabilizationPhasePanel);
            app.UITable1.ColumnName = {'Temperature'; 'Current'};
            app.UITable1.RowName = {};
            app.MeasurementPhasePanel = uipanel(app.Tables);
            app.MeasurementPhasePanel.Title = 'Measurement Phase';
            app.UITable2 = uitable(app.MeasurementPhasePanel);
            app.UITable2.ColumnName = {'Temperature'; 'Current'};
            app.UITable2.RowName = {};
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes1 = uiaxes(app.GraphsTab);
            title(app.UIAxes1, 'Current Vs Temperature, In Linear, During Stabilization')
            xlabel(app.UIAxes1, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes1, 'Initial Current Linear ; [I]=[Amps]')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GraphsTab);
            title(app.UIAxes2, 'Current Vs Temperature, In Logarithmic and During Stabilization')
            xlabel(app.UIAxes2, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes2, 'Initial Current Logarithmic ; [I]=[Amps]''')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraphsTab);
            title(app.UIAxes3, 'Current Vs Temperature, In Linear, During Measurements')
            xlabel(app.UIAxes3, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes3, 'Current linear ; [I]=[Amps]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GraphsTab);
            title(app.UIAxes4, 'Current Vs Temperature, In Logarithmic, During Measurements')
            xlabel(app.UIAxes4, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes4, 'Current Logarithmic ; [I]=[Amps]')
            zlabel(app.UIAxes4, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.InstantMeasurementsPanel = uipanel(app.GraphsTab);
            app.InstantMeasurementsPanel.Title = 'Instant Measurements:';
            app.TemperatureRateTextAreaLabel = uilabel(app.InstantMeasurementsPanel);
            app.TemperatureRateTextAreaLabel.Text = 'Temperature Rate:';
            app.TemperatureRateTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.KelvinLabel_2 = uilabel(app.InstantMeasurementsPanel);
            app.KelvinLabel_2.Text = '[Kelvin]';
            app.TemperatureLabel = uilabel(app.InstantMeasurementsPanel);
            app.TemperatureLabel.Text = 'Temperature:';
            app.TemperatureTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.KelvinLabel = uilabel(app.InstantMeasurementsPanel);
            app.KelvinLabel.Text = '[Kelvin]';
            app.VoltageLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel.Text = 'Current:';
            app.CurrentTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.AmpsLabel = uilabel(app.InstantMeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.VoltageLabel_2 = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage:';
            app.VoltageTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.VoltsLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.StartExperimentButton = uibutton(app.CurrentTemperatureCharacteristicsTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementsButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.IndicatorLampLabel = uilabel(app.CurrentTemperatureCharacteristicsTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.CurrentTemperatureCharacteristicsTab);
            app.StopExperimentButton = uibutton(app.CurrentTemperatureCharacteristicsTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RRAMapp4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end