classdef apptest1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        TEditField          matlab.ui.control.NumericEditField
        TEditFieldLabel     matlab.ui.control.Label
        RampEditField       matlab.ui.control.NumericEditField
        RampEditFieldLabel  matlab.ui.control.Label
        DEditField          matlab.ui.control.NumericEditField
        DEditFieldLabel     matlab.ui.control.Label
        IEditField          matlab.ui.control.NumericEditField
        IEditFieldLabel     matlab.ui.control.Label
        PEditField          matlab.ui.control.NumericEditField
        PEditFieldLabel     matlab.ui.control.Label
        RaEditFieldLabel    matlab.ui.control.Label
        RaEditField         matlab.ui.control.NumericEditField
        Tab2                matlab.ui.container.Tab
        UIAxes              matlab.ui.control.UIAxes
        Tab3                matlab.ui.container.Tab
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
      LSTC336=serialport("COM3",57600);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, TextArea
        function ButtonPushed(app, event)
Ra=app.RaEditField.Value;
P=app.PEditField.Value;
I=app.IEditField.Value;
D=app.DEditField.Value;
Ramp=app.RampEditField.Value;
T=app.TEditField.Value;

app.Button.Enable="off";

app.LSTC336.FlowControl="none";
app.LSTC336.Parity="odd";
app.LSTC336.StopBits=1;
app.LSTC336.DataBits=7;

% writeline(LSTC336,'*IDN?');
%disp(readline(LSTC336))
%disp ('The model of the temperature controller device is:');
%disp(writeread(app.LSTC336,'*IDN?'))
writeline(app.LSTC336, strcat('RANGE 1,', string(Ra))) 

str_P=num2str(P);
str_I=num2str(I);
str_D=num2str(D);
PID=strcat(str_P,',',str_I,',',str_D);
writeline(app.LSTC336, strcat('PID 1,',PID))
pause(0.1)

writeline(app.LSTC336, strcat('RAMP 1,1,',string(Ramp)))
pause(0.1)
writeline(app.LSTC336, strcat('SETP 1,', string(T)))
%app.TextArea.WordWrap="on";

%commands={('The model of the temperature controller device is:'),(writeread(LSTC336,'*IDN?'))};
%app.TextArea.Value=commands;

%commandhistory={('The model of the temperature controller device is:'),(writeread(LSTC336,'*IDN?'))};
%app.TextArea.Value={('The model of the temperature controller device is:'),(writeread(app.LSTC336,'*IDN?'))};
%pause(0.1)

% KEITHLEY configuration Parameters, check if connection is stablished
%{
KLYSM2450=visadev("USB0::0x05E6::0x2450::04429200::0::INSTR");
disp ('The model of the SourceMeter device is:')
disp(writeread(KLYSM2450,'*IDN?'))
writeline(KLYSM2450,'frequency=localnode.linefreq');
freq='ads';
app.TextArea.Value={('The model of the SourceMeter device is:'),freq};


%}
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.RaEditField = uieditfield(app.Tab, 'numeric');
            app.RaEditField.Value = 3;
            app.RaEditFieldLabel = uilabel(app.Tab);
            app.RaEditFieldLabel.Text = 'Ra';
            app.PEditFieldLabel = uilabel(app.Tab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.Tab, 'numeric');
            app.PEditField.Value = 100;
            app.IEditFieldLabel = uilabel(app.Tab);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.Tab, 'numeric');
            app.IEditField.Value = 50;
            app.DEditFieldLabel = uilabel(app.Tab);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.Tab, 'numeric');
            app.DEditField.Value = 20;
            app.RampEditFieldLabel = uilabel(app.Tab);
            app.RampEditFieldLabel.Text = 'Ramp';
            app.RampEditField = uieditfield(app.Tab, 'numeric');
            app.TEditFieldLabel = uilabel(app.Tab);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.Tab, 'numeric');
            app.TEditField.Value = 300;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.TextAreaLabel = uilabel(app.Tab3);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Tab3);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptest1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end