classdef RRAMapp14 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        TSCMethodKeithleyTab            matlab.ui.container.Tab
        StopExperimentButton            matlab.ui.control.Button
        StartExperimentButton           matlab.ui.control.Button
        IndicatorLamp                   matlab.ui.control.Lamp
        IndicatorLampLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        TSCKeithleyLabel                matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        CommentsEditField               matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SampleInfoEditFieldLabel        matlab.ui.control.Label
        SampleInfoEditField             matlab.ui.control.EditField
        KeithleySMParametersPanel       matlab.ui.container.Panel
        VoltageaftermeasureSwitch       matlab.ui.control.Switch
        VoltageaftermeasureSwitchLabel  matlab.ui.control.Label
        MeasurecountEditField           matlab.ui.control.NumericEditField
        NPLCEditField_2Label_2          matlab.ui.control.Label
        NPLCEditField                   matlab.ui.control.NumericEditField
        NPLCEditField_2Label            matlab.ui.control.Label
        LakeshoreTCParametersPanel      matlab.ui.container.Panel
        HeateraftermeasureSwitch        matlab.ui.control.Switch
        HeateraftermeasureSwitchLabel   matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        IEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        HMLOLabel                       matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        KMinLabel                       matlab.ui.control.Label
        RampEditField                   matlab.ui.control.NumericEditField
        RampEditFieldLabel              matlab.ui.control.Label
        SubsidiaryMeasurementParametersPanel  matlab.ui.container.Panel
        KelvinLabel_8                   matlab.ui.control.Label
        AccuracyEndEditField            matlab.ui.control.NumericEditField
        AccuracyEndLabel                matlab.ui.control.Label
        KelvinLabel_6                   matlab.ui.control.Label
        AccuracyStableEditField         matlab.ui.control.NumericEditField
        AccuracyStableLabel             matlab.ui.control.Label
        KelvinLabel_7                   matlab.ui.control.Label
        AccuracyStepEditField           matlab.ui.control.NumericEditField
        AccuracyStepLabel               matlab.ui.control.Label
        KelvinLabel_5                   matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        StepEditField_4Label            matlab.ui.control.Label
        MajorMeasurementParametersPanel  matlab.ui.container.Panel
        VoltsLabel_4                    matlab.ui.control.Label
        MeasurementVoltageEditField     matlab.ui.control.NumericEditField
        MeasurementVoltageEditFieldLabel  matlab.ui.control.Label
        SecondLabel                     matlab.ui.control.Label
        PulseTimeEditField              matlab.ui.control.NumericEditField
        PulseTimeLabel                  matlab.ui.control.Label
        VoltsLabel_2                    matlab.ui.control.Label
        PulseVoltageEditField           matlab.ui.control.NumericEditField
        PulseVoltageLabel               matlab.ui.control.Label
        VoltsLabel_3                    matlab.ui.control.Label
        PreliminaryVoltageEditField     matlab.ui.control.NumericEditField
        PreliminaryVoltageEditFieldLabel  matlab.ui.control.Label
        KelvinLabel_4                   matlab.ui.control.Label
        FinalTemperatureEditField       matlab.ui.control.NumericEditField
        FinalTemperatureEditFieldLabel  matlab.ui.control.Label
        KelvinLabel_3                   matlab.ui.control.Label
        InitialTemperatureEditField     matlab.ui.control.NumericEditField
        InitialTemperatureEditFieldLabel  matlab.ui.control.Label
        Tables                          matlab.ui.container.Tab
        ConstantValuesLabel             matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        MeasurementPhaseLabel           matlab.ui.control.Label
        UITable1                        matlab.ui.control.Table
        StabilizationPhaseLabel         matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        InstantMeasurementsPanel        matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        HeaterActivatedTextArea         matlab.ui.control.TextArea
        HeaterActivatedLabel            matlab.ui.control.Label
        VoltsLabel                      matlab.ui.control.Label
        VoltageTextArea                 matlab.ui.control.TextArea
        VoltageLabel_2                  matlab.ui.control.Label
        AmpsLabel                       matlab.ui.control.Label
        CurrentTextArea                 matlab.ui.control.TextArea
        VoltageLabel                    matlab.ui.control.Label
        KelvinLabel                     matlab.ui.control.Label
        TemperatureTextArea             matlab.ui.control.TextArea
        TemperatureLabel                matlab.ui.control.Label
        KMinLabel_2                     matlab.ui.control.Label
        TemperatureRateTextArea         matlab.ui.control.TextArea
        TemperatureRateTextAreaLabel    matlab.ui.control.Label
        StateoftheExperimentPanel       matlab.ui.container.Panel
        StateoftheExperimentTextArea    matlab.ui.control.TextArea
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        TSCMethodKeysightTab            matlab.ui.container.Tab
        StartExperimentButton_2         matlab.ui.control.Button
        StopExperimentButton_2          matlab.ui.control.Button
        IndicatorLamp_2                 matlab.ui.control.Lamp
        IndicatorLamp_2Label            matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        SettingsTab_2                   matlab.ui.container.Tab
        TSCKeysightLabel                matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        CommentsEditField_2             matlab.ui.control.EditField
        CommentsEditField_2Label        matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        SampleInfoEditField_2Label      matlab.ui.control.Label
        SampleInfoEditField_2           matlab.ui.control.EditField
        KeysightEMParametersPanel       matlab.ui.container.Panel
        VoltageaftermeasureSwitch_2     matlab.ui.control.Switch
        VoltageaftermeasureSwitch_2Label  matlab.ui.control.Label
        MeasurecountEditField_2         matlab.ui.control.NumericEditField
        MeasurecountEditField_2Label    matlab.ui.control.Label
        NPLCEditField_3                 matlab.ui.control.NumericEditField
        NPLCEditField_3Label            matlab.ui.control.Label
        LakeshoreTCParametersPanel_2    matlab.ui.container.Panel
        HeateraftermeasureSwitch_2      matlab.ui.control.Switch
        HeateraftermeasureSwitch_2Label  matlab.ui.control.Label
        HMLOLabel_2                     matlab.ui.control.Label
        KMinLabel_3                     matlab.ui.control.Label
        DEditField_2                    matlab.ui.control.NumericEditField
        DEditField_2Label               matlab.ui.control.Label
        IEditField_2                    matlab.ui.control.NumericEditField
        IEditField_2Label               matlab.ui.control.Label
        PEditField_2                    matlab.ui.control.NumericEditField
        PEditField_2Label               matlab.ui.control.Label
        RangeEditField_2                matlab.ui.control.NumericEditField
        RangeEditField_2Label           matlab.ui.control.Label
        RampEditField_2                 matlab.ui.control.NumericEditField
        RampEditField_2Label            matlab.ui.control.Label
        SubsidiaryMeasurementParametersPanel_2  matlab.ui.container.Panel
        KelvinLabel_14                  matlab.ui.control.Label
        KelvinLabel_13                  matlab.ui.control.Label
        KelvinLabel_12                  matlab.ui.control.Label
        KelvinLabel_11                  matlab.ui.control.Label
        AccuracyEndEditField_2          matlab.ui.control.NumericEditField
        AccuracyEndEditField_2Label     matlab.ui.control.Label
        AccuracyStableEditField_2       matlab.ui.control.NumericEditField
        AccuracyStableEditField_2Label  matlab.ui.control.Label
        AccuracyStepEditField_2         matlab.ui.control.NumericEditField
        AccuracyStepEditField_2Label    matlab.ui.control.Label
        StepEditField_2                 matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        MajorMeasurementParametersPanel_2  matlab.ui.container.Panel
        VoltsLabel_7                    matlab.ui.control.Label
        SecondLabel_2                   matlab.ui.control.Label
        VoltsLabel_6                    matlab.ui.control.Label
        VoltsLabel_5                    matlab.ui.control.Label
        KelvinLabel_10                  matlab.ui.control.Label
        KelvinLabel_9                   matlab.ui.control.Label
        MeasurementVoltageEditField_2   matlab.ui.control.NumericEditField
        MeasurementVoltageEditField_2Label  matlab.ui.control.Label
        PulseTimeEditField_2            matlab.ui.control.NumericEditField
        PulseTimeEditField_2Label       matlab.ui.control.Label
        PulseVoltageEditField_2         matlab.ui.control.NumericEditField
        PulseVoltageEditField_2Label    matlab.ui.control.Label
        PreliminaryVoltageEditField_2   matlab.ui.control.NumericEditField
        PreliminaryVoltageEditField_2Label  matlab.ui.control.Label
        FinalTemperatureEditField_2     matlab.ui.control.NumericEditField
        FinalTemperatureEditField_2Label  matlab.ui.control.Label
        InitialTemperatureEditField_2   matlab.ui.control.NumericEditField
        InitialTemperatureEditField_2Label  matlab.ui.control.Label
        Tables_2                        matlab.ui.container.Tab
        ConstantValuesLabel_2           matlab.ui.control.Label
        UITable3_2                      matlab.ui.control.Table
        UITable2_2                      matlab.ui.control.Table
        MeasurementPhaseLabel_2         matlab.ui.control.Label
        UITable1_2                      matlab.ui.control.Table
        StabilizationPhaseLabel_2       matlab.ui.control.Label
        GraphsTab_2                     matlab.ui.container.Tab
        InstantMeasurementsPanel_2      matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        HeaterActivatedTextArea_2       matlab.ui.control.TextArea
        HeaterActivatedTextArea_2Label  matlab.ui.control.Label
        VoltsLabel_8                    matlab.ui.control.Label
        VoltageTextArea_2               matlab.ui.control.TextArea
        VoltageTextArea_2Label          matlab.ui.control.Label
        AmpsLabel_2                     matlab.ui.control.Label
        CurrentTextArea_2               matlab.ui.control.TextArea
        CurrentTextArea_2Label          matlab.ui.control.Label
        KelvinLabel_15                  matlab.ui.control.Label
        TemperatureTextArea_2           matlab.ui.control.TextArea
        TemperatureTextArea_2Label      matlab.ui.control.Label
        KMinLabel_4                     matlab.ui.control.Label
        TemperatureRateTextArea_2       matlab.ui.control.TextArea
        TemperatureRateTextArea_2Label  matlab.ui.control.Label
        StateoftheExperimentPanel_2     matlab.ui.container.Panel
        StateoftheExperimentTextArea_2  matlab.ui.control.TextArea
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes1_2                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %Establishing Connection with Lakeshore Temperature Controller 336 and Sourcemeter Keithley
        LSTC336;
        KLYSM2450;
        KYST2987;
        
        %Parameters:
        T0;
        Tf;
        tp;
        Vp;
        V0;
        Vf;
        NPLC;
        Measurecount;

        Range;
        Ramp;

        Heater;
        P;
        I;
        D;

        Step;
        AccuracyStable;
        AccuracyStep;
        AccuracyEnd;
        rampingrate1;

        SampleInfo;
        Comments;

        data;
        sheet1;
        sheet2;
        sheet3;

        currentDateTime;
        hourvalue;
        minutevalue;
        secondvalue;
        yearvalue;
        monthvalue;
        dayvalue;
        Combineddate;
        dateString;
        Combinedtime;
        timeString;

        settings;
        stablization_var_names;
        measurement_var_names;

        freq;
        mw;
        consttableData;

        % Set Initial Variables for the Stabilization Phase
        k;
        l1;
        l2;
        k1;
        l3;
        l4;
        l5;
        l6;

        initialtemp;
        initialvolt;
        initialcurrent;
        initialtempall;
        initialvoltall;
        initialcurrentall;
        initialtime;
        initialtimeall;
        initialrealramping;

        % Set Initial Variables for the Measurement Phase
        n;
        i;
        m;
        j;
        i1;
        n1;
        m1;

        outputtemp;
        realtemp;
        realtempall;
        voltage;
        current;
        time;
        timeall;
        realramping;

        stablization_table;
        measurement_table;

        afterrange;
        aftervoltage;

    end
    
    methods (Access = private)
        
        function connectDevices(app)
            try
                app.LSTC336=serialport("COM3",57600);
                app.KLYSM2450=visadev("USB0::0x05E6::0x2450::04429200::0::INSTR");
                app.KYST2987=visadev("USB0::0x0957::0x9418::MY54321288::0::INSTR");
            catch
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            %%
            %Experiment has begun:
            format longE
            %Clearing Data, Tables, Graphs:
            app.IndicatorLamp.Color='y'; % Yellow means experiment has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Experiment has started, resetting and initializing parameters');

            app.StartExperimentButton.Enable="off";
            
            cla(app.UIAxes1)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)

            app.HeaterActivatedTextArea.FontColor='blue';

            app.UITable1.Data=[];
            app.UITable2.Data=[];

            app.T0=app.InitialTemperatureEditField.Value;
            app.Tf=app.FinalTemperatureEditField.Value;
            app.tp=app.PulseTimeEditField.Value;
            app.Vp=app.PulseVoltageEditField.Value;
            app.V0=app.PreliminaryVoltageEditField.Value;
            app.Vf=app.MeasurementVoltageEditField.Value;

            app.NPLC=app.NPLCEditField.Value;
            app.Measurecount=app.MeasurecountEditField.Value;

            app.Range=app.RangeEditField.Value; % Heating
            app.Ramp=app.RampEditField.Value;
            app.P=app.PEditField.Value;
            app.I=app.IEditField.Value;
            app.D=app.DEditField.Value;

            app.Step=app.StepEditField.Value; %Step poitns of temperature at which measurements are taken
            app.AccuracyStable=app.AccuracyStableEditField.Value;
            app.AccuracyStep=app.AccuracyStepEditField.Value;
            app.AccuracyEnd=app.AccuracyEndEditField.Value;

            app.SampleInfo=app.SampleInfoEditField.Value;
            app.Comments=app.CommentsEditField.Value;
            %app.StateofTheExperimentTextArea1.WordWrap="on";

            % The parameters for configuration of Temperature Controller Lake Shore Model 336
            app.LSTC336.FlowControl="none";
            app.LSTC336.Parity="odd";
            app.LSTC336.StopBits=1;
            app.LSTC336.DataBits=7;

            %Reset all devices:
            writeline(app.LSTC336,'*RST')
            writeline(app.KYST2987,'*RST')
            writeline(app.KLYSM2450,'*RST')
            pause(0.1)

            %Measuring Starting Temperature
            Startingtemperature=str2double(writeread(app.LSTC336,'KRDG?1'));

            % Set Initial Variables for the Stabilization Phase
            app.k=1;
            app.l1=1;
            app.l2=1;
            app.k1=1;
            app.l3=1;
            app.l4=1;
            app.l5=1;
            app.l6=1;

            app.initialtemp =zeros(app.k,1);
            app.initialvolt=zeros(app.l1,1);
            app.initialcurrent=zeros(app.l2,1);
            app.initialtempall=zeros(app.k1,1);
            app.initialvoltall=zeros(app.l3,1);
            app.initialcurrentall=zeros(app.l4,1);
            app.initialtimeall=zeros(app.l5,1);
            app.initialrealramping=zeros(app.l6,1);

            % Set Initial Variables for the Measurement Phase
            app.n=1;
            app.i=1;
            app.m=1;
            app.j=1;
            app.i1=1;
            app.n1=1;
            app.m1=1;

            app.outputtemp=zeros(app.n,1);
            app.realtemp=zeros(app.i,1);
            app.realtempall=zeros(app.i1,1);
            app.voltage=zeros(app.m,1);
            app.current=zeros(app.j,1);
            app.timeall=zeros(app.n1,1);
            app.realramping=zeros(app.m1,1);

            % define measurement parameters and data for saving data
            if exist('data.xlsx','file')==2
                delete('data.xlsx')
            end
            app.data='data.xlsx';
            app.sheet1 ='Settings';
            app.sheet2 ='Stabilization';
            app.sheet3 ='Measurements';

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.settings = {'Sample Info' , app.SampleInfo ; 'Sample Description' , app.Comments ;'Major Measurement Parameters' , ' ';'Starting Temperature' , Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keithley SM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'время' , '' ; 'Date' ,app.dateString ; 'Time', app.timeString};

            app.stablization_var_names = {'Temperature', 'Current'};
            app.measurement_var_names = {'Temperature', 'Current'};
            app.stablization_table = table(app.initialtemp, app.initialcurrent, 'VariableNames', app.stablization_var_names);
            app.measurement_table = table(app.realtemp, app.current, 'VariableNames', app.measurement_var_names);

            writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
            writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
            writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);

            %Voltage is the SOURCE function, Current is measurement:
            writeline(app.KLYSM2450,'smu.measure.func = smu.FUNC_DC_CURRENT')
            writeline(app.KLYSM2450,'smu.source.func = smu.FUNC_DC_VOLTAGE')

            %Settings for KEITHLEY that need to be set before start of measurements
            %Measurement Settings for KEITHLEY:
            writeline(app.KLYSM2450,'smu.measure.terminals = smu.TERMINALS_REAR')
            writeline(app.KLYSM2450,'smu.measure.sense = smu.SENSE_2WIRE')
            writeline(app.KLYSM2450,'smu.measure.autorange = smu.ON')
            writeline(app.KLYSM2450, strcat('smu.measure.count = ',string(app.Measurecount)))
            writeline(app.KLYSM2450, strcat('smu.measure.nplc=', string(app.NPLC)))
            writeline(app.KLYSM2450,'frequency=localnode.linefreq');

            app.freq=str2double(writeread(app.KLYSM2450,'print(frequency)'));
            app.mw=app.NPLC/app.freq;
            app.consttableData=[app.freq, app.NPLC,app.mw];
            app.UITable3.ColumnFormat={'numeric'};
            app.UITable3.Data=app.consttableData;

            % Source Settings for KEITHLEY:
            writeline(app.KLYSM2450,'smu.source.highc = smu.OFF')
            writeline(app.KLYSM2450,'smu.source.autorange = smu.ON')
            writeline(app.KLYSM2450,'smu.source.readback = smu.ON')
            writeline(app.KLYSM2450,'smu.source.output = smu.ON')
            writeline(app.KLYSM2450,'smu.source.ilimit.level = 0.1')

            % Set Initial Parameters for the Temperature Controller: RANGE,PID
            writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            pause(0.1)
            str_P=num2str(app.P);
            str_I=num2str(app.I);
            str_D=num2str(app.D);
            PID=strcat(str_P,',',str_I,',',str_D);
            writeline(app.LSTC336, strcat('PID 1,',PID))
            pause(0.1)
            app.afterrange=app.HeateraftermeasureSwitch.Value;
            app.aftervoltage=app.VoltageaftermeasureSwitch.Value;


            %%

            %Stabilization Phase:
            app.IndicatorLamp.Color='b'; % Blue means stabilization part has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Stabilizing initial temperature');

            %Set INITIAL Temperature
            writeline(app.LSTC336,'RAMP 1,0,0')
            pause(0.1)
            writeline(app.LSTC336, strcat('SETP 1,', string(app.T0)))  %initial temp
            writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.V0)))% Initial Voltage

            % Check the initial condition for more than onepoint, compare all values
            % to T0 and make sure it falls within the range, waits until temp is stable

            lowerBound=app.T0-app.AccuracyStable;
            upperBound=app.T0+app.AccuracyStable;

            %process of stabilizing temperature:
            %Setup buffer readings to take in values of current and voltage during the
            writeline(app.KLYSM2450,'Voltage_Current_Initial_Buffer = buffer.make(100),buffer.STYLE_WRITABLE_FULL')
            writeline(app.KLYSM2450,'Voltage_Current_Initial_Buffer.clear()')
            initialstart=tic;
            while true

                writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Initial_Buffer)')
                newTemp=str2double(writeread(app.LSTC336,'KRDG?1'));
                app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));

                initialvoltagetest=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.sourcevalues)'),', ')));
                initialcurrenttest=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.readings)'),', ')));

                if isempty(newTemp)
                    break;
                end

                app.TemperatureTextArea.Value=num2str(newTemp);
                app.VoltageTextArea.Value=num2str(initialvoltagetest);
                app.CurrentTextArea.Value=num2str(initialcurrenttest);
                app.HeaterActivatedTextArea.Value=num2str(app.Heater);

                app.initialtemp(app.k,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                app.initialtempall(app.k1,1)=app.initialtemp(app.k,1);
                app.initialvolt(app.l1,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.sourcevalues)'),', ')));
                app.initialvoltall(app.l3,1)=app.initialvolt(app.l1,1);
                app.initialcurrent(app.l2,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.readings)'),', ')));
                app.initialcurrentall(app.l4,1)=app.initialcurrent(app.l2,1);

                initialtableData=[app.initialtempall, app.initialcurrentall];
                app.UITable1.ColumnFormat={'numeric'};
                app.UITable1.Data=initialtableData;

                plot(app.UIAxes1,app.initialtemp,app.initialcurrent)
                xlabel(app.UIAxes1,'Initial Temperature ; [T]=[Kelvin]')
                ylabel(app.UIAxes1,'Initial Current Linear ; [I]=[Amps]')
                title(app.UIAxes1,'Current Vs Temperature, In Linear and During Stabilization')

                semilogy(app.UIAxes2,app.initialtemp,abs(app.initialcurrent))
                xlabel(app.UIAxes2,'Initial Temperature ; [T]=[Kelvin]')
                ylabel(app.UIAxes2,'Initial Current Logarithmic ; [I]=[Amps]')
                title(app.UIAxes2,'Current Vs Temperature, In Logarithmic and During Stabilization')

                app.k1=app.k1+1;
                app.l3=app.l3+1;
                app.l4=app.l4+1;

                if length(app.initialtemp)>=10
                    last10Elements1=app.initialtemp(end-9:end);

                    rangeCheck=all(last10Elements1>=lowerBound & last10Elements1<=upperBound);
                    if rangeCheck
                        break;
                    end
                end

                writeline(app.KLYSM2450,'Voltage_Current_Initial_Buffer.clear()')

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename


                app.k=app.k+1;
                app.l1=app.l1+1;
                app.l2=app.l2+1;

                pause(1)

                app.initialtime=toc(initialstart);
                app.initialtimeall(app.l5,1)=app.initialtime;
                if app.l5>1
                    initialtimedifference=(diff(app.initialtimeall))/60;
                    initialtempdifference=diff(app.initialtempall);
                    app.initialrealramping(app.l6,1)=mean(initialtempdifference./initialtimedifference);
                    initialramp=app.initialrealramping(app.l6,1);
                    app.TemperatureRateTextArea.Value=num2str(initialramp);
                    app.l6=app.l6+1;
                end
                app.l5=app.l5+1;

            end
            %%
            %Pulse Phase:
            writeline(app.KLYSM2450,'beeper.beep(1, 1500)')
            app.IndicatorLamp.Color='c'; % Cyan means pulse phase has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Pulse  has started');
            app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
            app.HeaterActivatedTextArea.Value=num2str(app.Heater);

            writeline(app.KLYSM2450,'Pulse_Buffer = buffer.make(100),buffer.STYLE_WRITABLE_FULL')
            writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp))) %Sets the Pulse voltage
            pause(1)
            writeline(app.KLYSM2450,'smu.measure.read(Pulse_Buffer)')
            realtemppulse=str2double(writeread(app.LSTC336,'KRDG?1'));
            voltagepulse=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Pulse_Buffer.n,Pulse_Buffer.sourcevalues)'),', ')));
            currentpulse=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Pulse_Buffer.n,Pulse_Buffer.readings)'),', ')));

            app.TemperatureTextArea.Value=num2str(realtemppulse);
            app.VoltageTextArea.Value=num2str(voltagepulse);
            app.CurrentTextArea.Value=num2str(currentpulse);

            pause(app.tp)
            %%
            % Measurment Phase:
            writeline(app.KLYSM2450,'beeper.beep(1, 1000)')
            app.IndicatorLamp.Color='r'; % red means measurement part has started
            app.StateoftheExperimentTextArea.Value=('The Phase: Measurements have started');

            %PROCESS for T0 to Tf, Setting the final Voltage, Buffers and readin values
            writeline(app.LSTC336, strcat('RAMP 1,1,',string(app.Ramp)))
            pause(0.1)
            writeline(app.LSTC336, strcat('SETP 1,', string(app.Tf)))  %writeline(LSTC336,'SETP 1,260)')
            writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vf))) %Sets the final voltage

            outputtemptest=str2double(writeread(app.LSTC336,'SETP?1'));
            realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
            app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
            app.HeaterActivatedTextArea.Value=num2str(app.Heater);

            lasttempforheating=realtemptest+app.AccuracyStep;
            lasttempforcooling=realtemptest-app.AccuracyStep;

            % The while loops: 1. The Heating, 2. The Cooling

            if outputtemptest<app.Tf % Means it's heating

                %make buffer and print readings to receive and save data for KETHLEY2450:
                writeline(app.KLYSM2450,'Voltage_Current_Buffer = buffer.make(100),buffer.STYLE_WRITABLE_FULL')
                writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                start=tic;
                while (realtemptest<(app.Tf+app.Step)) % Heating

                    realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
                    app.realtempall(app.i1,1)=realtemptest;
                    app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                    app.HeaterActivatedTextArea.Value=num2str(app.Heater);
                    if app.Heater>99
                        writeline(app.KLYSM2450,'beeper.beep(0.2, 2250)')
                        app.HeaterActivatedTextArea.FontColor='red';
                    else
                        app.HeaterActivatedTextArea.FontColor='blue';
                    end

                    if abs((realtemptest-lasttempforheating))<=(app.AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(app.AccuracyStep)
                        writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        lasttempforheating=lasttempforheating+app.Step;
                        lasttempforcooling=lasttempforcooling+app.Step;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                    end

                    if (realtemptest-lasttempforheating)>2
                        writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        lasttempforheating=lasttempforheating+app.Step+3;
                        lasttempforcooling=lasttempforcooling+app.Step+3;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                    end

                    writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                    app.TemperatureTextArea.Value=num2str(realtemptest);

                    app.time=toc(start);
                    app.timeall(app.n1,1)=app.time;
                    if app.n1>1
                        timedifference=(diff(app.timeall))/60;
                        tempdifference=diff(app.realtempall);
                        app.realramping(app.m1,1)=mean(tempdifference./timedifference);
                        realramp=app.realramping(app.m1,1);
                        app.TemperatureRateTextArea.Value=num2str(realramp);
                        app.m1=app.m1+1;
                    end
                    app.n1=app.n1+1;
                    app.i1=app.i1+1;

                    pause(0.25)
                    if (realtemptest>(app.Tf+app.AccuracyEnd))
                        app.IndicatorLamp.Color='g'; % green means Measurements have stopped
                        app.StateoftheExperimentTextArea.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');

                        [filename, pathname] = uiputfile('*.xlsx', 'Save as');
                        if isequal(filename,0) || isequal(pathname,0)
                            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                        else

                            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');

                            StabilizationTemperature = app.initialtempall;
                            StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                            MeasurementsTemperature = app.realtemp;
                            MeasurementsCurrent = app.current ; % replace this with your Measurements data
                            % Create tables for measurements
                            app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                            app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                            %write settings, temperature data and current data to the Excel file
                            writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                            writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                            writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                            % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                        end
                        break
                    end
                end

            elseif outputtemptest>app.Tf %means it's cooling
                start=tic;
                while (realtemptest>(app.Tf-app.Step)) %cooling

                    realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
                    app.realtempall(app.i1,1)=realtemptest;
                    app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                    app.HeaterActivatedTextArea.Value=num2str(app.Heater);
                    if app.Heater>95
                        writeline(app.KLYSM2450,'beeper.beep(0.2, 2250)')
                        app.HeaterActivatedTextArea.FontColor='red';
                    else
                        app.HeaterActivatedTextArea.FontColor='blue';
                    end

                    if abs((realtemptest-lasttempforheating))<=(app.AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(app.AccuracyStep)
                        writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        lasttempforheating=lasttempforheating-app.Step;
                        lasttempforcooling=lasttempforcooling-app.Step;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                    end

                    if (realtemptest-lasttempforheating)<2
                        writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2.ColumnFormat={'numeric'};
                        app.UITable2.Data=MeasurementtableData;

                        plot(app.UIAxes3,app.realtemp,app.current)
                        xlabel(app.UIAxes3,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea.Value=num2str(app.current(app.j,1));

                        writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        lasttempforheating=lasttempforheating-app.Step-3;
                        lasttempforcooling=lasttempforcooling-app.Step-3;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                    end

                    writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                    app.TemperatureTextArea.Value=num2str(realtemptest);

                    app.time=toc(start);
                    app.timeall(app.n1,1)=app.time;
                    if app.n1>1
                        timedifference=(diff(app.timeall))/60;
                        tempdifference=diff(app.realtempall);
                        app.realramping(app.m1,1)=mean(tempdifference./timedifference);
                        realramp=app.realramping(app.m1,1);
                        app.TemperatureRateTextArea.Value=num2str(realramp);
                        app.m1=app.m1+1;
                    end
                    app.n1=app.n1+1;
                    app.i1=app.i1+1;

                    pause(0.25)
                    if (realtemptest<(app.Tf-app.AccuracyEnd))
                        app.IndicatorLamp.Color='g'; % green means Measurements have stopped
                        app.StateoftheExperimentTextArea.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');

                        [filename, pathname] = uiputfile('*.xlsx', 'Save as');
                        if isequal(filename,0) || isequal(pathname,0)

                            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
                        else

                            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');

                            StabilizationTemperature = app.initialtempall;
                            StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                            MeasurementsTemperature = app.realtemp;
                            MeasurementsCurrent = app.current ; % replace this with your Measurements data

                            % Create tables for measurements
                            app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                            app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                            %write temperature data and current data to the Excel file
                            writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                            writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                            writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                            % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                        end
                        break
                    end
                end

            else
                app.StateoftheExperimentTextArea.Value=('The Phase: The Final temperature and Set point temperature are equal!');
                app.IndicatorLamp.Color='0.50,0.50,0.50';
            end

            %%
            writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            writeline(app.LSTC336,'RAMP 1,0,0') % Set Ramping Off
            if strcmp(app.afterrange,'On')
                writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            end
            if strcmp(app.afterrange,'Off')
                writeline(app.LSTC336,('RANGE 1, 0'))
            end
            if strcmp(app.aftervoltage,'On')
                writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp)))
            end
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KLYSM2450, 'smu.source.level = 0')
            end

            pause(1);
            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done!');
            app.IndicatorLamp.Color='w';
            app.StartExperimentButton.Enable="on";

            clc
            clear
            close all
            clearvars
            clearvars -global
            clear RRAM
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.IndicatorLamp.Color='k';
            app.StartExperimentButton.Enable="on";
            app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment has been stopped, Save the data!');
            writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            writeline(app.LSTC336,'RAMP 1,0,0') % Set Ramping Off
            if strcmp(app.afterrange,'On')
                writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            end
            if strcmp(app.afterrange,'Off')
                writeline(app.LSTC336,('RANGE 1, 0'))
            end
            if strcmp(app.aftervoltage,'On')
                writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp)))
            end
            if strcmp(app.aftervoltage,'Off')
                writeline(app.KLYSM2450, 'smu.source.level = 0')
            end

            [filename, pathname] = uiputfile('*.xlsx', 'Save as');
            if isequal(filename,0) || isequal(pathname,0)

                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is not saved!');
            else

                app.StateoftheExperimentTextArea.Value=('The Phase: The Experiment is done, the Data is saved!');

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                %write temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
            end
            clc
            waitfor(app.StartExperimentButton)
            clear RRAM

        end

        % Button pushed function: StartExperimentButton_2
        function StartExperimentButton_2Pushed(app, event)
            %%
            %Experiment has begun:
            format longE
            %Clearing Data, Tables, Graphs:
            app.IndicatorLamp_2.Color='y'; % Yellow means experiment has started
            app.StateoftheExperimentTextArea_2.Value=('The Phase: Experiment has started, resetting and initializing parameters');

            app.StartExperimentButton_2.Enable="off";

            cla(app.UIAxes1_2)
            cla(app.UIAxes2_2)
            cla(app.UIAxes3_2)
            cla(app.UIAxes4_2)
            app.UITable1_2.Data=[];
            app.UITable2_2.Data=[];

            app.T0=app.InitialTemperatureEditField_2.Value;
            app.Tf=app.FinalTemperatureEditField_2.Value;
            app.tp=app.PulseTimeEditField_2.Value;
            app.Vp=app.PulseVoltageEditField_2.Value;
            app.V0=app.PreliminaryVoltageEditField_2.Value;
            app.Vf=app.MeasurementVoltageEditField_2.Value;

            app.NPLC=app.NPLCEditField_3.Value;
            app.Measurecount=app.MeasurecountEditField_2.Value;

            app.Range=app.RangeEditField_2.Value; % Heating
            app.Ramp=app.RampEditField_2.Value;
            app.P=app.PEditField_2.Value;
            app.I=app.IEditField_2.Value;
            app.D=app.DEditField_2.Value;

            app.Step=app.StepEditField_2.Value; %Step poitns of temperature at which measurements are taken
            app.AccuracyStable=app.AccuracyStableEditField_2.Value;
            app.AccuracyStep=app.AccuracyStepEditField_2.Value;
            app.AccuracyEnd=app.AccuracyEndEditField_2.Value;

            app.SampleInfo=app.SampleInfoEditField_2.Value;
            app.Comments=app.CommentsEditField_2.Value;
            %app.StateofTheExperimentTextArea1.WordWrap="on";

            % The parameters for configuration of Temperature Controller Lake Shore Model 336
            app.LSTC336.FlowControl="none";
            app.LSTC336.Parity="odd";
            app.LSTC336.StopBits=1;
            app.LSTC336.DataBits=7;
            writeline(app.KYST2987,':SYST:COMM:ENAB 1,USB');

            %Reset all devices:
            writeline(app.LSTC336,'*RST')
            writeline(app.KYST2987,'*RST')
            writeline(app.KLYSM2450,'*RST')
            pause(0.1)

            %Measuring Starting Temperature
            Startingtemperature=str2double(writeread(app.LSTC336,'KRDG?1'));

            % Set Initial Variables for the Stabilization Phase
            app.k=1;
            app.l1=1;
            app.l2=1;
            app.k1=1;
            app.l3=1;
            app.l4=1;
            app.l5=1;
            app.l6=1;

            app.initialtemp =zeros(app.k,1);
            app.initialvolt=zeros(app.l1,1);
            app.initialcurrent=zeros(app.l2,1);
            app.initialtempall=zeros(app.k1,1);
            app.initialvoltall=zeros(app.l3,1);
            app.initialcurrentall=zeros(app.l4,1);
            app.initialtimeall=zeros(app.l5,1);
            app.initialrealramping=zeros(app.l6,1);

            % Set Initial Variables for the Measurement Phase
            app.n=1;
            app.i=1;
            app.m=1;
            app.j=1;
            app.i1=1;
            app.n1=1;
            app.m1=1;

            app.outputtemp=zeros(app.n,1);
            app.realtemp=zeros(app.i,1);
            app.realtempall=zeros(app.i1,1);
            app.voltage=zeros(app.m,1);
            app.current=zeros(app.j,1);
            app.timeall=zeros(app.n1,1);
            app.realramping=zeros(app.m1,1);

            % define measurement parameters and data for saving data
            if exist('data.xlsx','file')==2
                delete('data.xlsx')
            end

            app.data='data.xlsx';
            app.sheet1 ='Settings';
            app.sheet2 ='Stabilization';
            app.sheet3 ='Measurements';

            app.currentDateTime=datetime('now');
            app.hourvalue=num2str(hour(app.currentDateTime));
            app.minutevalue=num2str(minute(app.currentDateTime));
            app.secondvalue=num2str(fix(second(app.currentDateTime)));
            app.yearvalue=num2str(year(app.currentDateTime));
            app.monthvalue=num2str(month(app.currentDateTime));
            app.dayvalue=num2str(day(app.currentDateTime));
            app.Combineddate=strcat(app.dayvalue,'.',app.monthvalue,'.',app.yearvalue);
            app.dateString=strcat(app.Combineddate);
            app.Combinedtime=strcat(app.hourvalue,':',app.minutevalue,':',app.secondvalue);
            app.timeString=strcat(app.Combinedtime);

            app.settings = {'Sample Info' , app.SampleInfo ; 'Sample Description' , app.Comments ;'Major Measurement Parameters' , ' ';'Starting Temperature' , Startingtemperature ; 'Initial Temperature', app.T0 ; 'Final Temperature' , app.Tf ; 'Preliminary Voltage' , app.V0 ; 'Pulse Voltage' , app.Vp ; 'Pulse Time' , app.tp ; 'Measurement Voltage' , app.Vf ; 'Subsidiary Measurement Parameters', ' ' ; 'Step' , app.Step ; 'Accuracy Step' , app.AccuracyStep ; 'Accuracy Stable' , app.AccuracyStable ; 'Accuracy End' , app.AccuracyEnd ; 'Lakeshore TC Parameters' , ' ' ; 'Ramp' , app.Ramp ; 'Range' , app.Range ; 'P' , app.P ; 'I' , app.I ; 'D' , app.D ; 'Keysight EM Parameters' , ' ' ; 'NPLC' , app.NPLC ; 'Measurecount' , app.Measurecount ; 'Time' , '' ; 'Date' ,app.dateString ; 'clock', app.timeString};

            app.stablization_var_names = {'Temperature', 'Current'};
            app.measurement_var_names = {'Temperature', 'Current'};
            app.stablization_table = table(app.initialtemp, app.initialcurrent, 'VariableNames', app.stablization_var_names);
            app.measurement_table= table(app.realtemp, app.current, 'VariableNames', app.measurement_var_names);

            writecell(app.settings, fullfile(app.data), 'Sheet', app.sheet1);
            writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
            writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);


            %Parameters for Keysight: Voltage is the SOURCE function, Current is SENS:
            %writeline(app.KYST2987,':SENS:FUNC "CURR"')
            writeline(app.KYST2987,':SENS:CURR:RANG:AUTO ON')
            writeline(app.KYST2987,':SENS:CURR:LIM:AUTO ON')

            writeline(app.KYST2987,':SOUR:FUNC:MODE VOLT')
            writeline(app.KYST2987,':SOUR:VOLT:RANG:AUTO ON')

            writeline(app.KYST2987,':SENS:CURR:NPLC:AUTO OFF')
            writeline(app.KYST2987, sprintf(':SENS:CURR:NPLC %f', (app.NPLC)))

            writeline(app.KYST2987, sprintf('trig:coun %f',(app.Measurecount)))
            writeline(app.KYST2987, 'trig:sour aint')
            writeline(app.KYST2987, 'trig:acq:del 2e-4')

            writeline(app.KYST2987,':OUTP ON')
            writeline(app.KYST2987,':INP ON')
           
            writeline(app.KYST2987,':SYST:LFR:DET:AUTO')

            app.freq=str2double(writeread(app.KYST2987, ':SYST:LFR?'));
            app.mw=app.NPLC/app.freq;
            app.consttableData=[app.freq, app.NPLC,app.mw];
            app.UITable3_2.ColumnFormat={'numeric'};
            app.UITable3_2.Data=app.consttableData;

            % Set Initial Parameters for the Temperature Controller: RANGE,PID
            writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            pause(0.1)
            str_P=num2str(app.P);
            str_I=num2str(app.I);
            str_D=num2str(app.D);
            PID=strcat(str_P,',',str_I,',',str_D);
            writeline(app.LSTC336, strcat('PID 1,',PID))
            pause(0.1)
            app.afterrange=app.HeateraftermeasureSwitch_2.Value;
            app.aftervoltage=app.VoltageaftermeasureSwitch_2.Value;


            %%
            %Stabilization Phase:
            app.IndicatorLamp_2.Color='b'; % Blue means stabilization part has started
            app.StateoftheExperimentTextArea_2.Value=('The Phase: Stabilizing initial temperature');

            %Set INITIAL Temperature

            writeline(app.LSTC336,'RAMP 1,0,0')
            pause(0.1)
            writeline(app.LSTC336, strcat('SETP 1,', string(app.T0)))  %initial temp
            %writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.V0)))% Initial Voltage
            writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.V0)))

            % Check the initial condition for more than onepoint, compare all values
            % to T0 and make sure it falls within the range, waits until temp is stable

            lowerBound=app.T0-app.AccuracyStable;
            upperBound=app.T0+app.AccuracyStable;

            %process of stabilizing temperature:
            %Setup buffer readings to take in values of current and voltage during the
            %writeline(app.KLYSM2450,'Voltage_Current_Initial_Buffer = buffer.make(100),buffer.STYLE_WRITABLE_FULL')
            %writeline(app.KLYSM2450,'Voltage_Current_Initial_Buffer.clear()')
            initialstart=tic;
            while true

                %writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Initial_Buffer)')
                writeline(app.KYST2987, 'INIT:acq')
                newTemp=str2double(writeread(app.LSTC336,'KRDG?1'));
                app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));

                %initialvoltagetest=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.sourcevalues)'),', ')));
                %initialcurrenttest=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.readings)'),', ')));
                initialvoltagetest=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                initialcurrenttest=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));
                
                if isempty(newTemp)
                    break;
                end

                app.TemperatureTextArea_2.Value=num2str(newTemp);
                app.VoltageTextArea_2.Value=num2str(initialvoltagetest);
                app.CurrentTextArea_2.Value=num2str(initialcurrenttest);
                app.HeaterActivatedTextArea_2.Value=num2str(app.Heater);

                app.initialtemp(app.k,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                app.initialtempall(app.k1,1)=app.initialtemp(app.k,1);
                %app.initialvolt(app.l1,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.sourcevalues)'),', ')));
                app.initialvolt(app.l1,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                app.initialvoltall(app.l3,1)=app.initialvolt(app.l1,1);
                %app.initialcurrent(app.l2,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Initial_Buffer.n,Voltage_Current_Initial_Buffer.readings)'),', ')));
                app.initialcurrent(app.l2,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));
                app.initialcurrentall(app.l4,1)=app.initialcurrent(app.l2,1);


                initialtableData=[app.initialtempall, app.initialcurrentall];
                app.UITable1_2.ColumnFormat={'numeric'};
                app.UITable1_2.Data=initialtableData;

                plot(app.UIAxes1_2, app.initialtemp,app.initialcurrent)
                xlabel(app.UIAxes1_2,'Initial Temperature ; [T]=[Kelvin]')
                ylabel(app.UIAxes1_2,'Initial Current Linear ; [I]=[Amps]')
                title(app.UIAxes1_2,'Current Vs Temperature, In Linear and During Stabilization')

                semilogy(app.UIAxes2_2, app.initialtemp,abs(app.initialcurrent))
                xlabel(app.UIAxes2_2,'Initial Temperature ; [T]=[Kelvin]')
                ylabel(app.UIAxes2_2,'Initial Current Logarithmic ; [I]=[Amps]')
                title(app.UIAxes2_2,'Current Vs Temperature, In Logarithmic and During Stabilization')

                app.k1=app.k1+1;
                app.l3=app.l3+1;
                app.l4=app.l4+1;

                if length(app.initialtemp)>=10
                    last10Elements1=app.initialtemp(end-9:end);

                    rangeCheck=all(last10Elements1>=lowerBound & last10Elements1<=upperBound);
                    if rangeCheck
                        break;
                    end
                end

                %writeline(app.KLYSM2450,'Voltage_Current_Initial_Buffer.clear()')
                writeline(app.KYST2987, ':TRAC:CLE')

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                %write settings, temperature data and current data to the Excel file
                writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename


                app.k=app.k+1;
                app.l1=app.l1+1;
                app.l2=app.l2+1;

                pause(1)

                app.initialtime=toc(initialstart);
                app.initialtimeall(app.l5,1)=app.initialtime;
                if app.l5>1
                    initialtimedifference=(diff(app.initialtimeall))/60;
                    initialtempdifference=diff(app.initialtempall);
                    app.initialrealramping(app.l6,1)=mean(initialtempdifference./initialtimedifference);
                    initialramp=app.initialrealramping(app.l6,1);
                    app.TemperatureRateTextArea_2.Value=num2str(initialramp);
                    app.l6=app.l6+1;
                end
                app.l5=app.l5+1;

            end
            %%
            %Pulse Phase:
            app.IndicatorLamp_2.Color='c'; % Cyan means pulse phase has started
            writeline(app.KYST2987,':SYSTem:BEEPer 50,0.5')% Beep for Pulse
            app.StateoftheExperimentTextArea_2.Value=('The Phase: Pulse  has started');
            app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
            app.HeaterActivatedTextArea_2.Value=num2str(app.Heater);

            %writeline(app.KLYSM2450,'Pulse_Buffer = buffer.make(100),buffer.STYLE_WRITABLE_FULL')
            %writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp))) %Sets the Pulse voltage
            writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vp)))
            pause(1)
            %writeline(app.KLYSM2450,'smu.measure.read(Pulse_Buffer)')
            writeline(app.KYST2987, 'INIT:acq')
            realtemppulse=str2double(writeread(app.LSTC336,'KRDG?1'));
            %voltagepulse=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Pulse_Buffer.n,Pulse_Buffer.sourcevalues)'),', ')));
            %currentpulse=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Pulse_Buffer.n,Pulse_Buffer.readings)'),', ')));
            voltagepulse=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
            currentpulse=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

            app.TemperatureTextArea_2.Value=num2str(realtemppulse);
            app.VoltageTextArea_2.Value=num2str(voltagepulse);
            app.CurrentTextArea_2.Value=num2str(currentpulse);
            writeline(app.KYST2987, ':TRAC:CLE')

            pause(app.tp)
            %%
            % Measurment Phase:
            app.IndicatorLamp_2.Color='r'; % red means measurement part has started
            writeline(app.KYST2987,':SYSTem:BEEPer 25,1') % beep for measurements
            app.StateoftheExperimentTextArea_2.Value=('The Phase: Measurements have started');

            %PROCESS for T0 to Tf, Setting the final Voltage, Buffers and readin values
            writeline(app.LSTC336, strcat('RAMP 1,1,',string(app.Ramp)))
            pause(0.1)
            writeline(app.LSTC336, strcat('SETP 1,', string(app.Tf)))  %writeline(LSTC336,'SETP 1,260)')
            %writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vf))) %Sets the final voltage
            writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vf)))

            outputtemptest=str2double(writeread(app.LSTC336,'SETP?1'));
            realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
            app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
            app.HeaterActivatedTextArea.Value=num2str(app.Heater);

            lasttempforheating=realtemptest+app.AccuracyStep;
            lasttempforcooling=realtemptest-app.AccuracyStep;

            % The while loops: 1. The Heating, 2. The Cooling

            if outputtemptest<app.Tf % Means it's heating

                %make buffer and print readings to receive and save data for KETHLEY2450:
                %writeline(app.KLYSM2450,'Voltage_Current_Buffer = buffer.make(100),buffer.STYLE_WRITABLE_FULL')
                %writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                start=tic;
                while (realtemptest<(app.Tf+app.Step)) % Heating

                    realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
                    app.realtempall(app.i1,1)=realtemptest;
                    app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                    app.HeaterActivatedTextArea_2.Value=num2str(app.Heater);
                    if app.Heater>99
                        writeline(app.KLYSM2450,'beeper.beep(0.2, 2250)')
                        app.HeaterActivatedTextArea_2.FontColor='red';
                    else
                        app.HeaterActivatedTextArea_2.FontColor='blue';
                    end

                    if abs((realtemptest-lasttempforheating))<=(app.AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(app.AccuracyStep)
                        %writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        %app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2_2.ColumnFormat={'numeric'};
                        app.UITable2_2.Data=MeasurementtableData;

                        plot(app.UIAxes3_2,app.realtemp,app.current)
                        xlabel(app.UIAxes3_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3_2,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3_2,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4_2,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4_2,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4_2,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea_2.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea_2.Value=num2str(app.current(app.j,1));

                        %writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating+app.Step;
                        lasttempforcooling=lasttempforcooling+app.Step;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                    end

                    if (realtemptest-lasttempforheating)>2
                        %writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        %app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2_2.ColumnFormat={'numeric'};
                        app.UITable2_2.Data=MeasurementtableData;

                        plot(app.UIAxes3_2,app.realtemp,app.current)
                        xlabel(app.UIAxes3_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3_2,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3_2,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4_2,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4_2,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4_2,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea_2.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea_2.Value=num2str(app.current(app.j,1));

                        %writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating+app.Step+3;
                        lasttempforcooling=lasttempforcooling+app.Step+3;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                    end

                    %writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                    writeline(app.KYST2987, ':TRAC:CLE')
                    app.TemperatureTextArea_2.Value=num2str(realtemptest);

                    app.time=toc(start);
                    app.timeall(app.n1,1)=app.time;
                    if app.n1>1
                        timedifference=(diff(app.timeall))/60;
                        tempdifference=diff(app.realtempall);
                        app.realramping(app.m1,1)=mean(tempdifference./timedifference);
                        realramp=app.realramping(app.m1,1);
                        app.TemperatureRateTextArea_2.Value=num2str(realramp);
                        app.m1=app.m1+1;
                    end
                    app.n1=app.n1+1;
                    app.i1=app.i1+1;

                    pause(0.25)
                    if (realtemptest>(app.Tf+app.AccuracyEnd))
                        app.IndicatorLamp_2.Color='g'; % green means Measurements have stopped
                        app.StateoftheExperimentTextArea_2.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');

                        [filename, pathname] = uiputfile('*.xlsx', 'Save as');
                        if isequal(filename,0) || isequal(pathname,0)
                            app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment is done, the Data is not saved!');
                        else

                            app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment is done, the Data is saved!');

                            StabilizationTemperature = app.initialtempall;
                            StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                            MeasurementsTemperature = app.realtemp;
                            MeasurementsCurrent = app.current ; % replace this with your Measurements data
                            % Create tables for measurements
                            app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                            app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                            %write settings, temperature data and current data to the Excel file
                            writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                            writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                            writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                            % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                        end
                        break
                    end
                end

            elseif outputtemptest>app.Tf %means it's cooling
                start=tic;
                while (realtemptest>(app.Tf-app.Step)) %cooling

                    realtemptest=str2double(writeread(app.LSTC336,'KRDG?1'));
                    app.realtempall(app.i1,1)=realtemptest;
                    app.Heater=str2double(writeread(app.LSTC336,'HTR?1'));
                    app.HeaterActivatedTextArea_2.Value=num2str(app.Heater);
                    if app.Heater>95
                        writeline(app.KLYSM2450,'beeper.beep(0.2, 2250)')
                        app.HeaterActivatedTextArea_2.FontColor='red';
                    else
                        app.HeaterActivatedTextArea_2.FontColor='blue';
                    end

                    if abs((realtemptest-lasttempforheating))<=(app.AccuracyStep) || abs((realtemptest-lasttempforcooling))<=(app.AccuracyStep)
                        %writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        %app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2_2.ColumnFormat={'numeric'};
                        app.UITable2_2.Data=MeasurementtableData;

                        plot(app.UIAxes3_2,app.realtemp,app.current)
                        xlabel(app.UIAxes3_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3_2,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3_2,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4_2,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4_2,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4_2,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea_2.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea_2.Value=num2str(app.current(app.j,1));

                        %writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating-app.Step;
                        lasttempforcooling=lasttempforcooling-app.Step;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
                    end

                    if (realtemptest-lasttempforheating)<2
                        %writeline(app.KLYSM2450,'smu.measure.read(Voltage_Current_Buffer)')
                        writeline(app.KYST2987, 'INIT:acq')

                        app.outputtemp(app.n,1)=str2double(writeread(app.LSTC336,'SETP?1'));
                        app.realtemp(app.i,1)=str2double(writeread(app.LSTC336,'KRDG?1'));
                        %app.voltage(app.m,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.sourcevalues)'),', ')));
                        %app.current(app.j,1)=mean(str2double(split(writeread(app.KLYSM2450,'printbuffer(1,Voltage_Current_Buffer.n,Voltage_Current_Buffer.readings)'),', ')));
                        app.voltage(app.m,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:VOLT?'),',')));
                        app.current(app.j,1)=mean(str2double(split(writeread(app.KYST2987,'FETC:arr:CURR?'),',')));

                        MeasurementtableData=[app.realtemp, app.current];
                        app.UITable2_2.ColumnFormat={'numeric'};
                        app.UITable2_2.Data=MeasurementtableData;

                        plot(app.UIAxes3_2,app.realtemp,app.current)
                        xlabel(app.UIAxes3_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes3_2,'Current linear ; [I]=[Amps]')
                        title(app.UIAxes3_2,'Current Vs Temperature, In Linear, During Measurements')

                        semilogy(app.UIAxes4_2,app.realtemp,abs(app.current))
                        xlabel(app.UIAxes4_2,'Temperature ; [T]=[Kelvin]')
                        ylabel(app.UIAxes4_2,'Current Logarithmic ; [I]=[Amps]')
                        title(app.UIAxes4_2,'Current Vs Temperature, In Logarithmic, During Measurements')

                        app.VoltageTextArea_2.Value=num2str(app.voltage(app.m,1));
                        app.CurrentTextArea_2.Value=num2str(app.current(app.j,1));

                        %writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                        writeline(app.KYST2987, ':TRAC:CLE')
                        lasttempforheating=lasttempforheating-app.Step-3;
                        lasttempforcooling=lasttempforcooling-app.Step-3;

                        app.n=app.n+1;
                        app.i=app.i+1;
                        app.m=app.m+1;
                        app.j=app.j+1;

                        %Saving The Data:
                        StabilizationTemperature = app.initialtempall;
                        StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                        MeasurementsTemperature = app.realtemp;
                        MeasurementsCurrent = app.current ; % replace this with your Measurements data

                        % Create tables for measurements
                        app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                        app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                        %write settings, temperature data and current data to the Excel file
                        writetable(app.stablization_table, fullfile(app.data), 'Sheet', app.sheet2);
                        writetable(app.measurement_table, fullfile(app.data), 'Sheet', app.sheet3);
                        % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                    end

                    %writeline(app.KLYSM2450,'Voltage_Current_Buffer.clear()')
                    writeline(app.KYST2987, ':TRAC:CLE')
                    app.TemperatureTextArea_2.Value=num2str(realtemptest);

                    app.time=toc(start);
                    app.timeall(app.n1,1)=app.time;
                    if app.n1>1
                        timedifference=(diff(app.timeall))/60;
                        tempdifference=diff(app.realtempall);
                        app.realramping(app.m1,1)=mean(tempdifference./timedifference);
                        realramp=app.realramping(app.m1,1);
                        app.TemperatureRateTextArea_2.Value=num2str(realramp);
                        app.m1=app.m1+1;
                    end
                    app.n1=app.n1+1;
                    app.i1=app.i1+1;

                    pause(0.25)
                    if (realtemptest<(app.Tf-app.AccuracyEnd))
                        app.IndicatorLamp_2.Color='g'; % green means Measurements have stopped
                        app.StateoftheExperimentTextArea.Value=('The Phase: The Measurements have finished, Please Save the Data When You See The Prompt');

                        [filename, pathname] = uiputfile('*.xlsx', 'Save as');
                        if isequal(filename,0) || isequal(pathname,0)

                            app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment is done, the Data is not saved!');
                        else

                            app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment is done, the Data is saved!');

                            StabilizationTemperature = app.initialtempall;
                            StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                            MeasurementsTemperature = app.realtemp;
                            MeasurementsCurrent = app.current ; % replace this with your Measurements data

                            % Create tables for measurements
                            app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                            app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                            %write temperature data and current data to the Excel file
                            writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                            writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                            writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                            % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename

                        end
                        break
                    end
                end

            else
                app.StateoftheExperimentTextArea_2.Value=('The Phase: The Final temperature and Set point temperature are equal!');
                app.IndicatorLamp_2.Color='0.50,0.50,0.50';
            end

            %%
            %writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            writeline(app.LSTC336,'RAMP 1,0,0') % Set Ramping Off
            if strcmp(app.afterrange,'On')
                writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            end
            if strcmp(app.afterrange,'Off')
                writeline(app.LSTC336,('RANGE 1, 0'))
            end
            if strcmp(app.aftervoltage,'On')
                %writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp)))
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vf)))
            end
            if strcmp(app.aftervoltage,'Off')
                %writeline(app.KLYSM2450, 'smu.source.level = 0')
                writeline(app.KYST2987,':OUTP OFF')
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(0)))
            end

            pause(1);
            app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment is done!');
            app.IndicatorLamp_2.Color='w';
            app.StartExperimentButton_2.Enable="on";

            clc
            clear
            close all
            clearvars
            clearvars -global
            clear RRAM

        end

        % Button pushed function: StopExperimentButton_2
        function StopExperimentButton_2Pushed(app, event)
            app.IndicatorLamp_2.Color='k';
            app.StartExperimentButton_2.Enable="on";
            app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment has been stopped, Save the data!');
            %writeline(app.KLYSM2450,'smu.source.output = smu.OFF') %Put outpot off in KEITHLEY
            writeline(app.LSTC336,'RAMP 1,0,0') % Set Ramping Off
            if strcmp(app.afterrange,'On')
                writeline(app.LSTC336, strcat('RANGE 1,', string(app.Range)))
            end
            if strcmp(app.afterrange,'Off')
                writeline(app.LSTC336,('RANGE 1, 0'))
            end
            if strcmp(app.aftervoltage,'On')
                %writeline(app.KLYSM2450, strcat('smu.source.level = ',string(app.Vp)))
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(app.Vf)))
            end
            if strcmp(app.aftervoltage,'Off')
                %writeline(app.KLYSM2450, 'smu.source.level = 0')
                writeline(app.KYST2987,':OUTP OFF')
                writeline(app.KYST2987, sprintf('SOUR:VOLT %f',(0)))
            end

            [filename, pathname] = uiputfile('*.xlsx', 'Save as');
            if isequal(filename,0) || isequal(pathname,0)

                app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment is done, the Data is not saved!');
            else

                app.StateoftheExperimentTextArea_2.Value=('The Phase: The Experiment is done, the Data is saved!');

                StabilizationTemperature = app.initialtempall;
                StabilizationCurrent = app.initialcurrentall; % replace this with your Stabilization data
                MeasurementsTemperature = app.realtemp;
                MeasurementsCurrent = app.current ; % replace this with your Measurements data

                % Create tables for measurements
                app.stablization_table = table(StabilizationTemperature, StabilizationCurrent, 'VariableNames', app.stablization_var_names);
                app.measurement_table = table(MeasurementsTemperature, MeasurementsCurrent, 'VariableNames', app.measurement_var_names);

                %write temperature data and current data to the Excel file
                writecell(app.settings, fullfile(pathname, filename), 'Sheet', app.sheet1);
                writetable(app.stablization_table, fullfile(pathname, filename), 'Sheet', app.sheet2);
                writetable(app.measurement_table, fullfile(pathname, filename), 'Sheet', app.sheet3);
                % Add your writematrix, writecell, writetable code here, using fullfile(pathname, filename) as the filename
            end
            clc
            waitfor(app.StartExperimentButton)
            clear RRAM
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TSCMethodKeithleyTab = uitab(app.TabGroup2);
            app.TSCMethodKeithleyTab.Title = 'TSC Method (Keithley)';
            app.TabGroup = uitabgroup(app.TSCMethodKeithleyTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MajorMeasurementParametersPanel = uipanel(app.SettingsTab);
            app.MajorMeasurementParametersPanel.Title = 'Major Measurement Parameters';
            app.InitialTemperatureEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.InitialTemperatureEditFieldLabel.Text = 'Initial Temperature';
            app.InitialTemperatureEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.InitialTemperatureEditField.Value = 81;
            app.KelvinLabel_3 = uilabel(app.MajorMeasurementParametersPanel);
            app.KelvinLabel_3.Text = '[Kelvin]';
            app.FinalTemperatureEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.FinalTemperatureEditFieldLabel.Text = 'Final Temperature';
            app.FinalTemperatureEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.FinalTemperatureEditField.Value = 450;
            app.KelvinLabel_4 = uilabel(app.MajorMeasurementParametersPanel);
            app.KelvinLabel_4.Text = '[Kelvin]';
            app.PreliminaryVoltageEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PreliminaryVoltageEditFieldLabel.Text = 'Preliminary Voltage';
            app.PreliminaryVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.VoltsLabel_3 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_3.Text = '[Volts]';
            app.PulseVoltageLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PulseVoltageLabel.Text = 'Pulse Voltage';
            app.PulseVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.VoltsLabel_2 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_2.Text = '[Volts]';
            app.PulseTimeLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.PulseTimeLabel.Text = 'Pulse Time';
            app.PulseTimeEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.PulseTimeEditField.Value = 5;
            app.SecondLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.SecondLabel.Text = '[Second]';
            app.MeasurementVoltageEditFieldLabel = uilabel(app.MajorMeasurementParametersPanel);
            app.MeasurementVoltageEditFieldLabel.Text = 'Measurement Voltage';
            app.MeasurementVoltageEditField = uieditfield(app.MajorMeasurementParametersPanel, 'numeric');
            app.VoltsLabel_4 = uilabel(app.MajorMeasurementParametersPanel);
            app.VoltsLabel_4.Text = '[Volts]';
            app.SubsidiaryMeasurementParametersPanel = uipanel(app.SettingsTab);
            app.SubsidiaryMeasurementParametersPanel.Title = 'Subsidiary Measurement Parameters';
            app.StepEditField_4Label = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.StepEditField_4Label.Text = 'Step';
            app.StepEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.StepEditField.Value = 0.5;
            app.KelvinLabel_5 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_5.Text = '[Kelvin]';
            app.AccuracyStepLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyStepLabel.Text = 'Accuracy Step';
            app.AccuracyStepEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyStepEditField.Value = 0.1;
            app.KelvinLabel_7 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_7.Text = '[Kelvin]';
            app.AccuracyStableLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyStableLabel.Text = 'Accuracy Stable';
            app.AccuracyStableEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyStableEditField.Value = 1;
            app.KelvinLabel_6 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_6.Text = '[Kelvin]';
            app.AccuracyEndLabel = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.AccuracyEndLabel.Text = 'Accuracy End';
            app.AccuracyEndEditField = uieditfield(app.SubsidiaryMeasurementParametersPanel, 'numeric');
            app.AccuracyEndEditField.Value = 0.2;
            app.KelvinLabel_8 = uilabel(app.SubsidiaryMeasurementParametersPanel);
            app.KelvinLabel_8.Text = '[Kelvin]';
            app.LakeshoreTCParametersPanel = uipanel(app.SettingsTab);
            app.LakeshoreTCParametersPanel.Title = 'Lakeshore TC Parameters';
            app.RampEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.RampEditFieldLabel.Text = 'Ramp';
            app.RampEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.RampEditField.Value = 10;
            app.KMinLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.KMinLabel.Text = '[K/Min]';
            app.RangeEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.RangeEditField.Value = 3;
            app.HMLOLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.HMLOLabel.Text = '[H/M/L/O]';
            app.PEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.PEditField.Value = 100;
            app.IEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.IEditField.Value = 50;
            app.DEditFieldLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LakeshoreTCParametersPanel, 'numeric');
            app.DEditField.Value = 20;
            app.HeateraftermeasureSwitchLabel = uilabel(app.LakeshoreTCParametersPanel);
            app.HeateraftermeasureSwitchLabel.Text = 'Heater after measure';
            app.HeateraftermeasureSwitch = uiswitch(app.LakeshoreTCParametersPanel, 'slider');
            app.KeithleySMParametersPanel = uipanel(app.SettingsTab);
            app.KeithleySMParametersPanel.Title = 'Keithley SM Parameters';
            app.NPLCEditField_2Label = uilabel(app.KeithleySMParametersPanel);
            app.NPLCEditField_2Label.Text = 'NPLC';
            app.NPLCEditField = uieditfield(app.KeithleySMParametersPanel, 'numeric');
            app.NPLCEditField.Value = 1;
            app.NPLCEditField_2Label_2 = uilabel(app.KeithleySMParametersPanel);
            app.NPLCEditField_2Label_2.Text = 'Measurecount';
            app.MeasurecountEditField = uieditfield(app.KeithleySMParametersPanel, 'numeric');
            app.MeasurecountEditField.Value = 1;
            app.VoltageaftermeasureSwitchLabel = uilabel(app.KeithleySMParametersPanel);
            app.VoltageaftermeasureSwitchLabel.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch = uiswitch(app.KeithleySMParametersPanel, 'slider');
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = ' ';
            app.SampleInfoEditField = uieditfield(app.Panel_2, 'text');
            app.SampleInfoEditField.Value = 'Please Enter the Sample Info';
            app.SampleInfoEditFieldLabel = uilabel(app.Panel_2);
            app.SampleInfoEditFieldLabel.Text = 'Sample Info';
            app.Panel = uipanel(app.SettingsTab);
            app.Panel.Title = ' ';
            app.CommentsEditFieldLabel = uilabel(app.Panel);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.Panel, 'text');
            app.CommentsEditField.Tooltip = {''};
            app.CommentsEditField.Value = 'Please Enter the Comments';
            app.TSCKeithleyLabel = uilabel(app.SettingsTab);
            app.TSCKeithleyLabel.Text = 'TSC / Keithley';
            app.Tables = uitab(app.TabGroup);
            app.Tables.Title = 'Tables';
            app.StabilizationPhaseLabel = uilabel(app.Tables);
            app.StabilizationPhaseLabel.Text = 'Stabilization Phase';
            app.UITable1 = uitable(app.Tables);
            app.UITable1.ColumnName = {'Temperature'; 'Current'};
            app.UITable1.RowName = {};
            app.MeasurementPhaseLabel = uilabel(app.Tables);
            app.MeasurementPhaseLabel.Text = 'Measurement Phase';
            app.UITable2 = uitable(app.Tables);
            app.UITable2.ColumnName = {'Temperature'; 'Current'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.Tables);
            app.UITable3.ColumnName = {'Frequency'; 'NPLC'; 'Measurement Window'};
            app.UITable3.RowName = {};
            app.ConstantValuesLabel = uilabel(app.Tables);
            app.ConstantValuesLabel.Text = 'Constant  Values';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes1 = uiaxes(app.GraphsTab);
            title(app.UIAxes1, 'Current Vs Temperature, In Linear, During Stabilization')
            xlabel(app.UIAxes1, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes1, 'Initial Current Linear ; [I]=[Amps]')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GraphsTab);
            title(app.UIAxes2, 'Current Vs Temperature, In Logarithmic and During Stabilization')
            xlabel(app.UIAxes2, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes2, 'Initial Current Logarithmic ; [I]=[Amps]''')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraphsTab);
            title(app.UIAxes3, 'Current Vs Temperature, In Linear, During Measurements')
            xlabel(app.UIAxes3, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes3, 'Current linear ; [I]=[Amps]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GraphsTab);
            title(app.UIAxes4, 'Current Vs Temperature, In Logarithmic, During Measurements')
            xlabel(app.UIAxes4, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes4, 'Current Logarithmic ; [I]=[Amps]')
            zlabel(app.UIAxes4, 'Z')
            app.StateoftheExperimentPanel = uipanel(app.GraphsTab);
            app.StateoftheExperimentPanel.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea = uitextarea(app.StateoftheExperimentPanel);
            app.InstantMeasurementsPanel = uipanel(app.GraphsTab);
            app.InstantMeasurementsPanel.Title = 'Instant Measurements:';
            app.TemperatureRateTextAreaLabel = uilabel(app.InstantMeasurementsPanel);
            app.TemperatureRateTextAreaLabel.Text = 'Temperature Rate:';
            app.TemperatureRateTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.KMinLabel_2 = uilabel(app.InstantMeasurementsPanel);
            app.KMinLabel_2.Text = '[K/Min]';
            app.TemperatureLabel = uilabel(app.InstantMeasurementsPanel);
            app.TemperatureLabel.Text = 'Temperature:';
            app.TemperatureTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.KelvinLabel = uilabel(app.InstantMeasurementsPanel);
            app.KelvinLabel.Text = '[Kelvin]';
            app.VoltageLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel.Text = 'Current:';
            app.CurrentTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.AmpsLabel = uilabel(app.InstantMeasurementsPanel);
            app.AmpsLabel.Text = '[Amps]';
            app.VoltageLabel_2 = uilabel(app.InstantMeasurementsPanel);
            app.VoltageLabel_2.Text = 'Voltage:';
            app.VoltageTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.VoltsLabel = uilabel(app.InstantMeasurementsPanel);
            app.VoltsLabel.Text = '[Volts]';
            app.HeaterActivatedLabel = uilabel(app.InstantMeasurementsPanel);
            app.HeaterActivatedLabel.Text = 'Heater Activated:';
            app.HeaterActivatedTextArea = uitextarea(app.InstantMeasurementsPanel);
            app.Label = uilabel(app.InstantMeasurementsPanel);
            app.Label.Text = '[%]';
            app.IndicatorLampLabel = uilabel(app.TSCMethodKeithleyTab);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.TSCMethodKeithleyTab);
            app.StartExperimentButton = uibutton(app.TSCMethodKeithleyTab, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.StopExperimentButton = uibutton(app.TSCMethodKeithleyTab, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.TSCMethodKeysightTab = uitab(app.TabGroup2);
            app.TSCMethodKeysightTab.Title = 'TSC Method (Keysight)';
            app.TabGroup_2 = uitabgroup(app.TSCMethodKeysightTab);
            app.SettingsTab_2 = uitab(app.TabGroup_2);
            app.SettingsTab_2.Title = 'Settings';
            app.MajorMeasurementParametersPanel_2 = uipanel(app.SettingsTab_2);
            app.MajorMeasurementParametersPanel_2.Title = 'Major Measurement Parameters';
            app.InitialTemperatureEditField_2Label = uilabel(app.MajorMeasurementParametersPanel_2);
            app.InitialTemperatureEditField_2Label.Text = 'Initial Temperature';
            app.InitialTemperatureEditField_2 = uieditfield(app.MajorMeasurementParametersPanel_2, 'numeric');
            app.InitialTemperatureEditField_2.Value = 81;
            app.FinalTemperatureEditField_2Label = uilabel(app.MajorMeasurementParametersPanel_2);
            app.FinalTemperatureEditField_2Label.Text = 'Final Temperature';
            app.FinalTemperatureEditField_2 = uieditfield(app.MajorMeasurementParametersPanel_2, 'numeric');
            app.FinalTemperatureEditField_2.Value = 450;
            app.PreliminaryVoltageEditField_2Label = uilabel(app.MajorMeasurementParametersPanel_2);
            app.PreliminaryVoltageEditField_2Label.Text = 'Preliminary Voltage';
            app.PreliminaryVoltageEditField_2 = uieditfield(app.MajorMeasurementParametersPanel_2, 'numeric');
            app.PulseVoltageEditField_2Label = uilabel(app.MajorMeasurementParametersPanel_2);
            app.PulseVoltageEditField_2Label.Text = 'Pulse Voltage';
            app.PulseVoltageEditField_2 = uieditfield(app.MajorMeasurementParametersPanel_2, 'numeric');
            app.PulseTimeEditField_2Label = uilabel(app.MajorMeasurementParametersPanel_2);
            app.PulseTimeEditField_2Label.Text = 'Pulse Time';
            app.PulseTimeEditField_2 = uieditfield(app.MajorMeasurementParametersPanel_2, 'numeric');
            app.PulseTimeEditField_2.Value = 5;
            app.MeasurementVoltageEditField_2Label = uilabel(app.MajorMeasurementParametersPanel_2);
            app.MeasurementVoltageEditField_2Label.Text = 'Measurement Voltage';
            app.MeasurementVoltageEditField_2 = uieditfield(app.MajorMeasurementParametersPanel_2, 'numeric');
            app.KelvinLabel_9 = uilabel(app.MajorMeasurementParametersPanel_2);
            app.KelvinLabel_9.Text = '[Kelvin]';
            app.KelvinLabel_10 = uilabel(app.MajorMeasurementParametersPanel_2);
            app.KelvinLabel_10.Text = '[Kelvin]';
            app.VoltsLabel_5 = uilabel(app.MajorMeasurementParametersPanel_2);
            app.VoltsLabel_5.Text = '[Volts]';
            app.VoltsLabel_6 = uilabel(app.MajorMeasurementParametersPanel_2);
            app.VoltsLabel_6.Text = '[Volts]';
            app.SecondLabel_2 = uilabel(app.MajorMeasurementParametersPanel_2);
            app.SecondLabel_2.Text = '[Second]';
            app.VoltsLabel_7 = uilabel(app.MajorMeasurementParametersPanel_2);
            app.VoltsLabel_7.Text = '[Volts]';
            app.SubsidiaryMeasurementParametersPanel_2 = uipanel(app.SettingsTab_2);
            app.SubsidiaryMeasurementParametersPanel_2.Title = 'Subsidiary Measurement Parameters';
            app.StepEditField_2Label = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.SubsidiaryMeasurementParametersPanel_2, 'numeric');
            app.StepEditField_2.Value = 0.5;
            app.AccuracyStepEditField_2Label = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.AccuracyStepEditField_2Label.Text = 'Accuracy Step';
            app.AccuracyStepEditField_2 = uieditfield(app.SubsidiaryMeasurementParametersPanel_2, 'numeric');
            app.AccuracyStepEditField_2.Value = 0.1;
            app.AccuracyStableEditField_2Label = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.AccuracyStableEditField_2Label.Text = 'Accuracy Stable';
            app.AccuracyStableEditField_2 = uieditfield(app.SubsidiaryMeasurementParametersPanel_2, 'numeric');
            app.AccuracyStableEditField_2.Value = 1;
            app.AccuracyEndEditField_2Label = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.AccuracyEndEditField_2Label.Text = 'Accuracy End';
            app.AccuracyEndEditField_2 = uieditfield(app.SubsidiaryMeasurementParametersPanel_2, 'numeric');
            app.AccuracyEndEditField_2.Value = 0.2;
            app.KelvinLabel_11 = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.KelvinLabel_11.Text = '[Kelvin]';
            app.KelvinLabel_12 = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.KelvinLabel_12.Text = '[Kelvin]';
            app.KelvinLabel_13 = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.KelvinLabel_13.Text = '[Kelvin]';
            app.KelvinLabel_14 = uilabel(app.SubsidiaryMeasurementParametersPanel_2);
            app.KelvinLabel_14.Text = '[Kelvin]';
            app.LakeshoreTCParametersPanel_2 = uipanel(app.SettingsTab_2);
            app.LakeshoreTCParametersPanel_2.Title = 'Lakeshore TC Parameters';
            app.RampEditField_2Label = uilabel(app.LakeshoreTCParametersPanel_2);
            app.RampEditField_2Label.Text = 'Ramp';
            app.RampEditField_2 = uieditfield(app.LakeshoreTCParametersPanel_2, 'numeric');
            app.RampEditField_2.Value = 10;
            app.RangeEditField_2Label = uilabel(app.LakeshoreTCParametersPanel_2);
            app.RangeEditField_2Label.Text = 'Range';
            app.RangeEditField_2 = uieditfield(app.LakeshoreTCParametersPanel_2, 'numeric');
            app.RangeEditField_2.Value = 3;
            app.PEditField_2Label = uilabel(app.LakeshoreTCParametersPanel_2);
            app.PEditField_2Label.Text = 'P';
            app.PEditField_2 = uieditfield(app.LakeshoreTCParametersPanel_2, 'numeric');
            app.PEditField_2.Value = 100;
            app.IEditField_2Label = uilabel(app.LakeshoreTCParametersPanel_2);
            app.IEditField_2Label.Text = 'I';
            app.IEditField_2 = uieditfield(app.LakeshoreTCParametersPanel_2, 'numeric');
            app.IEditField_2.Value = 50;
            app.DEditField_2Label = uilabel(app.LakeshoreTCParametersPanel_2);
            app.DEditField_2Label.Text = 'D';
            app.DEditField_2 = uieditfield(app.LakeshoreTCParametersPanel_2, 'numeric');
            app.DEditField_2.Value = 20;
            app.KMinLabel_3 = uilabel(app.LakeshoreTCParametersPanel_2);
            app.KMinLabel_3.Text = '[K/Min]';
            app.HMLOLabel_2 = uilabel(app.LakeshoreTCParametersPanel_2);
            app.HMLOLabel_2.Text = '[H/M/L/O]';
            app.HeateraftermeasureSwitch_2Label = uilabel(app.LakeshoreTCParametersPanel_2);
            app.HeateraftermeasureSwitch_2Label.Text = 'Heater after measure';
            app.HeateraftermeasureSwitch_2 = uiswitch(app.LakeshoreTCParametersPanel_2, 'slider');
            app.KeysightEMParametersPanel = uipanel(app.SettingsTab_2);
            app.KeysightEMParametersPanel.Title = 'Keysight EM Parameters';
            app.NPLCEditField_3Label = uilabel(app.KeysightEMParametersPanel);
            app.NPLCEditField_3Label.Text = 'NPLC';
            app.NPLCEditField_3 = uieditfield(app.KeysightEMParametersPanel, 'numeric');
            app.NPLCEditField_3.Value = 1;
            app.MeasurecountEditField_2Label = uilabel(app.KeysightEMParametersPanel);
            app.MeasurecountEditField_2Label.Text = 'Measurecount';
            app.MeasurecountEditField_2 = uieditfield(app.KeysightEMParametersPanel, 'numeric');
            app.MeasurecountEditField_2.Value = 1;
            app.VoltageaftermeasureSwitch_2Label = uilabel(app.KeysightEMParametersPanel);
            app.VoltageaftermeasureSwitch_2Label.Text = 'Voltage after measure';
            app.VoltageaftermeasureSwitch_2 = uiswitch(app.KeysightEMParametersPanel, 'slider');
            app.Panel_3 = uipanel(app.SettingsTab_2);
            app.Panel_3.Title = ' ';
            app.SampleInfoEditField_2 = uieditfield(app.Panel_3, 'text');
            app.SampleInfoEditField_2.Value = 'Please Enter the Sample Info';
            app.SampleInfoEditField_2Label = uilabel(app.Panel_3);
            app.SampleInfoEditField_2Label.Text = 'Sample Info';
            app.Panel_4 = uipanel(app.SettingsTab_2);
            app.Panel_4.Title = ' ';
            app.CommentsEditField_2Label = uilabel(app.Panel_4);
            app.CommentsEditField_2Label.Text = 'Comments';
            app.CommentsEditField_2 = uieditfield(app.Panel_4, 'text');
            app.CommentsEditField_2.Tooltip = {''};
            app.CommentsEditField_2.Value = 'Please Enter the Comments';
            app.TSCKeysightLabel = uilabel(app.SettingsTab_2);
            app.TSCKeysightLabel.Text = 'TSC / Keysight';
            app.Tables_2 = uitab(app.TabGroup_2);
            app.Tables_2.Title = 'Tables';
            app.StabilizationPhaseLabel_2 = uilabel(app.Tables_2);
            app.StabilizationPhaseLabel_2.Text = 'Stabilization Phase';
            app.UITable1_2 = uitable(app.Tables_2);
            app.UITable1_2.ColumnName = {'Temperature'; 'Current'};
            app.UITable1_2.RowName = {};
            app.MeasurementPhaseLabel_2 = uilabel(app.Tables_2);
            app.MeasurementPhaseLabel_2.Text = 'Measurement Phase';
            app.UITable2_2 = uitable(app.Tables_2);
            app.UITable2_2.ColumnName = {'Temperature'; 'Current'};
            app.UITable2_2.RowName = {};
            app.UITable3_2 = uitable(app.Tables_2);
            app.UITable3_2.ColumnName = {'Frequency'; 'NPLC'; 'Measurement Window'};
            app.UITable3_2.RowName = {};
            app.ConstantValuesLabel_2 = uilabel(app.Tables_2);
            app.ConstantValuesLabel_2.Text = 'Constant  Values';
            app.GraphsTab_2 = uitab(app.TabGroup_2);
            app.GraphsTab_2.Title = 'Graphs';
            app.UIAxes1_2 = uiaxes(app.GraphsTab_2);
            title(app.UIAxes1_2, 'Current Vs Temperature, In Linear, During Stabilization')
            xlabel(app.UIAxes1_2, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes1_2, 'Initial Current Linear ; [I]=[Amps]')
            zlabel(app.UIAxes1_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.GraphsTab_2);
            title(app.UIAxes2_2, 'Current Vs Temperature, In Logarithmic and During Stabilization')
            xlabel(app.UIAxes2_2, 'Initial Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes2_2, 'Initial Current Logarithmic ; [I]=[Amps]''')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.GraphsTab_2);
            title(app.UIAxes3_2, 'Current Vs Temperature, In Linear, During Measurements')
            xlabel(app.UIAxes3_2, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes3_2, 'Current linear ; [I]=[Amps]')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes4_2 = uiaxes(app.GraphsTab_2);
            title(app.UIAxes4_2, 'Current Vs Temperature, In Logarithmic, During Measurements')
            xlabel(app.UIAxes4_2, 'Temperature ; [T]=[Kelvin]')
            ylabel(app.UIAxes4_2, 'Current Logarithmic ; [I]=[Amps]')
            zlabel(app.UIAxes4_2, 'Z')
            app.StateoftheExperimentPanel_2 = uipanel(app.GraphsTab_2);
            app.StateoftheExperimentPanel_2.Title = 'State of the Experiment:';
            app.StateoftheExperimentTextArea_2 = uitextarea(app.StateoftheExperimentPanel_2);
            app.InstantMeasurementsPanel_2 = uipanel(app.GraphsTab_2);
            app.InstantMeasurementsPanel_2.Title = 'Instant Measurements:';
            app.TemperatureRateTextArea_2Label = uilabel(app.InstantMeasurementsPanel_2);
            app.TemperatureRateTextArea_2Label.Text = 'Temperature Rate:';
            app.TemperatureRateTextArea_2 = uitextarea(app.InstantMeasurementsPanel_2);
            app.KMinLabel_4 = uilabel(app.InstantMeasurementsPanel_2);
            app.KMinLabel_4.Text = '[K/Min]';
            app.TemperatureTextArea_2Label = uilabel(app.InstantMeasurementsPanel_2);
            app.TemperatureTextArea_2Label.Text = 'Temperature:';
            app.TemperatureTextArea_2 = uitextarea(app.InstantMeasurementsPanel_2);
            app.KelvinLabel_15 = uilabel(app.InstantMeasurementsPanel_2);
            app.KelvinLabel_15.Text = '[Kelvin]';
            app.CurrentTextArea_2Label = uilabel(app.InstantMeasurementsPanel_2);
            app.CurrentTextArea_2Label.Text = 'Current:';
            app.CurrentTextArea_2 = uitextarea(app.InstantMeasurementsPanel_2);
            app.AmpsLabel_2 = uilabel(app.InstantMeasurementsPanel_2);
            app.AmpsLabel_2.Text = '[Amps]';
            app.VoltageTextArea_2Label = uilabel(app.InstantMeasurementsPanel_2);
            app.VoltageTextArea_2Label.Text = 'Voltage:';
            app.VoltageTextArea_2 = uitextarea(app.InstantMeasurementsPanel_2);
            app.VoltsLabel_8 = uilabel(app.InstantMeasurementsPanel_2);
            app.VoltsLabel_8.Text = '[Volts]';
            app.HeaterActivatedTextArea_2Label = uilabel(app.InstantMeasurementsPanel_2);
            app.HeaterActivatedTextArea_2Label.Text = 'Heater Activated:';
            app.HeaterActivatedTextArea_2 = uitextarea(app.InstantMeasurementsPanel_2);
            app.Label_2 = uilabel(app.InstantMeasurementsPanel_2);
            app.Label_2.Text = '[%]';
            app.IndicatorLamp_2Label = uilabel(app.TSCMethodKeysightTab);
            app.IndicatorLamp_2Label.Text = 'Indicator';
            app.IndicatorLamp_2 = uilamp(app.TSCMethodKeysightTab);
            app.StopExperimentButton_2 = uibutton(app.TSCMethodKeysightTab, 'push');
            app.StopExperimentButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButton_2Pushed, true);
            app.StopExperimentButton_2.Text = 'Stop Experiment';
            app.StartExperimentButton_2 = uibutton(app.TSCMethodKeysightTab, 'push');
            app.StartExperimentButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButton_2Pushed, true);
            app.StartExperimentButton_2.Text = 'Start Experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RRAMapp14

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end